classdef collar_picking_APP1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        OriginalVDLRescaleButton        matlab.ui.control.Button
        DepthEditFieldLabel             matlab.ui.control.Label
        DepthEditField                  matlab.ui.control.NumericEditField
        CHEditFieldLabel                matlab.ui.control.Label
        CHEditField                     matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        toEditField                     matlab.ui.control.NumericEditField
        toEditField_2Label              matlab.ui.control.Label
        toEditField_2                   matlab.ui.control.NumericEditField
        InputPanel                      matlab.ui.container.Panel
        LoadVDLDataButton               matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        LoadWellSchematicsButton        matlab.ui.control.Button
        EditField_24                    matlab.ui.control.EditField
        SectionsDropDownLabel           matlab.ui.control.Label
        SectionsDropDown                matlab.ui.control.DropDown
        ShowVDLButton                   matlab.ui.control.Button
        PreparationProcessingPanel      matlab.ui.container.Panel
        DataPreprocessingDropDownLabel  matlab.ui.control.Label
        DataPreprocessingDropDown       matlab.ui.control.DropDown
        RunButton                       matlab.ui.control.Button
        Pipe1innerLabel_2               matlab.ui.control.Label
        Pipe2Label_2                    matlab.ui.control.Label
        EditField_19                    matlab.ui.control.NumericEditField
        EditField_20                    matlab.ui.control.NumericEditField
        Pipe3Label_2                    matlab.ui.control.Label
        EditField_21                    matlab.ui.control.NumericEditField
        CHLabel                         matlab.ui.control.Label
        Pipe4Label_2                    matlab.ui.control.Label
        EditField_22                    matlab.ui.control.NumericEditField
        Pipe5Label_2                    matlab.ui.control.Label
        EditField_23                    matlab.ui.control.NumericEditField
        Pipe1innerLabel_3               matlab.ui.control.Label
        Pipe2Label_3                    matlab.ui.control.Label
        EditField_25                    matlab.ui.control.NumericEditField
        EditField_26                    matlab.ui.control.NumericEditField
        EditField_27                    matlab.ui.control.NumericEditField
        EditField_28                    matlab.ui.control.NumericEditField
        Pipe3Label_3                    matlab.ui.control.Label
        EditField_29                    matlab.ui.control.NumericEditField
        EditField_30                    matlab.ui.control.NumericEditField
        AnchorDepthftLabel              matlab.ui.control.Label
        JointLenftLabel                 matlab.ui.control.Label
        TotalpipenumberLabel            matlab.ui.control.Label
        EditField_31                    matlab.ui.control.NumericEditField
        Pipe4Label_3                    matlab.ui.control.Label
        EditField_32                    matlab.ui.control.NumericEditField
        EditField_33                    matlab.ui.control.NumericEditField
        Pipe5Label_3                    matlab.ui.control.Label
        EditField_34                    matlab.ui.control.NumericEditField
        EditField_35                    matlab.ui.control.NumericEditField
        MargininLabel                   matlab.ui.control.Label
        EditField_36                    matlab.ui.control.NumericEditField
        CollarlengthdefaultLabel        matlab.ui.control.Label
        EditField_37                    matlab.ui.control.NumericEditField
        ShowCollarVDLButton             matlab.ui.control.Button
        Pipe1Label                      matlab.ui.control.Label
        Pipe2Label_4                    matlab.ui.control.Label
        EditField_38                    matlab.ui.control.NumericEditField
        EditField_39                    matlab.ui.control.NumericEditField
        Pipe3Label_4                    matlab.ui.control.Label
        EditField_40                    matlab.ui.control.NumericEditField
        Pipe4Label_4                    matlab.ui.control.Label
        EditField_41                    matlab.ui.control.NumericEditField
        Pipe5Label_4                    matlab.ui.control.Label
        EditField_42                    matlab.ui.control.NumericEditField
        ConfidenceRateLabel             matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        GenerateCollarVDLCheckBox       matlab.ui.control.CheckBox
        EditField_43                    matlab.ui.control.NumericEditField
        EditField_44                    matlab.ui.control.NumericEditField
        EditField_45                    matlab.ui.control.NumericEditField
        EditField_46                    matlab.ui.control.NumericEditField
        ResultsPanel                    matlab.ui.container.Panel
        ExportCSVButton                 matlab.ui.control.Button
        ShowonVDLButton                 matlab.ui.control.Button
        PipeLabel                       matlab.ui.control.Label
        PipeEditField                   matlab.ui.control.EditField
        CollarVDLRescaleButton          matlab.ui.control.Button
        ColorScaleEditFieldLabel        matlab.ui.control.Label
        ColorScaleEditField             matlab.ui.control.NumericEditField
        toEditField_3Label              matlab.ui.control.Label
        toEditField_3                   matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Property % Description
        
        % load log data
        logDataPath     % log data path
        lasSrcFilename  % log data filename
        lasData         % log las data
        lasHeader       % log las header
        lasDetails      % log las details
        lasDepth        % log las depth
        VDL             % entire well vdl data from log las
        newmap          % newmap for vdl
        
        % load well schematic info
        wellSchemPath   % well schematic xml file path
        pipeList        % pipe list read from xml file
        sectionList     % list for section name
        depth_min       % sectional depth min
        depth_max       % sectional depth max
        
        % sectional data
        vdl             % sectional VDL
        depth           % sectional depth
        pipeNum         % number of pipe in current section
        
        % data processing
        pipe_num        % total pipe number selected
        CHs             % selected channels for decomposition
        jntlgth         % input joint length for all pipes
        achrdepth1      % depth of all anchor points with first fifth anchor
        achrdepth2      % depth of all anchor points with second fifth anchor
        achrdepth3      % depth of all anchor points with third fifth anchor
        margin          % selected margin range
        depth_MVMD      % depth for MVMD
        collar_depth    % depth for plot collar vdl
        collar_vdl      % vdl for collar
        
        % for output
        collar_length   % collar length table
        table_P1_v      % verified table 1
        table_P2_v      % verified table 2
        table_P3_v      % verified table 3
        table_P4_v      % verified table 4
        table_P5_v      % verified table 5
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadVDLDataButton
        function LoadVDLDataButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.las'}, 'Load Log Data');
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg('No file selected', 'Error');
            end
            
            app.logDataPath = strcat(pathname, filename);
            app.EditField.Value = app.logDataPath;
            app.lasSrcFilename = filename;
            
            % read las
            [ app.lasData, app.lasHeader, app.lasDetails ] = ImportLAS(filename);
            
            % get all decays and eliminate all ADEC and depth with ADEC[1] <= 0
            i_ADEC = ~cellfun('isempty', regexp(app.lasHeader, '^ADEC\[\d+\]_n$', 'match'));
            VDL = app.lasData(:, i_ADEC);
            VDL = VDL(:, 1);
            nozeroNum = find(VDL > 0, 1);
            app.VDL = app.lasData(nozeroNum : end, i_ADEC);
            app.VDL = app.VDL(:, app.VDL(1, :)>0);
            % get all decays cont.
            app.lasDepth = app.lasData(nozeroNum : end, strcmp(app.lasHeader, 'Depth') | strcmp(app.lasHeader, 'DEPT'));
            app.lasDepth = app.lasDepth(:, 1);
            
            function [ data, header, detail ] = ImportLAS( filename )
                %ImportLAS Import .las file
                % Input:
                %   filename    target file name
                %
                % Output:
                %   data        numeric data
                %   header      header of data
                %   detail      structure of more detailed info
                
                %% File Operations
                % Open the text file.
                fileID = fopen(filename, 'r');
                
                % read in las as space delimited string
                formatSpec = '%s%[^\n\r]';
                
                % Read first 1000 lines to find header
                textArray = textscan(fileID, formatSpec, 1000, 'Delimiter', ' ', ...
                    'MultipleDelimsAsOne', true,  'ReturnOnError', false);
                
                % find '~A' as the header line start, '#' for additional header lines
                i_labeledheader = find(strcmp(textArray{1}, '~A') | ...
                    strcmp(textArray{1}, '#'));
                
                % read header lines
                i_header = GetTureHeader(textArray{2}(i_labeledheader), i_labeledheader);
                [header, n_col] = Block2Vector(textArray{2}(i_header), 'string');
                
                % Read in data block
                frewind(fileID);
                formatSpec = '%[^\n\r]';
                dataArray = textscan(fileID, formatSpec, 'Delimiter', ' ', ...
                    'MultipleDelimsAsOne', true, 'HeaderLines', i_labeledheader(end), ...
                    'ReturnOnError', false);
                
                % Close the text file.
                fclose(fileID);
                
                %% Data Output
                % parse data block
                row_block = length(i_header);
                col_total = length(header);
                raw = dataArray{1};
                n_depth = length(raw) / row_block;
                
                % read data
                data = zeros(n_depth, col_total);
                for i = 1:n_depth
                    % read data lines by block
                    [ data_depth, ~ ] = Block2Vector(raw((i - 1) * row_block + ...
                        (1:row_block)), 'float');
                    data(i, :) = data_depth;
                end
                
                % build detail structure for output
                detail = struct;
                detail.i_datastart = i_labeledheader(end) + 1;
                detail.n_col = n_col;
                
            end
            
            function [ i_trueheader ] = GetTureHeader( data, i_header )
                %GetTureHeader Remove empty lines of labeled header
                % Input:
                %   data            input data, n x 1 cell
                %   i_header        labeled header lines
                %
                % Output:
                %   i_trueheader    filled header lines
                
                i_trueheader = zeros(size(i_header));
                len = size(data, 1);
                
                index = 1;
                for i = 1:len
                    line = strtrim(data{i});
                    if isempty(line)
                        continue;
                    end
                    i_trueheader(index) = i_header(i);
                    index = index + 1;
                end
                
                i_trueheader = i_trueheader(1:(index - 1));
                
            end
            
            function [ output, n_col ] = Block2Vector( data, option )
                %Block2Vector Convert block data to 1 dimensional vector
                % Input:
                %   data        input data, n x 1 cell
                %   option      'string' or 'float'
                %
                % Output:
                %   output      output data, 1 x m vector
                %   n_col       column size, n x 1 vector
                
                if strcmp(option, 'string')
                    formatSpec = '%s';
                    output = cell(1, 200);
                    type = 1;
                else
                    formatSpec = '%f';
                    output = zeros(1, 200);
                    type = 2;
                end
                
                len = size(data, 1);
                n_col = zeros(len, 1);
                index = 0;
                for i = 1:len
                    if type == 1
                        line = strsplit(strtrim(data{i}));
                        n = size(line, 2);
                    else
                        [line, n] = sscanf(data{i}, formatSpec);
                    end
                    
                    output(index + (1:n)) = line;
                    index = index + n;
                    n_col(i) = n;
                end
                
                if index > 0
                    output = output(1:index);
                else
                    output = [];
                    n_col = [];
                end
                
            end
        end

        % Button pushed function: LoadWellSchematicsButton
        function LoadWellSchematicsButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.xml'}, 'Load Well Schematics');
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg("No file selected", 'Error');
            end
            
            app.wellSchemPath = strcat(pathname, filename);
            app.EditField_24.Value = app.wellSchemPath;
            
            % Import well schematic in xml file
            % Input:
            %   filename    target file name
            %
            % Output:
            %   pipeList    pipe list
            
            % labels to read
            labels = {'ItemID', 'TopDepth', 'BottomDepth', 'NomOD', 'NomThickness'};
            
            % as = fullfile(pathname, filename);
            % read xml file
            xDoc = xmlread(filename);
            
            % find all pipe section
            allListitems = xDoc.getElementsByTagName('EMDSPipeSection');
            
            % get listnode information
            for i = 1:allListitems.getLength
                thisListitem = allListitems.item(i - 1);
                
                % read the labels
                for j = 1:length(labels)
                    thisList = thisListitem.getElementsByTagName(labels{j});
                    thisElement = thisList.item(0);
                    app.pipeList(i).(labels{j}) = ...
                        str2double(thisElement.getFirstChild.getData);
                end
            end
            
            % generate a string for the dropdown button
            depths = unique([app.pipeList.TopDepth, app.pipeList.BottomDepth]);
            app.sectionList = strings(1, (length(depths) - 1));
            for j = 1:(length(depths) - 1)
                n = 0;
                % use median of section depth to count pipe number
                depthmid = mean([depths(j), depths(j + 1)]);
                for k = 1 : app.pipeList(end).ItemID
                    if depthmid >= app.pipeList(k).TopDepth && depthmid <= app.pipeList(k).BottomDepth
                        n = n + 1;
                    end
                end
                app.pipeNum = num2str(n);
                app.sectionList(j) = strcat(app.pipeNum, " Pipes Section");
            end
            
            app.SectionsDropDown.Items = app.sectionList;
            app.SectionsDropDown.ItemsData = 1 : length(app.SectionsDropDown.Items);
            
        end

        % Value changed function: SectionsDropDown
        function SectionsDropDownValueChanged(app, event)
            value = app.SectionsDropDown.Value;
            
            depths = unique([app.pipeList.TopDepth, app.pipeList.BottomDepth]);
            
            % depth selection
            app.depth_min = depths(value);
            app.depth_max = depths(value + 1);
            
            i_Section = zeros(size(app.lasDepth, 1), 1); % logic array for selected sectional depth (1 depth only)
            
            for d = 1:size(app.lasDepth, 1)
                if app.lasDepth(d) >= app.depth_min && app.lasDepth(d) <= app.depth_max
                    i_Section(d) = 1;
                end
            end
            i_Section = logical(i_Section);
            
            i_CH = app.VDL(1,:) > 0;
            
            % store selected ADEC in a varible for selected depth
            app.vdl = app.VDL(i_Section, i_CH);
            app.depth = app.lasDepth(i_Section, :);
            str = app.SectionsDropDown.Items(value);
            str_num = regexp(str, '\d+', 'match');
            app.EditField_31.Value = str2num(string(str_num));
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            msgbox('Start calculating.');
            
            % if anchor depth is outer than depth min and max
            if app.EditField_28.Value ~= 0 && (app.EditField_28.Value > app.depth_max || ...
                    app.EditField_28.Value < app.depth_min)
                errordlg('Pipe 1 anchor depth out of boundary', 'Error');
                return                
            end
            if app.EditField_27.Value ~= 0 && (app.EditField_27.Value > app.depth_max || ...
                    app.EditField_27.Value < app.depth_min)
                errordlg('Pipe 2 anchor depth out of boundary', 'Error');
                return                
            end
            if app.EditField_30.Value ~= 0 && (app.EditField_30.Value > app.depth_max || ...
                    app.EditField_30.Value < app.depth_min)
                errordlg('Pipe 3 anchor depth out of boundary', 'Error');
                return                
            end
            if app.EditField_33.Value ~= 0 && (app.EditField_33.Value > app.depth_max || ...
                    app.EditField_33.Value < app.depth_min)
                errordlg('Pipe 4 anchor depth out of boundary', 'Error');
                return                
            end
            if app.EditField_35.Value ~= 0 && (app.EditField_35.Value > app.depth_max || ...
                    app.EditField_35.Value < app.depth_min)
                errordlg('Pipe 5 anchor depth out of boundary', 'Error');
                return                
            end
            if app.EditField_43.Value ~= 0 && (app.EditField_43.Value > app.depth_max || ...
                    app.EditField_43.Value < app.depth_min)
                errordlg('Pipe 5 anchor depth out of boundary', 'Error');
                return                
            end
            if app.EditField_44.Value ~= 0 && (app.EditField_44.Value > app.depth_max || ...
                    app.EditField_44.Value < app.depth_min)
                errordlg('Pipe 5 anchor depth out of boundary', 'Error');
                return                
            end
            
            % total pipe number
            app.pipe_num = app.EditField_31.Value;
            
            % No. of channel for signal decomposition for each pipe
            app.CHs = [app.EditField_19.Value, app.EditField_20.Value, app.EditField_21.Value, ...
                app.EditField_22.Value, app.EditField_23.Value];
            app.CHs = app.CHs(1:app.pipe_num);
            
            % joint length for each pipe
            app.jntlgth = [app.EditField_25.Value, app.EditField_26.Value, app.EditField_29.Value, ...
                app.EditField_32.Value, app.EditField_34.Value];
            app.jntlgth = app.jntlgth(1:app.pipe_num);
            
            % anchor point depth for each pipe
            % update 12.06.2023 add three fifth pipe anchor 
            app.achrdepth1 = [app.EditField_28.Value, app.EditField_27.Value, app.EditField_30.Value, ...
                app.EditField_33.Value, app.EditField_35.Value];
            app.achrdepth1 = app.achrdepth1(1:app.pipe_num);
            app.achrdepth2 = [app.EditField_28.Value, app.EditField_27.Value, app.EditField_30.Value, ...
                app.EditField_33.Value, app.EditField_43.Value];
            app.achrdepth2 = app.achrdepth2(1:app.pipe_num);
            app.achrdepth3 = [app.EditField_28.Value, app.EditField_27.Value, app.EditField_30.Value, ...
                app.EditField_33.Value, app.EditField_44.Value];
            app.achrdepth3 = app.achrdepth3(1:app.pipe_num);
            a_achr = [app.EditField_35.Value, app.EditField_43.Value, app.EditField_44.Value];
            
            % margin/allowance of pipe length
            app.margin = app.EditField_36.Value;
            
            % collar length default - to replace hard to find collar length
            clr_len_def = app.EditField_37.Value;
            
            % msgbox if collar VDL is required
            if app.GenerateCollarVDLCheckBox.Value == 1
                msgbox('Calculating for Collar VDL, please wait.');
            end
            
            %% data preprocessing -- decomposition
            % EMD
            flag_EMD = strcmp(app.DataPreprocessingDropDown.Value, 'EMD');
            if flag_EMD == 1
                if app.GenerateCollarVDLCheckBox.Value == 0
                    % 1D EMD
                    EMDdata = ones(size(app.vdl, 1), app.pipe_num);
                    for CH_No = 1:app.pipe_num
                        CH_select = app.CHs(CH_No);
                        EMDdata(:, CH_No) = decomp_EMD(app.vdl, CH_select);
                    end
                    data_preproc = EMDdata;
                else
                    
                    % 2D EMD
                    [data_preproc, app.collar_vdl] = EMD_2D(app.vdl, app.depth, app.CHs);
                    
                    app.collar_depth = app.depth;
                end
            end
            
            
            % MVMD
            flag_MVMD = strcmp(app.DataPreprocessingDropDown.Value, 'MVMD');
            if flag_MVMD == 1
                if app.GenerateCollarVDLCheckBox.Value == 0
%                     % ensembled mvmd for 1st channel
%                     if app.CHs(1) < 3
%                         vdl_mvmd = app.vdl(:, 1:(app.CHs(1) + 2));
%                     elseif app.CHs(1) > size(app.vdl, 2)-2
%                         vdl_mvmd = app.vdl(:, (app.CHs(1)-2) : size(app.vdl, 2));
%                     else
%                         vdl_mvmd = app.vdl(:, (app.CHs(1)-1) : (app.CHs(1)+1));
%                     end
%                     [MVMDdata, app.collar_depth] = Hierach_MVMD(vdl_mvmd, app.depth);
%                     data_preproc = zeros(size(MVMDdata, 1), size(app.CHs, 2));
%                     if size(MVMDdata, 2) >= 3
%                         data_preproc(:, 1) = MVMDdata(:, 3);
%                     else
%                         data_preproc(:, 1) = MVMDdata(:, 1);
%                     end
%                     
%                     % do ensembled mvmd for rest chs
%                     for i = 1:size(app.CHs, 2)
%                         if app.CHs(i) < 3
%                             vdl_mvmd = app.vdl(:, 1:(app.CHs(i) + 2));
%                         elseif app.CHs(i) > size(app.vdl, 2)-2
%                             vdl_mvmd = app.vdl(:, (app.CHs(i)-2) : size(app.vdl, 2));
%                         else
%                             vdl_mvmd = app.vdl(:, (app.CHs(i)-1) : (app.CHs(i)+1));
%                         end
%                         [MVMDdata, app.collar_depth] = Hierach_MVMD(vdl_mvmd, app.depth);
%                         if size(MVMDdata, 2) >= 3
%                             data_preproc(:, i) = MVMDdata(:, 3);
%                         else
%                             data_preproc(:, i) = MVMDdata(:, 1);
%                         end
%                     end
%                     app.collar_vdl = data_preproc(:, 1:end);
                    
                    vdl_mvmd = app.vdl(:, app.CHs);
                    [MVMDdata, app.collar_depth] = Hierach_MVMD(vdl_mvmd, app.depth);
                    data_preproc = MVMDdata;
                    app.collar_vdl = data_preproc(:, 1:end);
                    
                else
                    [MVMDdata, app.collar_depth] = Hierach_MVMD(app.vdl, app.depth);
                    data_preproc = ones(size(MVMDdata, 1), app.pipe_num);
                    for CH_No = 1:app.pipe_num
                        CH_select = app.CHs(CH_No);
                        data_preproc (:, CH_No) = MVMDdata(:, CH_select);
                    end
                    % assign values for plot
                    app.collar_vdl = MVMDdata(:, 1:end);
                end
            end
                
                
                %             %% data screening -- find collar peaks -- threshold
                %             flag_Threshold = strcmp(app.DataScreeningDropDown.Value, 'Threshold');
                %             if flag_Threshold == 1
                %                 collar_info_format = comprs_thrsd(data_preproc, app.depth);
                %             end
                
                %% data screening -- find collar peaks -- ranking
                %flag_Counting = strcmp(app.DataScreeningDropDown.Value, 'Peak Counting');
                %if flag_Counting == 1
                if flag_EMD == 1
                    [collar_info_format, app.collar_length]  = comprs_count(data_preproc, app.depth, app.jntlgth, clr_len_def);
                else
                    [collar_info_format, app.collar_length]  = comprs_count(data_preproc, app.collar_depth, app.jntlgth, clr_len_def);
                end
                %end
                
                %% decision step
                % use all three anchor depth for fifth pipe
                collar_info = collar_info_format(:, 2:end);
                depth_info = collar_info_format(:, 1);                
                [table_P1, table_P2, table_P3, table_P4, table_P51]...
                    = decisionmaking(collar_info, depth_info, app.jntlgth, app.margin, app.achrdepth1);
                [~, ~, ~, ~, table_P52]...
                    = decisionmaking(collar_info, depth_info, app.jntlgth, app.margin, app.achrdepth2);
                [~, ~, ~, ~, table_P53]...
                    = decisionmaking(collar_info, depth_info, app.jntlgth, app.margin, app.achrdepth3);
                if a_achr(1) == 0
                    table_P51 = [1, 0, 1];
                end
                if a_achr(2) == 0
                    table_P52 = [1, 0, 1];
                end
                if a_achr(3) == 0
                    table_P53 = [1, 0, 1];
                end
                if table_P51 ~= 0
                    table_P5 = combineTable5(table_P51, table_P52, table_P53, transpose(a_achr), app.margin, app.jntlgth(5), [app.depth_min, app.depth_max]);
                else
                    table_P5 = 0;
                end
                
                % combine table_P5 three groups of results
                
                %% verification
                app.table_P1_v = 0;
                app.table_P2_v = 0;
                app.table_P3_v = 0;
                app.table_P4_v = 0;
                app.table_P5_v = 0;
                conf_rate = zeros(1, 5);
                if table_P1 ~= 0
                    app.table_P1_v = verify(table_P1, app.jntlgth(1), app.margin*1.5);
                    conf_rate(1) = 100*length(find(app.table_P1_v(:, 3) == 1))/size(app.table_P1_v, 1);
                end
                if table_P2 ~= 0
                    app.table_P2_v = verify(table_P2, app.jntlgth(2), app.margin*1.5);
                    conf_rate(2) = 100*length(find(app.table_P2_v(:, 3) == 1))/size(app.table_P2_v, 1);
                end
                if table_P3 ~= 0
                    app.table_P3_v = verify(table_P3, app.jntlgth(3), app.margin*1.5);
                    conf_rate(3) = 100*length(find(app.table_P3_v(:, 3) == 1))/size(app.table_P3_v, 1);
                end
                if table_P4 ~= 0
                    app.table_P4_v = verify(table_P4, app.jntlgth(4), app.margin*1.5);
                    conf_rate(4) = 100*length(find(app.table_P4_v(:, 3) == 1))/size(app.table_P4_v, 1);
                end
                if table_P5 ~= 0
                    app.table_P5_v = verify(table_P5, app.jntlgth(5), app.margin*1.5);
                    conf_rate(5) = 100*length(find(app.table_P5_v(:, 3) == 1))/size(app.table_P5_v, 1);
                end
                
                % enable button for collar VDL
                if app.GenerateCollarVDLCheckBox.Value == 1
                    set(app.ShowCollarVDLButton, 'Enable', 'on');
                end
                
                % calculate confidence rate
                app.EditField_38.Value = ceil(conf_rate(1));
                app.EditField_39.Value = ceil(conf_rate(2));
                app.EditField_40.Value = ceil(conf_rate(3));
                app.EditField_41.Value = ceil(conf_rate(4));
                app.EditField_42.Value = ceil(conf_rate(5));
                
                msgbox("Calculation completed!");
        end

        % Button pushed function: ExportCSVButton
        function ExportCSVButtonPushed(app, event)
            if app.table_P1_v ~= 0
                txtFileCreate1 = fopen('NewPipe1Ends.txt', 'w');
                table_P1_o = output(app.table_P1_v, app.collar_length, app.jntlgth(1));
                fprintf(txtFileCreate1, 'Joint End Table,,,,,,,\n') ;
                fprintf(txtFileCreate1, '%.0f,8,8,\n', size(table_P1_o, 1)) ;
                fprintf(txtFileCreate1, 'Index,BodyBottom,BodyTop,BodyLength,CollarLength,CollarBottom,CollarTop,Definition,\n') ;
                for i = 1:size(table_P1_o, 1)
                    fprintf(txtFileCreate1, '%.0f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.0f,\n', table_P1_o(i, :));
                end
            end
            if app.table_P2_v ~= 0
                txtFileCreate2 = fopen('NewPipe2Ends.txt', 'w');
                table_P2_o = output(app.table_P2_v, app.collar_length, app.jntlgth(2));
                fprintf(txtFileCreate2, 'Joint End Table,,,,,,,\n') ;
                fprintf(txtFileCreate2, '%.0f,8,8,\n', size(table_P2_o, 1)) ;
                fprintf(txtFileCreate2, 'Index,BodyBottom,BodyTop,BodyLength,CollarLength,CollarBottom,CollarTop,Definition,\n') ;
                for i = 1:size(table_P2_o, 1)
                    fprintf(txtFileCreate2, '%.0f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.0f,\n', table_P2_o(i, :));
                end
            end
            if app.table_P3_v ~= 0
                txtFileCreate3 = fopen('NewPipe3Ends.txt', 'w');
                table_P3_o = output(app.table_P3_v, app.collar_length, app.jntlgth(3));
                fprintf(txtFileCreate3, 'Joint End Table,,,,,,,\n') ;
                fprintf(txtFileCreate3, '%.0f,8,8,\n', size(table_P3_o, 1)) ;
                fprintf(txtFileCreate3, 'Index,BodyBottom,BodyTop,BodyLength,CollarLength,CollarBottom,CollarTop,Definition,\n') ;
                for i = 1:size(table_P3_o, 1)
                    fprintf(txtFileCreate3, '%.0f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.0f,\n', table_P3_o(i, :));
                end
            end
            if app.table_P4_v ~= 0
                txtFileCreate4 = fopen('NewPipe4Ends.txt', 'w');
                table_P4_o = output(app.table_P4_v, app.collar_length, app.jntlgth(4));
                fprintf(txtFileCreate4, 'Joint End Table,,,,,,,\n') ;
                fprintf(txtFileCreate4, '%.0f,8,8,\n', size(table_P4_o, 1)) ;
                fprintf(txtFileCreate4, 'Index,BodyBottom,BodyTop,BodyLength,CollarLength,CollarBottom,CollarTop,Definition,\n') ;
                for i = 1:size(table_P4_o, 1)
                    fprintf(txtFileCreate4, '%.0f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.0f,\n', table_P4_o(i, :));
                end
            end
            if app.table_P5_v ~= 0
                txtFileCreate5 = fopen('NewPipe5Ends.txt', 'w');
                table_P5_o = output(app.table_P5_v, app.collar_length, app.jntlgth(5));
                fprintf(txtFileCreate5, 'Joint End Table,,,,,,,\n') ;
                fprintf(txtFileCreate5, '%.0f,8,8,\n', size(table_P5_o, 1)) ;
                fprintf(txtFileCreate5, 'Index,BodyBottom,BodyTop,BodyLength,CollarLength,CollarBottom,CollarTop,Definition,\n') ;
                for i = 1:size(table_P5_o, 1)
                    fprintf(txtFileCreate5, '%.0f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.0f,\n', table_P5_o(i, :));
                end
            end
            
            msgbox("Output complete.");
        end

        % Value changed function: EditField_31
        function EditField_11ValueChanged(app, event)
            value = app.EditField_31.Value;
            
        end

        % Button pushed function: ShowVDLButton
        function ShowVDLButtonPushed(app, event)
            app.UIAxes.cla;
            app.newmap=newmap();
            
            x = (1:size(app.vdl, 2));
            imagesc(app.UIAxes, x, app.depth, app.vdl);
            colormap(app.UIAxes,app.newmap);
            caxis(app.UIAxes,[app.ColorScaleEditField.Value, app.toEditField_3.Value]);
            % colorbar(app.UIAxes,'northoutside');
            app.UIAxes.YLim = [app.DepthEditField.Value, app.toEditField_2.Value];
            app.UIAxes.XLim = [1, size(app.vdl, 2)];
            
        end

        % Button pushed function: OriginalVDLRescaleButton
        function OriginalVDLRescaleButtonPushed(app, event)
            app.UIAxes.cla;
            app.newmap=newmap();
            
            x = (1:size(app.vdl, 2));
            imagesc(app.UIAxes, x, app.depth, app.vdl);
            colormap(app.UIAxes,app.newmap);
            caxis(app.UIAxes,[app.ColorScaleEditField.Value, app.toEditField_3.Value]);
            % colorbar(app.UIAxes,'northoutside');
            app.UIAxes.YLim = [app.DepthEditField.Value, app.toEditField_2.Value];
            app.UIAxes.XLim = [app.CHEditField.Value, app.toEditField.Value];
        end

        % Button pushed function: ShowCollarVDLButton
        function ShowCollarVDLButtonPushed(app, event)
            set(app.CollarVDLRescaleButton, 'Enable', 'on')
            
            app.UIAxes.cla;
            app.newmap=newmap();
            
            x = (1:size(app.collar_vdl, 2));
            imagesc(app.UIAxes, x, app.collar_depth, app.collar_vdl);
            colormap(app.UIAxes,app.newmap);
            caxis(app.UIAxes,[app.ColorScaleEditField.Value, app.toEditField_3.Value]);
            % colorbar(app.UIAxes,'northoutside');
            app.UIAxes.YLim = [app.DepthEditField.Value, app.toEditField_2.Value];
            app.UIAxes.XLim = [app.CHEditField.Value, app.toEditField.Value];
            hold on;
            
        end

        % Button pushed function: CollarVDLRescaleButton
        function CollarVDLRescaleButtonPushed(app, event)
            set(app.CollarVDLRescaleButton, 'Enable', 'on')
            
            app.UIAxes.cla;
            app.newmap=newmap();
            
            x = (1:size(app.collar_vdl, 2));
            imagesc(app.UIAxes, x, app.collar_depth, app.collar_vdl);
            colormap(app.UIAxes,app.newmap);
            caxis(app.UIAxes,[app.ColorScaleEditField.Value, app.toEditField_3.Value]);
            % colorbar(app.UIAxes,'northoutside');
            app.UIAxes.YLim = [app.DepthEditField.Value, app.toEditField_2.Value];
            app.UIAxes.XLim = [app.CHEditField.Value, app.toEditField.Value];
        end

        % Value changed function: GenerateCollarVDLCheckBox
        function GenerateCollarVDLCheckBoxValueChanged(app, event)
            value = app.GenerateCollarVDLCheckBox.Value;
            
        end

        % Button pushed function: ShowonVDLButton
        function ShowonVDLButtonPushed(app, event)
            result_No = app.PipeEditField.Value;
            
            if str2double(result_No) > app.EditField_31.Value
                errordlg('Exceed total pipe number', 'Error');
            elseif isempty(app.PipeEditField.Value)
                errordlg('Please type in pipe number', 'Error');
            else
                app.UIAxes.cla;
                app.newmap=newmap();
                
                x = (1:size(app.vdl, 2));
                imagesc(app.UIAxes, x, app.depth, app.vdl);
                colormap(app.UIAxes,app.newmap);
                caxis(app.UIAxes,[app.ColorScaleEditField.Value, app.toEditField_3.Value]);
                % colorbar(app.UIAxes,'northoutside');
                app.UIAxes.YLim = [app.DepthEditField.Value, app.toEditField_2.Value];
                app.UIAxes.XLim = [app.CHEditField.Value, app.toEditField.Value];
                
                if str2double(result_No) == 1
                    table = app.table_P1_v;
                elseif str2double(result_No) == 2
                    table = app.table_P2_v;
                elseif str2double(result_No) == 3
                    table = app.table_P3_v;
                elseif str2double(result_No) == 4
                    table = app.table_P4_v;
                elseif str2double(result_No) == 5
                    table = app.table_P5_v;
                end
                
                yy = table(:, 2);
                xx = ones(size(yy));
                xxx = xx * size(app.VDL, 2);
                hold(app.UIAxes, 'on');
                plot2 = plot(app.UIAxes, xx, yy, 'o', 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'w');
                for i = 1:size(yy, 1)
                    text(app.UIAxes, xx(i)+1, yy(i), num2str(i), 'FontWeight', 'bold');
                end
                plot3 = plot(app.UIAxes, xxx, yy, 'o', 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'w');
                for i = 1:size(yy, 1)
                    text(app.UIAxes, xxx(i)-4, yy(i), num2str(i), 'FontWeight', 'bold');
                end
                
                
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'VDL')
            xlabel(app.UIAxes, 'CH')
            ylabel(app.UIAxes, 'Depth')
            app.OriginalVDLRescaleButton = uibutton(app.UIFigure, 'push');
            app.OriginalVDLRescaleButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalVDLRescaleButtonPushed, true);
            app.OriginalVDLRescaleButton.Text = 'Original VDL Rescale';
            app.DepthEditFieldLabel = uilabel(app.UIFigure);
            app.DepthEditFieldLabel.Text = 'Depth';
            app.DepthEditField = uieditfield(app.UIFigure, 'numeric');
            app.DepthEditField.Value = 0.1;
            app.CHEditFieldLabel = uilabel(app.UIFigure);
            app.CHEditFieldLabel.Text = 'CH';
            app.CHEditField = uieditfield(app.UIFigure, 'numeric');
            app.CHEditField.Value = 1;
            app.toEditFieldLabel = uilabel(app.UIFigure);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.UIFigure, 'numeric');
            app.toEditField.Value = 96;
            app.toEditField_2Label = uilabel(app.UIFigure);
            app.toEditField_2Label.Text = 'to';
            app.toEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.toEditField_2.Value = 10000;
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.LoadVDLDataButton = uibutton(app.InputPanel, 'push');
            app.LoadVDLDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVDLDataButtonPushed, true);
            app.LoadVDLDataButton.Text = 'Load VDL Data';
            app.EditField = uieditfield(app.InputPanel, 'text');
            app.LoadWellSchematicsButton = uibutton(app.InputPanel, 'push');
            app.LoadWellSchematicsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWellSchematicsButtonPushed, true);
            app.LoadWellSchematicsButton.Text = 'Load Well Schematics';
            app.EditField_24 = uieditfield(app.InputPanel, 'text');
            app.SectionsDropDownLabel = uilabel(app.InputPanel);
            app.SectionsDropDownLabel.Text = 'Sections:';
            app.SectionsDropDown = uidropdown(app.InputPanel);
            app.SectionsDropDown.Items = {'Piple Sections'};
            app.SectionsDropDown.ValueChangedFcn = createCallbackFcn(app, @SectionsDropDownValueChanged, true);
            app.SectionsDropDown.Value = 'Piple Sections';
            app.ShowVDLButton = uibutton(app.InputPanel, 'push');
            app.ShowVDLButton.ButtonPushedFcn = createCallbackFcn(app, @ShowVDLButtonPushed, true);
            app.ShowVDLButton.Tooltip = {'Color scale default is 0.9-1.1'};
            app.ShowVDLButton.Text = 'Show VDL';
            app.PreparationProcessingPanel = uipanel(app.UIFigure);
            app.PreparationProcessingPanel.Title = 'Preparation & Processing';
            app.DataPreprocessingDropDownLabel = uilabel(app.PreparationProcessingPanel);
            app.DataPreprocessingDropDownLabel.Text = 'Data Pre-processing';
            app.DataPreprocessingDropDown = uidropdown(app.PreparationProcessingPanel);
            app.DataPreprocessingDropDown.Items = {'MVMD', 'EMD'};
            app.DataPreprocessingDropDown.Value = 'EMD';
            app.RunButton = uibutton(app.PreparationProcessingPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Pipe1innerLabel_2 = uilabel(app.PreparationProcessingPanel);
            app.Pipe1innerLabel_2.Text = 'Pipe 1 inner';
            app.Pipe2Label_2 = uilabel(app.PreparationProcessingPanel);
            app.Pipe2Label_2.Text = 'Pipe 2';
            app.EditField_19 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_20 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.Pipe3Label_2 = uilabel(app.PreparationProcessingPanel);
            app.Pipe3Label_2.Text = 'Pipe 3';
            app.EditField_21 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.CHLabel = uilabel(app.PreparationProcessingPanel);
            app.CHLabel.Text = 'CH:';
            app.Pipe4Label_2 = uilabel(app.PreparationProcessingPanel);
            app.Pipe4Label_2.Text = 'Pipe 4';
            app.EditField_22 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.Pipe5Label_2 = uilabel(app.PreparationProcessingPanel);
            app.Pipe5Label_2.Text = 'Pipe 5';
            app.EditField_23 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.Pipe1innerLabel_3 = uilabel(app.PreparationProcessingPanel);
            app.Pipe1innerLabel_3.Text = 'Pipe 1 inner';
            app.Pipe2Label_3 = uilabel(app.PreparationProcessingPanel);
            app.Pipe2Label_3.Text = 'Pipe 2';
            app.EditField_25 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_26 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_27 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_28 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.Pipe3Label_3 = uilabel(app.PreparationProcessingPanel);
            app.Pipe3Label_3.Text = 'Pipe 3';
            app.EditField_29 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_30 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.AnchorDepthftLabel = uilabel(app.PreparationProcessingPanel);
            app.AnchorDepthftLabel.Text = 'Anchor Depth (ft):';
            app.JointLenftLabel = uilabel(app.PreparationProcessingPanel);
            app.JointLenftLabel.Text = 'Joint Len (ft):';
            app.TotalpipenumberLabel = uilabel(app.PreparationProcessingPanel);
            app.TotalpipenumberLabel.Text = 'Total pipe number';
            app.EditField_31 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_31.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.Pipe4Label_3 = uilabel(app.PreparationProcessingPanel);
            app.Pipe4Label_3.Text = 'Pipe 4';
            app.EditField_32 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_33 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.Pipe5Label_3 = uilabel(app.PreparationProcessingPanel);
            app.Pipe5Label_3.Text = 'Pipe 5';
            app.EditField_34 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_35 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.MargininLabel = uilabel(app.PreparationProcessingPanel);
            app.MargininLabel.Text = 'Margin (in.)';
            app.EditField_36 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_36.ValueDisplayFormat = '%.1f';
            app.EditField_36.Value = 3;
            app.CollarlengthdefaultLabel = uilabel(app.PreparationProcessingPanel);
            app.CollarlengthdefaultLabel.Text = 'Collar length default';
            app.EditField_37 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_37.ValueDisplayFormat = '%.1f';
            app.EditField_37.Value = 2;
            app.ShowCollarVDLButton = uibutton(app.PreparationProcessingPanel, 'push');
            app.ShowCollarVDLButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCollarVDLButtonPushed, true);
            app.ShowCollarVDLButton.Enable = 'off';
            app.ShowCollarVDLButton.Text = 'Show Collar VDL ';
            app.Pipe1Label = uilabel(app.PreparationProcessingPanel);
            app.Pipe1Label.Text = 'Pipe 1';
            app.Pipe2Label_4 = uilabel(app.PreparationProcessingPanel);
            app.Pipe2Label_4.Text = 'Pipe 2';
            app.EditField_38 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_38.ValueDisplayFormat = '%.1f';
            app.EditField_38.Editable = 'off';
            app.EditField_39 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_39.ValueDisplayFormat = '%.1f';
            app.EditField_39.Editable = 'off';
            app.Pipe3Label_4 = uilabel(app.PreparationProcessingPanel);
            app.Pipe3Label_4.Text = 'Pipe 3';
            app.EditField_40 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_40.ValueDisplayFormat = '%.1f';
            app.EditField_40.Editable = 'off';
            app.Pipe4Label_4 = uilabel(app.PreparationProcessingPanel);
            app.Pipe4Label_4.Text = 'Pipe 4';
            app.EditField_41 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_41.ValueDisplayFormat = '%.1f';
            app.EditField_41.Editable = 'off';
            app.Pipe5Label_4 = uilabel(app.PreparationProcessingPanel);
            app.Pipe5Label_4.Text = 'Pipe 5';
            app.EditField_42 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_42.ValueDisplayFormat = '%.1f';
            app.EditField_42.Editable = 'off';
            app.ConfidenceRateLabel = uilabel(app.PreparationProcessingPanel);
            app.ConfidenceRateLabel.Text = 'Confidence Rate:';
            app.Label = uilabel(app.PreparationProcessingPanel);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.PreparationProcessingPanel);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.PreparationProcessingPanel);
            app.Label_3.Text = '%';
            app.Label_4 = uilabel(app.PreparationProcessingPanel);
            app.Label_4.Text = '%';
            app.Label_5 = uilabel(app.PreparationProcessingPanel);
            app.Label_5.Text = '%';
            app.GenerateCollarVDLCheckBox = uicheckbox(app.PreparationProcessingPanel);
            app.GenerateCollarVDLCheckBox.ValueChangedFcn = createCallbackFcn(app, @GenerateCollarVDLCheckBoxValueChanged, true);
            app.GenerateCollarVDLCheckBox.Text = 'Generate Collar VDL';
            app.EditField_43 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_44 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_45 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_46 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.ExportCSVButton = uibutton(app.ResultsPanel, 'push');
            app.ExportCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCSVButtonPushed, true);
            app.ExportCSVButton.Text = 'Export CSV';
            app.ShowonVDLButton = uibutton(app.ResultsPanel, 'push');
            app.ShowonVDLButton.ButtonPushedFcn = createCallbackFcn(app, @ShowonVDLButtonPushed, true);
            app.ShowonVDLButton.Text = 'Show on VDL';
            app.PipeLabel = uilabel(app.ResultsPanel);
            app.PipeLabel.Text = 'Pipe #';
            app.PipeEditField = uieditfield(app.ResultsPanel, 'text');
            app.CollarVDLRescaleButton = uibutton(app.UIFigure, 'push');
            app.CollarVDLRescaleButton.ButtonPushedFcn = createCallbackFcn(app, @CollarVDLRescaleButtonPushed, true);
            app.CollarVDLRescaleButton.Enable = 'off';
            app.CollarVDLRescaleButton.Text = 'Collar VDL Rescale';
            app.ColorScaleEditFieldLabel = uilabel(app.UIFigure);
            app.ColorScaleEditFieldLabel.Text = 'Color Scale';
            app.ColorScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColorScaleEditField.Value = 0.9;
            app.toEditField_3Label = uilabel(app.UIFigure);
            app.toEditField_3Label.Text = 'to';
            app.toEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.toEditField_3.Value = 1.1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = collar_picking_APP1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end