classdef collar_picking_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        RescaleButton                   matlab.ui.control.Button
        ftmLabel                        matlab.ui.control.Label
        DepthEditFieldLabel             matlab.ui.control.Label
        DepthEditField                  matlab.ui.control.NumericEditField
        CHEditFieldLabel                matlab.ui.control.Label
        CHEditField                     matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        toEditField                     matlab.ui.control.NumericEditField
        toEditField_2Label              matlab.ui.control.Label
        toEditField_2                   matlab.ui.control.NumericEditField
        InputPanel                      matlab.ui.container.Panel
        LoadVDLDataButton               matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        Pipe1innerLabel                 matlab.ui.control.Label
        Pipe2Label                      matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        Pipe3Label                      matlab.ui.control.Label
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_7                     matlab.ui.control.NumericEditField
        AnchorDepthLabel                matlab.ui.control.Label
        JointLenLabel                   matlab.ui.control.Label
        TotalpipenumberLabel            matlab.ui.control.Label
        EditField_11                    matlab.ui.control.NumericEditField
        Pipe4Label                      matlab.ui.control.Label
        EditField_12                    matlab.ui.control.NumericEditField
        EditField_13                    matlab.ui.control.NumericEditField
        Pipe5Label                      matlab.ui.control.Label
        EditField_14                    matlab.ui.control.NumericEditField
        EditField_15                    matlab.ui.control.NumericEditField
        MarginLabel                     matlab.ui.control.Label
        EditField_16                    matlab.ui.control.NumericEditField
        LoadWellSchematicsButton        matlab.ui.control.Button
        EditField_24                    matlab.ui.control.EditField
        SectionsDropDownLabel           matlab.ui.control.Label
        SectionsDropDown                matlab.ui.control.DropDown
        PreparationProcessingPanel      matlab.ui.container.Panel
        DataPreprocessingDropDownLabel  matlab.ui.control.Label
        DataPreprocessingDropDown       matlab.ui.control.DropDown
        DataScreeningDropDownLabel      matlab.ui.control.Label
        DataScreeningDropDown           matlab.ui.control.DropDown
        VerificationCheckBox            matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
        Pipe1innerLabel_2               matlab.ui.control.Label
        Pipe2Label_2                    matlab.ui.control.Label
        EditField_19                    matlab.ui.control.NumericEditField
        EditField_20                    matlab.ui.control.NumericEditField
        Pipe3Label_2                    matlab.ui.control.Label
        EditField_21                    matlab.ui.control.NumericEditField
        CHLabel                         matlab.ui.control.Label
        Pipe4Label_2                    matlab.ui.control.Label
        EditField_22                    matlab.ui.control.NumericEditField
        Pipe5Label_2                    matlab.ui.control.Label
        EditField_23                    matlab.ui.control.NumericEditField
        OutputPanel                     matlab.ui.container.Panel
        ExportButton_2                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        
        % load log data
        logDataPath % log data path
        lasSrcFilename % log data filename
        lasData % log las data
        lasHeader % log las header
        lasDetails % log las details
        lasDepth % log las depth
        VDL % vdl data from log las
                
        % load well schematic info
        wellSchemPath % well schematic xml file path
        pipeList % pipe list read from xml file
        sectionList % list for section name
        depth_max % bottom (max) of selected section
        depth_min % top (min) of selected section
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadVDLDataButton
        function LoadVDLDataButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.las'}, 'Load Log Data');
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg('No file selected', 'Error');
            end
            
            app.logDataPath = strcat(pathname, filename);
            app.EditField.Value = app.logDataPath;
            app.lasSrcFilename = filename;
            
            % read las
            [ app.lasData, app.lasHeader, app.lasDetails ] = ImportLAS(filename);
            
            % get all decays and eliminate all ADEC and depth with ADEC[1] <= 0
            i_ADEC = ~cellfun('isempty', regexp(app.lasHeader, '^ADEC\[\d+\]_n$', 'match'));
            VDL = app.lasData(:, i_ADEC);
            VDL = VDL(:, 1);
            nozeroNum = find(VDL > 0, 1);
            app.VDL = app.lasData(nozeroNum : end, i_ADEC);
            % get all decays cont.
            app.lasDepth = app.lasData(nozeroNum : end, strcmp(app.lasHeader, 'Depth') | strcmp(app.lasHeader, 'DEPT'));
            app.lasDepth = app.lasDepth(:, 1);
            
            function [ data, header, detail ] = ImportLAS( filename )
                %ImportLAS Import .las file
                % Input:
                %   filename    target file name
                %
                % Output:
                %   data        numeric data
                %   header      header of data
                %   detail      structure of more detailed info
                
                %% File Operations
                % Open the text file.
                fileID = fopen(filename, 'r');
                
                % read in las as space delimited string
                formatSpec = '%s%[^\n\r]';
                
                % Read first 1000 lines to find header
                textArray = textscan(fileID, formatSpec, 1000, 'Delimiter', ' ', ...
                    'MultipleDelimsAsOne', true,  'ReturnOnError', false);
                
                % find '~A' as the header line start, '#' for additional header lines
                i_labeledheader = find(strcmp(textArray{1}, '~A') | ...
                    strcmp(textArray{1}, '#'));
                
                % read header lines
                i_header = GetTureHeader(textArray{2}(i_labeledheader), i_labeledheader);
                [header, n_col] = Block2Vector(textArray{2}(i_header), 'string');
                
                % Read in data block
                frewind(fileID);
                formatSpec = '%[^\n\r]';
                dataArray = textscan(fileID, formatSpec, 'Delimiter', ' ', ...
                    'MultipleDelimsAsOne', true, 'HeaderLines', i_labeledheader(end), ...
                    'ReturnOnError', false);
                
                % Close the text file.
                fclose(fileID);
                
                %% Data Output
                % parse data block
                row_block = length(i_header);
                col_total = length(header);
                raw = dataArray{1};
                n_depth = length(raw) / row_block;
                
                % read data
                data = zeros(n_depth, col_total);
                for i = 1:n_depth
                    % read data lines by block
                    [ data_depth, ~ ] = Block2Vector(raw((i - 1) * row_block + ...
                        (1:row_block)), 'float');
                    data(i, :) = data_depth;
                end
                
                % build detail structure for output
                detail = struct;
                detail.i_datastart = i_labeledheader(end) + 1;
                detail.n_col = n_col;
                
            end
            
            function [ i_trueheader ] = GetTureHeader( data, i_header )
                %GetTureHeader Remove empty lines of labeled header
                % Input:
                %   data            input data, n x 1 cell
                %   i_header        labeled header lines
                %
                % Output:
                %   i_trueheader    filled header lines
                
                i_trueheader = zeros(size(i_header));
                len = size(data, 1);
                
                index = 1;
                for i = 1:len
                    line = strtrim(data{i});
                    if isempty(line)
                        continue;
                    end
                    i_trueheader(index) = i_header(i);
                    index = index + 1;
                end
                
                i_trueheader = i_trueheader(1:(index - 1));
                
            end
            
            function [ output, n_col ] = Block2Vector( data, option )
                %Block2Vector Convert block data to 1 dimensional vector
                % Input:
                %   data        input data, n x 1 cell
                %   option      'string' or 'float'
                %
                % Output:
                %   output      output data, 1 x m vector
                %   n_col       column size, n x 1 vector
                
                if strcmp(option, 'string')
                    formatSpec = '%s';
                    output = cell(1, 200);
                    type = 1;
                else
                    formatSpec = '%f';
                    output = zeros(1, 200);
                    type = 2;
                end
                
                len = size(data, 1);
                n_col = zeros(len, 1);
                index = 0;
                for i = 1:len
                    if type == 1
                        line = strsplit(strtrim(data{i}));
                        n = size(line, 2);
                    else
                        [line, n] = sscanf(data{i}, formatSpec);
                    end
                    
                    output(index + (1:n)) = line;
                    index = index + n;
                    n_col(i) = n;
                end
                
                if index > 0
                    output = output(1:index);
                else
                    output = [];
                    n_col = [];
                end
                
            end
        end

        % Button pushed function: LoadWellSchematicsButton
        function LoadWellSchematicsButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.xml'}, 'Load Well Schematics');
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg("No file selected", 'Error');
            end
            
            app.wellSchemPath = strcat(pathname, filename);
            app.EditField_24.Value = app.wellSchemPath;
            
            % Import well schematic in xml file
            % Input:
            %   filename    target file name
            %
            % Output:
            %   pipeList    pipe list
            
            % labels to read
            labels = {'ItemID', 'TopDepth', 'BottomDepth', 'NomOD', 'NomThickness'};
            
            % as = fullfile(pathname, filename);
            % read xml file
            xDoc = xmlread(filename);
            
            % find all pipe section
            allListitems = xDoc.getElementsByTagName('EMDSPipeSection');
            
            % get listnode information
            for i = 1:allListitems.getLength
                thisListitem = allListitems.item(i - 1);
                
                % read the labels
                for j = 1:length(labels)
                    thisList = thisListitem.getElementsByTagName(labels{j});
                    thisElement = thisList.item(0);
                    app.pipeList(i).(labels{j}) = ...
                        str2double(thisElement.getFirstChild.getData);
                end
            end
            
            % generate a string for the dropdown button
            depths = unique([app.pipeList.TopDepth, app.pipeList.BottomDepth]);
            app.sectionList = strings(1, (length(depths) - 1));
            for j = 1:(length(depths) - 1)
                n = 0;
                % use median of section depth to count pipe number
                depthmid = mean([depths(j), depths(j + 1)]);
                for k = 1 : app.pipeList(end).ItemID
                    if depthmid >= app.pipeList(k).TopDepth && depthmid <= app.pipeList(k).BottomDepth
                        n = n + 1;
                    end
                end
                pipeNum = num2str(n);
                app.sectionList(j) = strcat(pipeNum, " Pipes Section");
            end
            
            app.SectionsDropDown.Items = app.sectionList;
            app.SectionsDropDown.ItemsData = 1 : length(app.SectionsDropDown.Items);
            
        end

        % Value changed function: SectionsDropDown
        function SectionsDropDownValueChanged(app, event)
            value = app.SectionsDropDown.Value;
            
            depths = unique([app.pipeList.TopDepth, app.pipeList.BottomDepth]);
            
            % depth selection
            app.depth_min = depths(value);
            app.depth_max = depths(value + 1);
            
            i_Section = zeros(size(app.lasDepth, 1), 1); % logic array for selected sectional depth (1 depth only)
            
            for d = 1:size(app.lasDepth, 1)
                if app.lasDepth(d) >= app.depth_min && app.lasDepth(d) <= app.depth_max
                    i_Section(d) = 1;
                end
            end
            i_Section = logical(i_Section);
            
%             % display depth of the section mid
%             app.EditField_18.Value = (app.depth_min + app.depth_max)/2;
            
            % store selected ADEC in a varible for selected depth
            app.sectional_VDL = app.VDL(i_Section, :);
            app.sectional_Dep = app.lasDepth(i_Section, :);       
            a=1;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'VDL')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RescaleButton = uibutton(app.UIFigure, 'push');
            app.RescaleButton.Text = 'Rescale';
            app.ftmLabel = uilabel(app.UIFigure);
            app.ftmLabel.Text = 'ft/m';
            app.DepthEditFieldLabel = uilabel(app.UIFigure);
            app.DepthEditFieldLabel.Text = 'Depth';
            app.DepthEditField = uieditfield(app.UIFigure, 'numeric');
            app.DepthEditField.Value = 0.1;
            app.CHEditFieldLabel = uilabel(app.UIFigure);
            app.CHEditFieldLabel.Text = 'CH';
            app.CHEditField = uieditfield(app.UIFigure, 'numeric');
            app.toEditFieldLabel = uilabel(app.UIFigure);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.UIFigure, 'numeric');
            app.toEditField.Value = 100;
            app.toEditField_2Label = uilabel(app.UIFigure);
            app.toEditField_2Label.Text = 'to';
            app.toEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.toEditField_2.Value = 10000;
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.LoadVDLDataButton = uibutton(app.InputPanel, 'push');
            app.LoadVDLDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVDLDataButtonPushed, true);
            app.LoadVDLDataButton.Text = 'Load VDL Data';
            app.EditField = uieditfield(app.InputPanel, 'text');
            app.Pipe1innerLabel = uilabel(app.InputPanel);
            app.Pipe1innerLabel.Text = 'Pipe1 inner';
            app.Pipe2Label = uilabel(app.InputPanel);
            app.Pipe2Label.Text = 'Pipe 2';
            app.EditField_2 = uieditfield(app.InputPanel, 'numeric');
            app.EditField_3 = uieditfield(app.InputPanel, 'numeric');
            app.EditField_4 = uieditfield(app.InputPanel, 'numeric');
            app.EditField_5 = uieditfield(app.InputPanel, 'numeric');
            app.Pipe3Label = uilabel(app.InputPanel);
            app.Pipe3Label.Text = 'Pipe 3';
            app.EditField_6 = uieditfield(app.InputPanel, 'numeric');
            app.EditField_7 = uieditfield(app.InputPanel, 'numeric');
            app.AnchorDepthLabel = uilabel(app.InputPanel);
            app.AnchorDepthLabel.Text = 'Anchor Depth:';
            app.JointLenLabel = uilabel(app.InputPanel);
            app.JointLenLabel.Text = 'Joint Len:';
            app.TotalpipenumberLabel = uilabel(app.InputPanel);
            app.TotalpipenumberLabel.Text = 'Total pipe number';
            app.EditField_11 = uieditfield(app.InputPanel, 'numeric');
            app.Pipe4Label = uilabel(app.InputPanel);
            app.Pipe4Label.Text = 'Pipe 4';
            app.EditField_12 = uieditfield(app.InputPanel, 'numeric');
            app.EditField_13 = uieditfield(app.InputPanel, 'numeric');
            app.Pipe5Label = uilabel(app.InputPanel);
            app.Pipe5Label.Text = 'Pipe 5';
            app.EditField_14 = uieditfield(app.InputPanel, 'numeric');
            app.EditField_15 = uieditfield(app.InputPanel, 'numeric');
            app.MarginLabel = uilabel(app.InputPanel);
            app.MarginLabel.Text = 'Margin';
            app.EditField_16 = uieditfield(app.InputPanel, 'numeric');
            app.EditField_16.ValueDisplayFormat = '%.1f';
            app.EditField_16.Value = 3;
            app.LoadWellSchematicsButton = uibutton(app.InputPanel, 'push');
            app.LoadWellSchematicsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWellSchematicsButtonPushed, true);
            app.LoadWellSchematicsButton.Text = 'Load Well Schematics';
            app.EditField_24 = uieditfield(app.InputPanel, 'text');
            app.SectionsDropDownLabel = uilabel(app.InputPanel);
            app.SectionsDropDownLabel.Text = 'Sections:';
            app.SectionsDropDown = uidropdown(app.InputPanel);
            app.SectionsDropDown.Items = {'Piple Sections'};
            app.SectionsDropDown.ValueChangedFcn = createCallbackFcn(app, @SectionsDropDownValueChanged, true);
            app.SectionsDropDown.Value = 'Piple Sections';
            app.PreparationProcessingPanel = uipanel(app.UIFigure);
            app.PreparationProcessingPanel.Title = 'Preparation & Processing';
            app.DataPreprocessingDropDownLabel = uilabel(app.PreparationProcessingPanel);
            app.DataPreprocessingDropDownLabel.Text = 'Data Pre-processing';
            app.DataPreprocessingDropDown = uidropdown(app.PreparationProcessingPanel);
            app.DataPreprocessingDropDown.Items = {'EMD', 'MVMD'};
            app.DataPreprocessingDropDown.Value = 'EMD';
            app.DataScreeningDropDownLabel = uilabel(app.PreparationProcessingPanel);
            app.DataScreeningDropDownLabel.Text = 'Data Screening';
            app.DataScreeningDropDown = uidropdown(app.PreparationProcessingPanel);
            app.DataScreeningDropDown.Items = {'Threshold', 'Peak Counting'};
            app.DataScreeningDropDown.Value = 'Threshold';
            app.VerificationCheckBox = uicheckbox(app.PreparationProcessingPanel);
            app.VerificationCheckBox.Text = 'Verification';
            app.RunButton = uibutton(app.PreparationProcessingPanel, 'push');
            app.RunButton.Text = 'Run';
            app.Pipe1innerLabel_2 = uilabel(app.PreparationProcessingPanel);
            app.Pipe1innerLabel_2.Text = 'Pipe1 inner';
            app.Pipe2Label_2 = uilabel(app.PreparationProcessingPanel);
            app.Pipe2Label_2.Text = 'Pipe 2';
            app.EditField_19 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.EditField_20 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.Pipe3Label_2 = uilabel(app.PreparationProcessingPanel);
            app.Pipe3Label_2.Text = 'Pipe 3';
            app.EditField_21 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.CHLabel = uilabel(app.PreparationProcessingPanel);
            app.CHLabel.Text = 'CH:';
            app.Pipe4Label_2 = uilabel(app.PreparationProcessingPanel);
            app.Pipe4Label_2.Text = 'Pipe 4';
            app.EditField_22 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.Pipe5Label_2 = uilabel(app.PreparationProcessingPanel);
            app.Pipe5Label_2.Text = 'Pipe 5';
            app.EditField_23 = uieditfield(app.PreparationProcessingPanel, 'numeric');
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.ExportButton_2 = uibutton(app.OutputPanel, 'push');
            app.ExportButton_2.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = collar_picking_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end