classdef ePDT_Model_vs_Tool_VerificationV21_NewCali < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        WellschematicLabel         matlab.ui.control.Label
        PipeThicknessChangePanel   matlab.ui.container.Panel
        ToolDataPanel              matlab.ui.container.Panel
        LoadToolDataButton         matlab.ui.control.Button
        EditField_17               matlab.ui.control.NumericEditField
        EditField_18               matlab.ui.control.NumericEditField
        mLabel                     matlab.ui.control.Label
        mLabel_2                   matlab.ui.control.Label
        DataDEPTHforCalcLabel      matlab.ui.control.Label
        DepthwindowLabel           matlab.ui.control.Label
        EditField_19               matlab.ui.control.EditField
        DisplayWindowButton        matlab.ui.control.Button
        LoadINIFileButton          matlab.ui.control.Button
        EditField_20               matlab.ui.control.EditField
        LoadWellSchematicButton    matlab.ui.control.Button
        EditField_23               matlab.ui.control.EditField
        CombineButton              matlab.ui.control.Button
        SectionsDropDownLabel      matlab.ui.control.Label
        SectionsDropDown           matlab.ui.control.DropDown
        MaxIndexSMLLabel           matlab.ui.control.Label
        EditField_36               matlab.ui.control.NumericEditField
        EditField_37               matlab.ui.control.NumericEditField
        EditField_38               matlab.ui.control.NumericEditField
        ApplyAngleButton           matlab.ui.control.Button
        MinIndexSMLLabel           matlab.ui.control.Label
        EditField_39               matlab.ui.control.NumericEditField
        EditField_40               matlab.ui.control.NumericEditField
        EditField_41               matlab.ui.control.NumericEditField
        LoadSavedDataButton        matlab.ui.control.Button
        EditField_48               matlab.ui.control.EditField
        SectionalDataCheckBox      matlab.ui.control.CheckBox
        ExportDataButton           matlab.ui.control.Button
        ModelPanel                 matlab.ui.container.Panel
        SSensorLabel               matlab.ui.control.Label
        MSensorLabel               matlab.ui.control.Label
        EditField_5                matlab.ui.control.NumericEditField
        EditField_6                matlab.ui.control.NumericEditField
        EditField_7                matlab.ui.control.NumericEditField
        EditField_8                matlab.ui.control.NumericEditField
        LSensorLabel               matlab.ui.control.Label
        EditField_9                matlab.ui.control.NumericEditField
        EditField_10               matlab.ui.control.NumericEditField
        BLabel                     matlab.ui.control.Label
        ALabel                     matlab.ui.control.Label
        EditField_11               matlab.ui.control.NumericEditField
        EditField_12               matlab.ui.control.NumericEditField
        EditField_13               matlab.ui.control.NumericEditField
        CLabel                     matlab.ui.control.Label
        EditField_14               matlab.ui.control.NumericEditField
        EditField_15               matlab.ui.control.NumericEditField
        EditField_16               matlab.ui.control.NumericEditField
        WallLossLabel              matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Pipe1Label                 matlab.ui.control.Label
        Pipe2Label                 matlab.ui.control.Label
        Pipe3Label                 matlab.ui.control.Label
        NominalLabel               matlab.ui.control.Label
        TubingLabel                matlab.ui.control.Label
        CasingLabel                matlab.ui.control.Label
        MuLabel                    matlab.ui.control.Label
        SigmaLabel                 matlab.ui.control.Label
        EditField                  matlab.ui.control.NumericEditField
        EditField_2                matlab.ui.control.NumericEditField
        EditField_3                matlab.ui.control.NumericEditField
        EditField_4                matlab.ui.control.NumericEditField
        FindParametersButton       matlab.ui.control.Button
        CalcandPlotButton          matlab.ui.control.Button
        ApplyParametersButton      matlab.ui.control.Button
        EditField_24               matlab.ui.control.NumericEditField
        EditField_25               matlab.ui.control.NumericEditField
        Label_8                    matlab.ui.control.Label
        Label_9                    matlab.ui.control.Label
        Pipe4Label                 matlab.ui.control.Label
        Pipe5Label                 matlab.ui.control.Label
        SLabel                     matlab.ui.control.Label
        MLabel                     matlab.ui.control.Label
        EditField_26               matlab.ui.control.NumericEditField
        EditField_27               matlab.ui.control.NumericEditField
        EditField_28               matlab.ui.control.NumericEditField
        EditField_29               matlab.ui.control.NumericEditField
        LLabel                     matlab.ui.control.Label
        EditField_30               matlab.ui.control.NumericEditField
        EditField_31               matlab.ui.control.NumericEditField
        Label_11                   matlab.ui.control.Label
        Label_10                   matlab.ui.control.Label
        CalculateParametersButton  matlab.ui.control.Button
        CalcandPlotButton_2        matlab.ui.control.Button
        ApplyParametersButton_2    matlab.ui.control.Button
        EditField_32               matlab.ui.control.NumericEditField
        EditField_33               matlab.ui.control.NumericEditField
        EditField_34               matlab.ui.control.NumericEditField
        DLabel                     matlab.ui.control.Label
        CombineButton_2            matlab.ui.control.Button
        CombineButton_3            matlab.ui.control.Button
        MSmLabel                   matlab.ui.control.Label
        MSmLabel_2                 matlab.ui.control.Label
        EditField_35               matlab.ui.control.NumericEditField
        Label_12                   matlab.ui.control.Label
        Pipe6Label                 matlab.ui.control.Label
        EditField_42               matlab.ui.control.NumericEditField
        EditField_43               matlab.ui.control.NumericEditField
        EditField_44               matlab.ui.control.NumericEditField
        EditField_45               matlab.ui.control.NumericEditField
        EditField_46               matlab.ui.control.NumericEditField
        EditField_47               matlab.ui.control.NumericEditField
        CHLabel                    matlab.ui.control.Label
        WLLabel                    matlab.ui.control.Label
        ePDTModelvsToolVerificationLabel  matlab.ui.control.Label
        RescaleButton              matlab.ui.control.Button
        VoltageEditField_2Label    matlab.ui.control.Label
        VoltageEditField_2         matlab.ui.control.NumericEditField
        CHEditFieldLabel           matlab.ui.control.Label
        CHEditField                matlab.ui.control.NumericEditField
        toEditField_2Label         matlab.ui.control.Label
        toEditField_2              matlab.ui.control.NumericEditField
        toEditField_3Label         matlab.ui.control.Label
        toEditField_3              matlab.ui.control.NumericEditField
        mVLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        % general varibles
        FM_x % time axis
        
        % load log data
        logDataPath % log data path
        lasSrcFilename % log data filename
        lasData % log las data
        lasHeader % log las header
        lasDetails % log las details
        lasDepth % log las depth
        testMode % test mode from las
        ADEC % decay data from log las
        
        % load config info
        configFileName % config file name
        configPath % config file path
        testConfig % config info from ini
        
        % load well schematic info
        wellSchemPath % well schematic xml file path
        pipeList % pipe list read from xml file
        sectionList % list for section name
        depth_max % bottom (max) of selected section
        depth_min % top (min) of selected section
        
        % data initialization
        ModeUsed % mode used according to config and ini
        selected_ADEC % selected ADEC for processing
        ADEC_section % sectional ADEC for selected section of depth
        log_Max % max value of log raw data
        log_Min % min value of log raw data
        log_Med_lin % median value of log raw data, used as nominal values
        log_Med % median value of log raw data, used as nominal values with natural log
        log_Thin_lin % median value of log raw data at selected depth
        log_Thin % % median value of log raw data at selected depth, used as nominal values with natural log
        log_Max_perm % max value reserved for matching WL and enlarge gap before display
        log_Min_perm % min value reserved for matching WL and enlarge gap before display
        log_Max_sect % sectional max of selected section
        log_Min_sect % sectional min of selected section
        log_med_sect % sectional nom of selected section
        log_Max_sect_perm % sectional max perminent storage
        log_Min_sect_perm % sectional min perminent storage
        
        % data processing
        CH_S % channel for S sensor
        CH_M % channel for M sensor
        CH_L % channel for L sensor
        
        % data for FM
        n_p % number of pipes
        od_p % outer diameter of each pipe, mm
        tk_p % thickness of each pipe, mm
        loss_tk % thickness of each pipe after wall loss, mm
        sig_p % sigma of pipes
        mur_p % mu of pipes
        pipe % pipe index of enabled blank for ML
        FM_nom_lin % calculated nominal FM data in CH of all sensors
        FM_nom_linS % calculated nominal FM data in CH of S sensors
        FM_nom_linM % calculated nominal FM data in CH of M sensors
        FM_nom_linL % calculated nominal FM data in CH of L sensors
        FM_nom % calculated nominal FM data in CH of all sensors with natural log
        % FM of Thin/Thick
        tk_pThin % thickness of each pipe of selected depth, mm
        FM_thin_lin % calculated thin FM data in CH of all sensors
        FM_thin_linS % calculated thin FM data in CH of S sensors
        FM_thin_linM % calculated thin FM data in CH of M sensors
        FM_thin_linL % calculated thin FM data in CH of L sensors
        FM_thin % calculated thin FM data in CH of all sensors with natural log FM of Max
        FM_Max_lin % calculated max FM data in CH of all sensors with natural log
        degain % array for degain
        
        % data for adjustment
        lognom_S % log data slice
        logthin_S % log data slice
        logmax_S % log data slice
        lognom_M % log data slice
        logthin_M % log data slice
        logmax_M % log data slice
        lognom_L % log data slice
        logthin_L % log data slice
        logmax_L % log data slice
        sensiRatio % sensitivity ratio of log max to FM max
        
        Pn_S % index of third order term of FM nom
        Qn_S % index of second order term of FM nom
        Rn_S % index of first order term of FM nom
        Sn_S % index of constant term of FM nom
        
        Pn_M % index of third order term of FM nom
        Qn_M % index of second order term of FM nom
        Rn_M % index of first order term of FM nom
        Sn_M % index of constant term of FM nom
        
        Pn_L % index of third order term of FM nom
        Qn_L % index of second order term of FM nom
        Rn_L % index of first order term of FM nom
        Sn_L % index of constant term of FM nom
        
        Pt_S % index of third order term of FM thin
        Qt_S % index of second order term of FM thin
        Rt_S % index of first order term of FM thin
        St_S % index of constant term of FM thin
        
        Pt_M % index of third order term of FM thin
        Qt_M % index of second order term of FM thin
        Rt_M % index of first order term of FM thin
        St_M % index of constant term of FM thin
        
        Pt_L % index of third order term of FM thin
        Qt_L % index of second order term of FM thin
        Rt_L % index of first order term of FM thin
        St_L % index of constant term of FM thin
        
        Pm_S % index of third order term of FM thin
        Qm_S % index of second order term of FM thin
        Rm_S % index of first order term of FM thin
        Sm_S % index of constant term of FM thin
        
        Pm_M % index of third order term of FM thin
        Qm_M % index of second order term of FM thin
        Rm_M % index of first order term of FM thin
        Sm_M % index of constant term of FM thin
        
        Pm_L % index of third order term of FM thin
        Qm_L % index of second order term of FM thin
        Rm_L % index of first order term of FM thin
        Sm_L % index of constant term of FM thin
        
        % factors for adjustment
        An_S % factor for adjusting third order term of FM nom
        Bn_S % factor for adjusting second order term of FM nom
        Cn_S % factor for adjusting first order term of FM nom
        Dn_S % factor for adjusting constant term of FM nom
        
        An_M % factor for adjusting third order term of FM nom
        Bn_M % factor for adjusting second order term of FM nom
        Cn_M % factor for adjusting first order term of FM nom
        Dn_M % factor for adjusting constant term of FM nom
        
        An_L % factor for adjusting third order term of FM nom
        Bn_L % factor for adjusting second order term of FM nom
        Cn_L % factor for adjusting first order term of FM nom
        Dn_L % factor for adjusting constant term of FM nom
        
        alphaS % factor for sensitivity ratio
        alphaM % factor for sensitivity ratio
        alphaL % factor for sensitivity ratio
        
        At_S % factor for adjusting second and first order term of FM thin
        Bt_S % factor for adjusting constant term of FM thin
        Ct_S % factor for assist matching
        Dt_S % factor for up and down
        
        At_M % factor for adjusting second and first order term of FM thin
        Bt_M % factor for adjusting constant term of FM thin
        Ct_M % factor for assist matching
        Dt_M % factor for up and down
        
        At_L % factor for adjusting second and first order term of FM thin
        Bt_L % factor for adjusting constant term of FM thin
        Ct_L % factor for assist matching
        Dt_L % factor for up and down
        
        % adjusted data
        FMnom_S % adjusted nominal data for plot
        FMnom_M % adjusted nominal data for plot
        FMnom_L % adjusted nominal data for plot
        FMnom_adj % combined data for plot
        FMthin_S
        FMthin_M
        FMthin_L
        FMthin_adj
        FMmax % FM max data
        
        % varibles to store calculated data
        FM_nom_save % matrix to store calculated FM nominal adec for calculated sections
        angle_save % matrix to save angle of three sensors for calculated sections
        ABCD_save % matrix to save ABCD of three sensors for calculated sections
        K_save % matrix to save Ks of three sensors of calculated sections
        L_save % matrix to save Ls of three sensors of calculated sections
        sig_tubing % parameters imported from saved data
        sig_casing % parameters imported from saved data
        mu_tubing % parameters imported from saved data
        mu_casing % parameters imported from saved data
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FindParametersButton
        function FindParametersButtonPushed(app, event)
            % S sensor finding parameters
            if app.testConfig.sensor(1) == 1
                msgbox("Finding parameters for S sensor");
                % first half of sensor
                log_S = app.lognom_S; % add 10 to avoid negative value or 0
                x_S = 1 : size(app.FM_nom_linS, 2);
                i = 1;
                Sum = 1000000;
                Parameters = 0;
                % find alpha and beta in -50~50 range for L sensor
                for An_S = -4.5:0.1:4.5
                    for Bn_S = -3:0.1:3
                        for Cn_S = -3:0.1:3
                            
                            P_S = app.Pn_S * (1 + An_S);
                            Q_S = app.Qn_S * (1 + Bn_S);
                            R_S = app.Rn_S * (1 + Cn_S);
                            S_S = app.Sn_S;
                            emf_adj_poly = [P_S, Q_S, R_S, S_S];
                            emf_adjusted = polyval(emf_adj_poly, x_S);
                            emf_adj = zeros(1, max(app.testConfig.chendtime));
                            emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(1)) + 1) :...
                                app.testConfig.chendtime(app.testConfig.adec_idx_end(1))) = emf_adjusted;
                            for j = app.testConfig.adec_idx_start(1):app.testConfig.adec_idx_end(1)
                                FM_CH_S(j) = mean(emf_adj(...
                                    (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                            end
                            emf = FM_CH_S(app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1)) -...
                                (FM_CH_S(app.testConfig.adec_idx_start(1)) - log_S(1));
                            
                            for m = 1:size(log_S, 2)
                                if m <= size(log_S, 2)/3
                                    Misfit(m) = abs(1 * (emf(m) - log_S(m)) / log_S(m));
                                elseif m > size(log_S, 2)/3 && m <=  size(log_S, 2) * (2/3)
                                    Misfit(m) = abs(1 * (emf(m) - log_S(m)) / log_S(m));
                                elseif m > size(log_S, 2) * (2/3)
                                    Misfit(m) = abs(1 * (emf(m) - log_S(m)) / log_S(m));
                                end
                            end
                            Sum_misfit = sum(Misfit);
                            % for inspection
                            if abs(Sum_misfit) < abs(Sum)
                                Sum =  Sum_misfit;
                                Parameters(i, 1:3) = [An_S, Bn_S, Cn_S];
                                Parameters(i, 4) = Sum;
                                FMmin_S = emf;
                                i = i + 1;
                            end
                        end
                    end
                end
                app.An_S = Parameters(end, 1);
                app.Bn_S = Parameters(end, 2);
                app.Cn_S = Parameters(end, 3);
                
                % displayed A B C D is the ones from second half sensor
                app.EditField_5.Value = app.An_S;
                app.EditField_8.Value = app.Bn_S;
                app.EditField_12.Value = app.Cn_S;
                app.EditField_33.Value = app.Dn_S;
            end
            
            % M sensor finding parameters
            if app.testConfig.sensor(2) == 1
                msgbox("Finding parameters for M sensor");
                log_M = app.lognom_M; % add 10 to avoid negative value or 0
                x_M = 1 : size(app.FM_nom_linM, 2);
                i = 1;
                Sum = 1000000;
                Parameters = 0;
                % find alpha and beta in -50~50 range for L sensor
                for An_M = -4:0.1:4
                    for Bn_M = -3:0.1:3
                        for Cn_M = -3:0.1:3
                            
                            P_M = app.Pn_M * (1 + An_M);
                            Q_M = app.Qn_M * (1 + Bn_M);
                            R_M = app.Rn_M * (1 + Cn_M);
                            S_M = app.Sn_M;
                            emf_adj_poly = [P_M, Q_M, R_M, S_M];
                            emf_adjusted = polyval(emf_adj_poly, x_M);
                            emf_adj = zeros(1, max(app.testConfig.chendtime));
                            emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(2)) + 1) :...
                                app.testConfig.chendtime(app.testConfig.adec_idx_end(2))) = emf_adjusted;
                            for j = app.testConfig.adec_idx_start(2):app.testConfig.adec_idx_end(2)
                                FM_CH_M(j) = mean(emf_adj(...
                                    (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                            end
                            emf = FM_CH_M(app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2)) -...
                                (FM_CH_M(app.testConfig.adec_idx_start(2)) - log_M(1));
                            
                            for m = 1:size(log_M, 2)
                                if m <= size(log_M, 2)/3
                                    Misfit(m) = abs(1 * (emf(m) - log_M(m)) / log_M(m));
                                elseif m > size(log_M, 2)/3 && m <=  size(log_M, 2) * (2/3)
                                    Misfit(m) = abs(1 * (emf(m) - log_M(m)) / log_M(m));
                                elseif m > size(log_M, 2) * (2/3)
                                    Misfit(m) = abs(1 * (emf(m) - log_M(m)) / log_M(m));
                                end
                            end
                            Sum_misfit = sum(Misfit);
                            % for inspection
                            if abs(Sum_misfit) < abs(Sum)
                                Sum =  Sum_misfit;
                                Parameters(i, 1:3) = [An_M, Bn_M, Cn_M];
                                Parameters(i, 4) = Sum;
                                FMmin_M = emf;
                                i = i + 1;
                            end
                        end
                    end
                end
                app.An_M = Parameters(end, 1);
                app.Bn_M = Parameters(end, 2);
                app.Cn_M = Parameters(end, 3);
                
                % displayed A B C D is the ones from second half sensor
                app.EditField_6.Value = app.An_M;
                app.EditField_7.Value = app.Bn_M;
                app.EditField_11.Value = app.Cn_M;
                app.EditField_32.Value = app.Dn_M;
            end
            
            % L sensor finding parameters
            if app.testConfig.sensor(3) == 1
                msgbox("Finding parameters for L sensor");
                log_L = app.lognom_L; % add 10 to avoid negative value or 0
                x_L = 1 : size(app.FM_nom_linL, 2);
                i = 1;
                Sum = 1000000;
                Parameters = 0;
                % find alpha and beta in -50~50 range for L sensor
                for An_L = -5:0.1:5
                    for Bn_L = -4.5:0.1:4.5
                        for Cn_L = -4:0.1:4
                            
                            P_L = app.Pn_L * (1 + An_L);
                            Q_L = app.Qn_L * (1 + Bn_L);
                            R_L = app.Rn_L * (1 + Cn_L);
                            S_L = app.Sn_L;
                            emf_adj_poly = [P_L, Q_L, R_L, S_L];
                            emf_adjusted = polyval(emf_adj_poly, x_L);
                            emf_adj = zeros(1, max(app.testConfig.chendtime));
                            emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(3)) + 1) :...
                                app.testConfig.chendtime(app.testConfig.adec_idx_end(3))) = emf_adjusted;
                            for j = app.testConfig.adec_idx_start(3):app.testConfig.adec_idx_end(3)
                                FM_CH_L(j) = mean(emf_adj(...
                                    (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                            end
                            emf = FM_CH_L(app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3)) -...
                                (FM_CH_L(app.testConfig.adec_idx_start(3)) - log_L(1));
                            
                            % avoid effect of noise
                            n_ch = max(app.testConfig.adec_idx_end);
                            if n_ch >= 96
                                x = 7;
                            elseif n_ch == 60
                                x = 5;
                            elseif n_ch < 60
                                x = 3;
                            end
                            
                            for m = 1:size(log_L, 2)
                                if m <= size(log_L, 2)/3
                                    Misfit(m) = abs(1 * (emf(m) - log_L(m)) / log_L(m));
                                elseif m > size(log_L, 2)/3 && m <=  size(log_L, 2) * (2/3)
                                    Misfit(m) = abs(1 * (emf(m) - log_L(m)) / log_L(m));
                                elseif m > size(log_L, 2) * (2/3) && m <= (size(log_L, 2) - x)
                                    Misfit(m) = abs(1 * (emf(m) - log_L(m)) / log_L(m));
                                elseif m > (size(log_L, 2) - x)
                                    Misfit(m) = abs(0.1 * (emf(m) - log_L(m)) / log_L(m));
                                end
                            end
                            Sum_misfit = sum(Misfit);
                            % for inspection
                            if abs(Sum_misfit) < abs(Sum)
                                Sum = Sum_misfit;
                                Parameters(i, 1:3) = [An_L, Bn_L, Cn_L];
                                Parameters(i, 4) = Sum;
                                FMmin_L = emf;
                                i = i + 1;
                            end
                        end
                    end
                end
                app.An_L = Parameters(end, 1);
                app.Bn_L = Parameters(end, 2);
                app.Cn_L = Parameters(end, 3);
                
                % displayed A B C D is the ones from second half sensor
                app.EditField_9.Value = app.An_L;
                app.EditField_10.Value = app.Bn_L;
                app.EditField_13.Value = app.Cn_L;
                app.EditField_34.Value = app.Dn_L;
            end
            msgbox("Parameters found!");
            %
            %             % disable find parameter button after finding
            %             set(app.FindParametersButton, 'Enable', 'off');
            
        end

        % Button pushed function: CalcandPlotButton
        function CalcandPlotButtonPushed(app, event)
            %%% forward modeling of nominal value
            % data initialization for FM
            app.sig_p = ones(1, app.n_p) * app.EditField_2.Value;
            app.sig_p(1) = app.EditField.Value;
            app.mur_p = ones(1, app.n_p) * app.EditField_3.Value;
            app.mur_p(1) = app.EditField_4.Value;
            
            % prepare data for log data
            %%% may need to cut off 0
            app.log_Med = log(app.log_med_sect);
            
            % run FM function
            % calculate three sensor FM data, assign to varibles and channeling
            n_ch = max(app.testConfig.adec_idx_end);
            FM_ch = zeros(1, n_ch);
            
            if app.FM_nom_save (app.SectionsDropDown.Value, 1) == -9999 || app.EditField ~= app.sig_tubing ||...
                    app.EditField_2 ~= app.sig_casing || app.EditField_4 ~= app.mu_tubing || app.EditField_3 ~= app.mu_casing
                coils = 'SML';
                for i = 1:3
                    if i == 1 && app.testConfig.sensor(i)
                        msgbox("Calculating S Sensor");
                        v_ms = CalcePDTBSegMuAvgEMF(app.n_p, app.od_p, app.tk_p, coils(i), app.sig_p, app.mur_p, ...
                            app.testConfig.txcurrent(i), app.testConfig.txduration(i), app.testConfig.chdelaytime, ...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                        app.FM_nom_linS = v_ms((app.testConfig.chstarttime(app.testConfig.adec_idx_start(i))+1) : ...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                        for j = app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)
                            FM_ch(j) = mean(v_ms(...
                                (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                        end
                    elseif i == 2 && app.testConfig.sensor(i)
                        msgbox("Calculating M Sensor");
                        v_ms = CalcePDTBSegMuAvgEMF(app.n_p, app.od_p, app.tk_p, coils(i), app.sig_p, app.mur_p, ...
                            app.testConfig.txcurrent(i), app.testConfig.txduration(i), app.testConfig.chdelaytime, ...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                        app.FM_nom_linM = v_ms((app.testConfig.chstarttime(app.testConfig.adec_idx_start(i))+1) : ...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                        for j = app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)
                            FM_ch(j) = mean(v_ms(...
                                (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                        end
                    elseif i == 3 && app.testConfig.sensor(i)
                        msgbox("Calculating L Sensor");
                        v_ms = CalcePDTBSegMuAvgEMF(app.n_p, app.od_p, app.tk_p, coils(i), app.sig_p, app.mur_p, ...
                            app.testConfig.txcurrent(i), app.testConfig.txduration(i), app.testConfig.chdelaytime, ...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                        app.FM_nom_linL = v_ms((app.testConfig.chstarttime(app.testConfig.adec_idx_start(i))+1) : ...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                        for j = app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)
                            FM_ch(j) = mean(v_ms(...
                                (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                        end
                    end
                end
                app.FM_nom_save (app.SectionsDropDown.Value, 1 : n_ch) = FM_ch;
            else 
                FM_ch = app.FM_nom_save (app.SectionsDropDown.Value, 1 : n_ch);
            end
            app.FM_nom_lin = FM_ch;            
            
            %%% polyfit the FM_nom in separate sensors
            % S sensor
            if app.testConfig.sensor(1) == 1
                x_S = 1 : size(app.FM_nom_linS, 2);
                app.lognom_S = app.log_Med (1:app.testConfig.adec_idx_end(1));
                FMnom_S = log(app.FM_nom_linS);
                app.Dn_S = app.lognom_S (1) - FMnom_S(1);
                % fit FM nom as third order polynomial
                emf_poly_S = polyfit(x_S, FMnom_S, 3);
                app.Pn_S = emf_poly_S(1);
                app.Qn_S = emf_poly_S(2);
                app.Rn_S = emf_poly_S(3);
                app.Sn_S = emf_poly_S(4);
            elseif app.testConfig.sensor(1) == 0
                app.lognom_S = double.empty;
            end
            
            % M sensor
            if app.testConfig.sensor(2) == 1
                x_M = 1 : size(app.FM_nom_linM, 2);
                app.lognom_M = app.log_Med (app.testConfig.adec_idx_start(2) : ...
                    app.testConfig.adec_idx_end(2));
                FMnom_M = log(app.FM_nom_linM);
                app.Dn_M = app.lognom_M (1) - FMnom_M(1);
                % fit FM nom as third order polynomial
                emf_poly_M = polyfit(x_M, FMnom_M, 3);
                app.Pn_M = emf_poly_M(1);
                app.Qn_M = emf_poly_M(2);
                app.Rn_M = emf_poly_M(3);
                app.Sn_M = emf_poly_M(4);
            elseif app.testConfig.sensor(2) == 0
                app.lognom_M = double.empty;
            end
            
            % L sensor
            if app.testConfig.sensor(3) == 1
                x_L = 1 : size(app.FM_nom_linL, 2);
                app.lognom_L = app.log_Med (app.testConfig.adec_idx_start(3) : ...
                    app.testConfig.adec_idx_end(3));
                FMnom_L = log(app.FM_nom_linL);
                app.Dn_L = app.lognom_L (1) - FMnom_L(1);
                % fit FM nom as third order polynomial
                emf_poly_L = polyfit(x_L, FMnom_L, 3);
                app.Pn_L = emf_poly_L(1);
                app.Qn_L = emf_poly_L(2);
                app.Rn_L = emf_poly_L(3);
                app.Sn_L = emf_poly_L(4);
            elseif app.testConfig.sensor(3) == 0
                app.lognom_L = double.empty;
            end
            
            % plot three lines
            cla(app.UIAxes);
            %semilogy(app.UIAxes, app.FM_x, app.log_Med_lin, 'g', 'LineWidth', 0.7);
            %hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, app.FM_nom_lin, 'Color', 'k', 'LineStyle', '-', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, app.log_Max, 'r', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, app.log_Min, 'b', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            % if sectional line box checked, show sectional lines
            if app.SectionalDataCheckBox.Value == 1
                % plot three lines
                semilogy(app.UIAxes, app.FM_x, app.log_med_sect, 'Color', 'k', 'LineStyle', '-.', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                semilogy(app.UIAxes, app.FM_x, app.log_Max_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                semilogy(app.UIAxes, app.FM_x, app.log_Min_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                leg1 = legend(app.UIAxes,{'Model','Max','Min','Sect-Nom','Sect-Max','Sect-Min'},'Location','northeast','Orientation','horizontal');
            elseif app.SectionalDataCheckBox.Value == 0
                leg1 = legend(app.UIAxes,{'Model','Max','Min'},'Location','northeast','Orientation','horizontal');
            end
            msgbox("Finish calculating and plotting nominal values");
            
        end

        % Button pushed function: ApplyParametersButton
        function ApplyParametersButtonPushed(app, event)
            % S sensor finding parameters
            if app.testConfig.sensor(1) == 1
                x_S = 1 : size(app.FM_nom_linS, 2);
                % calculate decay curve
                P_S = app.Pn_S * (1 + app.EditField_5.Value);
                Q_S = app.Qn_S * (1 + app.EditField_8.Value);
                R_S = app.Rn_S * (1 + app.EditField_12.Value);
                S_S = app.Sn_S;
                emf_adj_poly = [P_S, Q_S, R_S, S_S];
                emf_adjusted = polyval(emf_adj_poly, x_S);
                emf_adj = zeros(1, max(app.testConfig.chendtime));
                emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(1)) + 1) :...
                    app.testConfig.chendtime(app.testConfig.adec_idx_end(1))) = emf_adjusted;
                for j = app.testConfig.adec_idx_start(1):app.testConfig.adec_idx_end(1)
                    FMnom_S(j) = mean(emf_adj(...
                        (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                end
                
                % make sure emf_adjusted > 0
                for i = 1:size(FMnom_S, 2)
                    if FMnom_S(i) <= 0
                        FMnom_S(i) = 0.01;
                    end
                end
                FMnom_S = FMnom_S(app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1));
                %                 FMnom_S = FMnom_S - (FMnom_S(1) - app.lognom_S(1));
                FMnom_S = FMnom_S + app.EditField_33.Value;
                app.FMnom_S = exp(FMnom_S);
            elseif app.testConfig.sensor(1) == 0
                app.FMnom_S = double.empty;
            end
            
            % M sensor finding parameters
            if app.testConfig.sensor(2) == 1
                x_M = 1 : size(app.FM_nom_linM, 2);
                % calculate decay curve
                P_M = app.Pn_M * (1 + app.EditField_6.Value);
                Q_M = app.Qn_M * (1 + app.EditField_7.Value);
                R_M = app.Rn_M * (1 + app.EditField_11.Value);
                S_M = app.Sn_M;
                emf_adj_poly = [P_M, Q_M, R_M, S_M];
                emf_adjusted = polyval(emf_adj_poly, x_M);
                emf_adj = zeros(1, max(app.testConfig.chendtime));
                emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(2)) + 1) :...
                    app.testConfig.chendtime(app.testConfig.adec_idx_end(2))) = emf_adjusted;
                for j = app.testConfig.adec_idx_start(2):app.testConfig.adec_idx_end(2)
                    FMnom_M(j) = mean(emf_adj(...
                        (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                end
                
                % make sure emf_adjusted > 0
                for i = 1:size(FMnom_M, 2)
                    if FMnom_M(i) <= 0
                        FMnom_M(i) = 0.01;
                    end
                end
                FMnom_M = FMnom_M(app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2));
                %                 FMnom_M = FMnom_M - (FMnom_M(1) - app.lognom_M(1));
                FMnom_M = FMnom_M + app.EditField_32.Value;
                app.FMnom_M = exp(FMnom_M);
            elseif app.testConfig.sensor(2) == 0
                app.FMnom_M = double.empty;
            end
            
            % L sensor finding parameters
            if app.testConfig.sensor(3) == 1
                x_L = 1 : size(app.FM_nom_linL, 2);
                % calculate decay curve
                P_L = app.Pn_L * (1 + app.EditField_9.Value);
                Q_L = app.Qn_L * (1 + app.EditField_10.Value);
                R_L = app.Rn_L * (1 + app.EditField_13.Value);
                S_L = app.Sn_L;
                emf_adj_poly = [P_L, Q_L, R_L, S_L];
                emf_adjusted = polyval(emf_adj_poly, x_L);
                emf_adj = zeros(1, max(app.testConfig.chendtime));
                emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(3)) + 1) :...
                    app.testConfig.chendtime(app.testConfig.adec_idx_end(3))) = emf_adjusted;
                for j = app.testConfig.adec_idx_start(3):app.testConfig.adec_idx_end(3)
                    FMnom_L(j) = mean(emf_adj(...
                        (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                end
                
                % make sure emf_adjusted > 0
                for i = 1:size(FMnom_L, 2)
                    if FMnom_L(i) <= 0
                        FMnom_L(i) = 0.01;
                    end
                end
                FMnom_L = FMnom_L(app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3));
                %                 FMnom_L = FMnom_L - (FMnom_L(1) - app.lognom_L(1));
                FMnom_L = FMnom_L + app.EditField_34.Value;
                app.FMnom_L = exp(FMnom_L);
            elseif app.testConfig.sensor(3) == 0
                app.FMnom_L = double.empty;
            end
            
            % combine three sensors into one
            app.FMnom_adj = [app.FMnom_S, app.FMnom_M, app.FMnom_L];
            
            %%% plot FM_nom vs FM_x
            cla(app.UIAxes);
            %semilogy(app.UIAxes, app.FM_x, app.log_Med_lin, 'g', 'LineWidth', 0.7);
            %hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, app.FMnom_adj, 'Color', 'k', 'LineStyle', '-', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, app.log_Max, 'r', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, app.log_Min, 'b', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            % if sectional line box checked, show sectional lines
            if app.SectionalDataCheckBox.Value == 1
                % plot three lines
                semilogy(app.UIAxes, app.FM_x, app.log_med_sect, 'Color', 'k', 'LineStyle', '-.', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                semilogy(app.UIAxes, app.FM_x, app.log_Max_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                semilogy(app.UIAxes, app.FM_x, app.log_Min_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                leg1 = legend(app.UIAxes,{'Model','Max','Min','Sect-Nom','Sect-Max','Sect-Min'},'Location','northeast','Orientation','horizontal');
            elseif app.SectionalDataCheckBox.Value == 0
                leg1 = legend(app.UIAxes,{'Model','Max','Min'},'Location','northeast','Orientation','horizontal');
            end
            
            % disabled if no app.FMnom_adj
            if isempty(app.FMnom_adj)
                set(app.CombineButton_2, 'Enable', 'off');
            else
                set(app.CombineButton_2, 'Enable', 'on');
            end
            
            
        end

        % Callback function
        function CloseButtonPushed(app, event)
            
        end

        % Button pushed function: LoadToolDataButton
        function LoadToolDataButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.las'}, 'Load Log Data');
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg('No file selected', 'Error');
            end
            
            app.logDataPath = strcat(pathname, filename);
            app.EditField_19.Value = app.logDataPath;
            app.lasSrcFilename = filename;
            
            % read las
            [ app.lasData, app.lasHeader, app.lasDetails ] = ImportLAS(filename);
            
            % get all decays and eliminate all ADEC and depth with ADEC[1] <= 0
            i_ADEC = ~cellfun('isempty', regexp(app.lasHeader, '^ADEC\[\d+\]$', 'match'));
            ADEC = app.lasData(:, i_ADEC);
            ADEC = ADEC(:, 1);
            nozeroNum = find(ADEC > 0, 1);
            app.ADEC = app.lasData(nozeroNum : end, i_ADEC);
            % get all decays cont.
            app.lasDepth = app.lasData(nozeroNum : end, strcmp(app.lasHeader, 'Depth') | strcmp(app.lasHeader, 'DEPT'));
            app.lasDepth = app.lasDepth(:, 1);
            app.testMode = app.lasData(nozeroNum : end, strcmp(app.lasHeader, 'MODE'));
            
            function [ data, header, detail ] = ImportLAS( filename )
                %ImportLAS Import .las file
                % Input:
                %   filename    target file name
                %
                % Output:
                %   data        numeric data
                %   header      header of data
                %   detail      structure of more detailed info
                
                %% File Operations
                % Open the text file.
                fileID = fopen(filename, 'r');
                
                % read in las as space delimited string
                formatSpec = '%s%[^\n\r]';
                
                % Read first 1000 lines to find header
                textArray = textscan(fileID, formatSpec, 1000, 'Delimiter', ' ', ...
                    'MultipleDelimsAsOne', true,  'ReturnOnError', false);
                
                % find '~A' as the header line start, '#' for additional header lines
                i_labeledheader = find(strcmp(textArray{1}, '~A') | ...
                    strcmp(textArray{1}, '#'));
                
                % read header lines
                i_header = GetTureHeader(textArray{2}(i_labeledheader), i_labeledheader);
                [header, n_col] = Block2Vector(textArray{2}(i_header), 'string');
                
                % Read in data block
                frewind(fileID);
                formatSpec = '%[^\n\r]';
                dataArray = textscan(fileID, formatSpec, 'Delimiter', ' ', ...
                    'MultipleDelimsAsOne', true, 'HeaderLines', i_labeledheader(end), ...
                    'ReturnOnError', false);
                
                % Close the text file.
                fclose(fileID);
                
                %% Data Output
                % parse data block
                row_block = length(i_header);
                col_total = length(header);
                raw = dataArray{1};
                n_depth = length(raw) / row_block;
                
                % read data
                data = zeros(n_depth, col_total);
                for i = 1:n_depth
                    % read data lines by block
                    [ data_depth, ~ ] = Block2Vector(raw((i - 1) * row_block + ...
                        (1:row_block)), 'float');
                    data(i, :) = data_depth;
                end
                
                % build detail structure for output
                detail = struct;
                detail.i_datastart = i_labeledheader(end) + 1;
                detail.n_col = n_col;
                
            end
            
            function [ i_trueheader ] = GetTureHeader( data, i_header )
                %GetTureHeader Remove empty lines of labeled header
                % Input:
                %   data            input data, n x 1 cell
                %   i_header        labeled header lines
                %
                % Output:
                %   i_trueheader    filled header lines
                
                i_trueheader = zeros(size(i_header));
                len = size(data, 1);
                
                index = 1;
                for i = 1:len
                    line = strtrim(data{i});
                    if isempty(line)
                        continue;
                    end
                    i_trueheader(index) = i_header(i);
                    index = index + 1;
                end
                
                i_trueheader = i_trueheader(1:(index - 1));
                
            end
            
            function [ output, n_col ] = Block2Vector( data, option )
                %Block2Vector Convert block data to 1 dimensional vector
                % Input:
                %   data        input data, n x 1 cell
                %   option      'string' or 'float'
                %
                % Output:
                %   output      output data, 1 x m vector
                %   n_col       column size, n x 1 vector
                
                if strcmp(option, 'string')
                    formatSpec = '%s';
                    output = cell(1, 200);
                    type = 1;
                else
                    formatSpec = '%f';
                    output = zeros(1, 200);
                    type = 2;
                end
                
                len = size(data, 1);
                n_col = zeros(len, 1);
                index = 0;
                for i = 1:len
                    if type == 1
                        line = strsplit(strtrim(data{i}));
                        n = size(line, 2);
                    else
                        [line, n] = sscanf(data{i}, formatSpec);
                    end
                    
                    output(index + (1:n)) = line;
                    index = index + n;
                    n_col(i) = n;
                end
                
                if index > 0
                    output = output(1:index);
                else
                    output = [];
                    n_col = [];
                end
                
            end
        end

        % Button pushed function: LoadINIFileButton
        function LoadINIFileButtonPushed(app, event)
            % read configuration file
            [app.configFileName, pathname] = uigetfile({'*.ini'}, 'Load Configuration');
            if isequal(app.configFileName, 0) || isequal(pathname, 0)
                errordlg("No file selected", 'Error');
            end
            
            app.configPath = strcat(pathname, app.configFileName);
            app.EditField_20.Value = app.configPath;
            
        end

        % Button pushed function: DisplayWindowButton
        function DisplayWindowButtonPushed(app, event)
            % depth selection
            depth_min = app.EditField_18.Value - (app.EditField_17.Value/2);
            depth_max = app.EditField_18.Value + (app.EditField_17.Value/2);
            if depth_min <= app.depth_min | depth_max >= app.depth_max
                errordlg("Depth out of range", 'Error');
                return
            end
            
            
            i_Depth = zeros(size(app.lasDepth, 1), 1); % logic array for selected section (all depth as required)
            for d = 1:size(app.lasDepth, 1)
                if app.lasDepth(d) >= depth_min && app.lasDepth(d) <= depth_max
                    i_Depth(d) = 1;
                end
            end
            i_Depth = logical(i_Depth);
            
            % store selected ADEC in a varible for selected depth
            app.selected_ADEC = app.ADEC(i_Depth, :);
            
            %%% get config information
            selected_Mode = app.testMode(i_Depth, :);
            app.ModeUsed = selected_Mode(1, 1);
            
            % Read ePDT ini file for certain mode
            % Input:
            %   app.configFileName    target file name
            %   testMode              selected config mode from .las file
            %
            % Output:
            %   status      success or not
            %   app.testConfig  config contents
            
            status = false;
            cfg_str = sprintf('[Config_%d]', app.ModeUsed);
            
            app.testConfig.configname = '';
            app.testConfig.cmdcode = '';
            app.testConfig.sensor = false(1, 3);      % enable status for S, M and L
            app.testConfig.txcurrent = zeros(1, 3);
            app.testConfig.txduration = zeros(1, 3);
            app.testConfig.chnum = zeros(1, 3);
            app.testConfig.chgain = cell(1, 3);       % unknown total channel number
            app.testConfig.chstarttime = [];
            app.testConfig.chendtime = [];
            app.testConfig.acq_idx_start = zeros(1, 3);
            app.testConfig.acq_idx_end = zeros(1, 3);
            app.testConfig.firingsequences = '';
            app.testConfig.fullsampletime = zeros(1, 3);
            app.testConfig.loggingspeed = 0;
            app.testConfig.adec_idx_start = zeros(1, 3);
            app.testConfig.adec_idx_end = zeros(1, 3);
            app.testConfig.chdelaytime = 20;          % defaulted to 20
            app.testConfig.turns = zeros(1, 3);
            app.testConfig.n_ch = 0;
            
            fid = fopen(app.configFileName); %% app.configFileName is the name of selected ini file
            while ~feof(fid)
                line = fgetl(fid);
                if strcmp(line, cfg_str)    % locate the correct config
                    while ~feof(fid) && ~isempty(line)
                        line = fgetl(fid);  % read line by line
                        paras = strsplit(line, '=');
                        switch paras{1}
                            case 'ConfigName'
                                app.testConfig.configname = paras{2};
                            case 'CmdCode'
                                app.testConfig.cmdcode = paras{2};
                            case 'SensorS'
                                app.testConfig.sensor(1) = strcmpi(paras{2}, 'true');
                            case 'SensorM'
                                app.testConfig.sensor(2) = strcmpi(paras{2}, 'true');
                            case 'SensorL'
                                app.testConfig.sensor(3) = strcmpi(paras{2}, 'true');
                            case 'TxCurrentS'
                                app.testConfig.txcurrent(1) = str2double(paras{2});
                            case 'TxDurationS'
                                app.testConfig.txduration(1) = str2double(paras{2});
                            case 'TxCurrentM'
                                app.testConfig.txcurrent(2) = str2double(paras{2});
                            case 'TxDurationM'
                                app.testConfig.txduration(2) = str2double(paras{2});
                            case 'TxCurrentL'
                                app.testConfig.txcurrent(3) = str2double(paras{2});
                            case 'TxDurationL'
                                app.testConfig.txduration(3) = str2double(paras{2});
                            case 'ChNumS'
                                app.testConfig.chnum(1) = str2double(paras{2});
                            case 'ChGainS'
                                app.testConfig.chgain{1} = str2double(strsplit(paras{2}, ','));
                            case 'ChNumM'
                                app.testConfig.chnum(2) = str2double(paras{2});
                            case 'ChGainM'
                                app.testConfig.chgain{2} = str2double(strsplit(paras{2}, ','));
                            case 'ChNumL'
                                app.testConfig.chnum(3) = str2double(paras{2});
                            case 'ChGainL'
                                app.testConfig.chgain{3} = str2double(strsplit(paras{2}, ','));
                            case 'ChStartTime'
                                app.testConfig.chstarttime = str2double(strsplit(paras{2}, ','));
                            case 'ChEndTime'
                                app.testConfig.chendtime = str2double(strsplit(paras{2}, ','));
                            case 'StartIdxSensorS'
                                app.testConfig.acq_idx_start(1) = str2double(paras{2});
                            case 'EndIdxSensorS'
                                app.testConfig.acq_idx_end(1) = str2double(paras{2});
                            case 'StartIdxSensorM'
                                app.testConfig.acq_idx_start(2) = str2double(paras{2});
                            case 'EndIdxSensorM'
                                app.testConfig.acq_idx_end(2) = str2double(paras{2});
                            case 'StartIdxSensorL'
                                app.testConfig.acq_idx_start(3) = str2double(paras{2});
                            case 'EndIdxSensorL'
                                app.testConfig.acq_idx_end(3) = str2double(paras{2});
                            case 'FiringSequences'
                                app.testConfig.firingsequences = paras{2};
                            case 'FullSampleTimeS'
                                app.testConfig.fullsampletime(1) = str2double(paras{2});
                            case 'FullSampleTimeM'
                                app.testConfig.fullsampletime(2) = str2double(paras{2});
                            case 'FullSampleTimeL'
                                app.testConfig.fullsampletime(3) = str2double(paras{2});
                            case 'LoggingSpeed'
                                app.testConfig.loggingspeed = str2double(paras{2});
                            case 'ADEC_S_START'
                                app.testConfig.adec_idx_start(1) = str2double(paras{2});
                            case 'ADEC_S_END'
                                if app.testConfig.adec_idx_start(1) == 0
                                    app.testConfig.adec_idx_end(1) = -1;
                                else
                                    app.testConfig.adec_idx_end(1) = str2double(paras{2});
                                end
                            case 'ADEC_M_START'
                                app.testConfig.adec_idx_start(2) = str2double(paras{2});
                            case 'ADEC_M_END'
                                if app.testConfig.adec_idx_start(2) == 0
                                    app.testConfig.adec_idx_end(2) = -1;
                                else
                                    app.testConfig.adec_idx_end(2) = str2double(paras{2});
                                end
                            case 'ADEC_L_START'
                                app.testConfig.adec_idx_start(3) = str2double(paras{2});
                            case 'ADEC_L_END'
                                if app.testConfig.adec_idx_start(3) == 0
                                    app.testConfig.adec_idx_end(3) = -1;
                                else
                                    app.testConfig.adec_idx_end(3) = str2double(paras{2});
                                end
                            case 'ChDelayTime'
                                app.testConfig.chdelaytime = str2double(paras{2});
                            case 'TurnsS'
                                app.testConfig.turns(1) = str2double(paras{2});
                            case 'TurnsM'
                                app.testConfig.turns(2) = str2double(paras{2});
                            case 'TurnsL'
                                app.testConfig.turns(3) = str2double(paras{2});
                        end
                    end
                    
                    n_ch = sum(app.testConfig.adec_idx_end - app.testConfig.adec_idx_start + 1);
                    if length(app.testConfig.chstarttime) ~= length(app.testConfig.chendtime) || ...
                            n_ch > length(app.testConfig.chstarttime)
                        break;
                    end
                    
                    app.testConfig.n_ch = n_ch;
                    status = true;
                    break;
                end
            end
            fclose(fid);
            
            %%% get, degain and plot max, min, med and plot
            % assign 85% - 55% to nom
            medianArr = ones(1, size(app.selected_ADEC, 2));
            linearArr = ones(1, size(app.selected_ADEC, 2));
            linearIdx = ones(1, size(app.selected_ADEC, 2));
            
            ADEC_section = sort(app.selected_ADEC, 'ascend');
            linearIdx(1) = 0.01 * 85;
            linearIdx(end) = 0.01 * 60;
            linearIdx_step = (linearIdx(1) - linearIdx(end)) / (size(linearIdx, 2) - 1);
            for i = 1:size(app.selected_ADEC, 2)
                linearIdx(i) = linearIdx(1) - (i - 1) * linearIdx_step;
            end
            for CHIDX = 1:size(app.selected_ADEC, 2)
                abc = floor(linearIdx(CHIDX) * size(app.selected_ADEC, 1));
                if abc == 0
                    abc = 1;
                else
                    linearArr(1, CHIDX) = ADEC_section(abc, CHIDX);
                end
            end
            app.log_Med_lin = linearArr ./ app.degain;
            
            % get, degain and plot max, min, med
            app.FM_x = 1:size(app.log_Med_lin, 2);
            window_Gain = app.testConfig.chendtime - app.testConfig.chstarttime;
            coilTurn_Gain = ones(size(window_Gain));
            for CH_coil = 1:size(app.testConfig.chstarttime, 2)
                if CH_coil >= app.testConfig.adec_idx_start(1) && CH_coil <= app.testConfig.adec_idx_end(1) &&...
                        app.testConfig.adec_idx_start(1) < app.testConfig.adec_idx_end(1)
                    coilTurn_Gain(CH_coil) = 1 / app.testConfig.turns(1);
                elseif CH_coil >= app.testConfig.adec_idx_start(2) && CH_coil <= app.testConfig.adec_idx_end(2) &&...
                        app.testConfig.adec_idx_start(2) < app.testConfig.adec_idx_end(2)
                    coilTurn_Gain(CH_coil) = 1 / app.testConfig.turns(2);
                elseif CH_coil >= app.testConfig.adec_idx_start(3) && CH_coil <= app.testConfig.adec_idx_end(3) &&...
                        app.testConfig.adec_idx_start(3) < app.testConfig.adec_idx_end(3)
                    coilTurn_Gain(CH_coil) = 1 / app.testConfig.turns(3);
                end
            end
            analog_Gain = 0.175 * ones(size(window_Gain)); % as long as analog_Gain is 0.175
            app.log_Max = max(app.selected_ADEC) ./ (window_Gain .* coilTurn_Gain .* analog_Gain);
            app.log_Min = min(app.selected_ADEC) ./ (window_Gain .* coilTurn_Gain .* analog_Gain);
            
            % store max and min log values before magnified with indexs
            app.log_Max_perm = app.log_Max;
            app.log_Min_perm = app.log_Min;
            
            % plot three lines
            cla(app.UIAxes);
            %semilogy(app.UIAxes, app.FM_x, app.log_Med_lin, 'g', 'LineWidth', 0.7);
            %hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, app.log_Max, 'r', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, app.log_Min, 'b', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            % if sectional line box checked, show sectional lines
            if app.SectionalDataCheckBox.Value == 1
                % plot three lines
                semilogy(app.UIAxes, app.FM_x, app.log_med_sect, 'Color', 'k', 'LineStyle', '-.', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                semilogy(app.UIAxes, app.FM_x, app.log_Max_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                semilogy(app.UIAxes, app.FM_x, app.log_Min_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                leg1 = legend(app.UIAxes,{'Max','Min','Sect-Nom','Sect-Max','Sect-Min'},'Location','northeast','Orientation','horizontal');
            elseif app.SectionalDataCheckBox.Value == 0
                leg1 = legend(app.UIAxes,{'Max','Min'},'Location','northeast','Orientation','horizontal');
            end
            
            %%% get well schematic of target depth for FM
            % read OD, TK for FM according to selected depth
            app.n_p = 0;
            app.od_p = 0;
            app.tk_p = 0;
            app.pipe = 0;
            g = 0;
            for i = 1:length(app.pipeList)
                if app.pipeList(i).TopDepth <= app.EditField_18.Value && app.pipeList(i).BottomDepth >= app.EditField_18.Value
                    g = g + 1;
                    app.n_p = g;%app.pipeList(i).ItemID;
                    app.od_p(g) = app.pipeList(i).NomOD * 25.4;
                    app.tk_p(g) = app.pipeList(i).NomThickness * 25.4;
                    app.pipe(g) = i;
                end
            end
            
            % only activate pipe ML input for activated pipes
            if ~any(app.pipe(:) == 1)
                set(app.EditField_15, 'Enable', 'off');
            else
                set(app.EditField_15, 'Enable', 'on');
            end
            if ~any(app.pipe(:) == 2)
                set(app.EditField_14, 'Enable', 'off');
            else
                set(app.EditField_14, 'Enable', 'on');
            end
            if ~any(app.pipe(:) == 3)
                set(app.EditField_16, 'Enable', 'off');
            else
                set(app.EditField_16, 'Enable', 'on');
            end
            if ~any(app.pipe(:) == 4)
                set(app.EditField_24, 'Enable', 'off');
            else
                set(app.EditField_24, 'Enable', 'on');
            end
            if ~any(app.pipe(:) == 5)
                set(app.EditField_25, 'Enable', 'off');
            else
                set(app.EditField_25, 'Enable', 'on');
            end
            if ~any(app.pipe(:) == 6)
                set(app.EditField_35, 'Enable', 'off');
            else
                set(app.EditField_35, 'Enable', 'on');
            end
            
        end

        % Button pushed function: LoadWellSchematicButton
        function LoadWellSchematicButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.xml'}, 'Load Well Schematics');
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg("No file selected", 'Error');
            end
            
            app.wellSchemPath = strcat(pathname, filename);
            app.EditField_23.Value = app.wellSchemPath;
            
            % Import well schematic in xml file
            % Input:
            %   filename    target file name
            %
            % Output:
            %   pipeList    pipe list
            
            % labels to read
            labels = {'ItemID', 'TopDepth', 'BottomDepth', 'NomOD', 'NomThickness'};
            
            % as = fullfile(pathname, filename);
            % read xml file
            xDoc = xmlread(filename);
            
            % find all pipe section
            allListitems = xDoc.getElementsByTagName('EMDSPipeSection');
            
            % get listnode information
            for i = 1:allListitems.getLength
                thisListitem = allListitems.item(i - 1);
                
                % read the labels
                for j = 1:length(labels)
                    thisList = thisListitem.getElementsByTagName(labels{j});
                    thisElement = thisList.item(0);
                    app.pipeList(i).(labels{j}) = ...
                        str2double(thisElement.getFirstChild.getData);
                end
            end
            
            % generate a string for the dropdown button
            depths = unique([app.pipeList.TopDepth, app.pipeList.BottomDepth]);
            app.sectionList = strings(1, (length(depths) - 1));
            for j = 1:(length(depths) - 1)
                n = 0;
                % use median of section depth to count pipe number
                depthmid = mean([depths(j), depths(j + 1)]);
                for k = 1 : app.pipeList(end).ItemID
                    if depthmid >= app.pipeList(k).TopDepth && depthmid <= app.pipeList(k).BottomDepth
                        n = n + 1;
                    end
                end
                pipeNum = num2str(n);
                app.sectionList(j) = strcat(pipeNum, " Pipes Section");
            end
            
            app.SectionsDropDown.Items = app.sectionList;
            app.SectionsDropDown.ItemsData = 1 : length(app.SectionsDropDown.Items);
            % define space for saving data for exporting
            app.FM_nom_save = ones(length(app.SectionsDropDown.Items), 200) * -9999;
            app.angle_save = ones(length(app.SectionsDropDown.Items), 6) * -9999;
            app.ABCD_save = ones (length(app.SectionsDropDown.Items), 12) * -9999;
            app.K_save = ones(length(app.SectionsDropDown.Items), 6) * -9999;
            app.L_save = ones(length(app.SectionsDropDown.Items), 6) * -9999;
            
        end

        % Button pushed function: CalcandPlotButton_2
        function CalcandPlotButton_2Pushed(app, event)
            %%% forward modeling of nominal value
            % data initialization for FM
            app.sig_p = ones(1, app.n_p) * app.EditField_2.Value;
            app.sig_p(1) = app.EditField.Value;
            app.mur_p = ones(1, app.n_p) * app.EditField_3.Value;
            app.mur_p(1) = app.EditField_4.Value;
            
            % prepare data for log data
            % degain
            window_Gain = app.testConfig.chendtime - app.testConfig.chstarttime;
            coilTurn_Gain = ones(size(window_Gain));
            for CH_coil = 1:size(app.testConfig.chstarttime, 2)
                if CH_coil >= app.testConfig.adec_idx_start(1) && CH_coil <= app.testConfig.adec_idx_end(1) &&...
                        app.testConfig.adec_idx_start(1) < app.testConfig.adec_idx_end(1)
                    coilTurn_Gain(CH_coil) = 1 / app.testConfig.turns(1);
                elseif CH_coil >= app.testConfig.adec_idx_start(2) && CH_coil <= app.testConfig.adec_idx_end(2) &&...
                        app.testConfig.adec_idx_start(2) < app.testConfig.adec_idx_end(2)
                    coilTurn_Gain(CH_coil) = 1 / app.testConfig.turns(2);
                elseif CH_coil >= app.testConfig.adec_idx_start(3) && CH_coil <= app.testConfig.adec_idx_end(3) &&...
                        app.testConfig.adec_idx_start(3) < app.testConfig.adec_idx_end(3)
                    coilTurn_Gain(CH_coil) = 1 / app.testConfig.turns(3);
                end
            end
            analog_Gain = 0.175 * ones(size(window_Gain)); % as long as analog_Gain is 0.175
            app.log_Thin_lin = app.log_Min;
            app.log_Thin = log(app.log_Thin_lin);
            
            % run FM to calculate thin or thick situation and max situation (45% gain for all pipes)
            % assign app.tk_p with input thickness change
            jj = 1;
            if any(app.pipe(:) == 1)
                tk_perc(jj) = 0.01 * app.EditField_15.Value;
                jj = jj + 1;
            end
            if any(app.pipe(:) == 2)
                tk_perc(jj) = 0.01 * app.EditField_14.Value;
                jj = jj + 1;
            end
            if any(app.pipe(:) == 3)
                tk_perc(jj) = 0.01 * app.EditField_16.Value;
                jj = jj + 1;
            end
            if any(app.pipe(:) == 4)
                tk_perc(jj) = 0.01 * app.EditField_24.Value;
                jj = jj + 1;
            end
            if any(app.pipe(:) == 5)
                tk_perc(jj) = 0.01 * app.EditField_25.Value;
                jj = jj + 1;
            end
            if any(app.pipe(:) == 6)
                tk_perc(jj) = 0.01 * app.EditField_35.Value;
                jj = jj + 1;
            end
            
            app.tk_pThin = (1 - tk_perc) .* app.tk_p;
            % max situation
            tk_pMax = 1.55 * app.tk_p;
            
            % calculate three sensor FM data, assign to varibles and channeling
            n_ch = max(app.testConfig.adec_idx_end);
            FM_ch = zeros(1, n_ch);
            coils = 'SML';
            for i = 1:3
                if i == 1 && app.testConfig.sensor(i)
                    msgbox("Calculating S Sensor");
                    % calculate thin
                    v_ms = CalcePDTBSegMuAvgEMF(app.n_p, app.od_p, app.tk_pThin, coils(i), app.sig_p, app.mur_p, ...
                        app.testConfig.txcurrent(i), app.testConfig.txduration(i), app.testConfig.chdelaytime, ...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                    app.FM_thin_linS = v_ms((app.testConfig.chstarttime(app.testConfig.adec_idx_start(i))+1) : ...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                    for j = app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)
                        FM_ch(j) = mean(v_ms(...
                            (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                    end
                    % calculate max
                    v_ms = CalcePDTBSegMuAvgEMF(app.n_p, app.od_p, tk_pMax, coils(i), app.sig_p, app.mur_p, ...
                        app.testConfig.txcurrent(i), app.testConfig.txduration(i), app.testConfig.chdelaytime, ...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                    FM_MaxS = v_ms((app.testConfig.chstarttime(app.testConfig.adec_idx_start(i))+1) : ...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                    for j = app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)
                        FM_Max(j) = mean(v_ms(...
                            (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                    end
                elseif i == 2 && app.testConfig.sensor(i)
                    msgbox("Calculating M Sensor");
                    % calculate thin
                    v_ms = CalcePDTBSegMuAvgEMF(app.n_p, app.od_p, app.tk_pThin, coils(i), app.sig_p, app.mur_p, ...
                        app.testConfig.txcurrent(i), app.testConfig.txduration(i), app.testConfig.chdelaytime, ...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                    app.FM_thin_linM = v_ms((app.testConfig.chstarttime(app.testConfig.adec_idx_start(i))+1) : ...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                    for j = app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)
                        FM_ch(j) = mean(v_ms(...
                            (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                    end
                    % calculate max
                    v_ms = CalcePDTBSegMuAvgEMF(app.n_p, app.od_p, tk_pMax, coils(i), app.sig_p, app.mur_p, ...
                        app.testConfig.txcurrent(i), app.testConfig.txduration(i), app.testConfig.chdelaytime, ...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                    FM_MaxM = v_ms((app.testConfig.chstarttime(app.testConfig.adec_idx_start(i))+1) : ...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                    for j = app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)
                        FM_Max(j) = mean(v_ms(...
                            (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                    end
                elseif i == 3 && app.testConfig.sensor(i)
                    msgbox("Calculating L Sensor");
                    % calculate thin
                    v_ms = CalcePDTBSegMuAvgEMF(app.n_p, app.od_p, app.tk_pThin, coils(i), app.sig_p, app.mur_p, ...
                        app.testConfig.txcurrent(i), app.testConfig.txduration(i), app.testConfig.chdelaytime, ...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                    app.FM_thin_linL = v_ms((app.testConfig.chstarttime(app.testConfig.adec_idx_start(i))+1) : ...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                    for j = app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)
                        FM_ch(j) = mean(v_ms(...
                            (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                    end
                    % calculate max
                    v_ms = CalcePDTBSegMuAvgEMF(app.n_p, app.od_p, tk_pMax, coils(i), app.sig_p, app.mur_p, ...
                        app.testConfig.txcurrent(i), app.testConfig.txduration(i), app.testConfig.chdelaytime, ...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                    FM_MaxL = v_ms((app.testConfig.chstarttime(app.testConfig.adec_idx_start(i))+1) : ...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                    for j = app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)
                        FM_Max(j) = mean(v_ms(...
                            (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                    end
                end
            end
            app.FM_thin_lin = FM_ch;
            app.FM_Max_lin = FM_Max;
            
            %%% polyfit the FM_nom in separate sensors
            % S sensor
            if app.testConfig.sensor(1) == 1
                x_S = 1 : size(app.FM_thin_linS, 2);
                % polyfit thin FM
                app.logthin_S = app.log_Thin (1:app.testConfig.adec_idx_end(1));
                FMthin_S = log(app.FM_thin_linS);
                app.Dt_S = app.logthin_S (1) - FMthin_S (1);
                % fit FM thin as third order polynomial
                emf_poly_S = polyfit(x_S, FMthin_S, 3);
                app.Pt_S = emf_poly_S(1);
                app.Qt_S = emf_poly_S(2);
                app.Rt_S = emf_poly_S(3);
                app.St_S = emf_poly_S(4);
                % polyfit max FM
                app.logmax_S = log(app.log_Max (1:app.testConfig.adec_idx_end(1)));
                FMmax_S = log(FM_MaxS);
                % fit FM thin as third order polynomial
                emf_poly_S = polyfit(x_S, FMmax_S, 3);
                app.Pm_S = emf_poly_S(1);
                app.Qm_S = emf_poly_S(2);
                app.Rm_S = emf_poly_S(3);
                app.Sm_S = emf_poly_S(4);
            elseif app.testConfig.sensor(1) == 0
                app.logthin_S = double.empty;
                app.logmax_S = double.empty;
            end
            
            % M sensor
            if app.testConfig.sensor(2) == 1
                x_M = 1 : size(app.FM_thin_linM, 2);
                app.logthin_M = app.log_Thin (app.testConfig.adec_idx_start(2) : ...
                    app.testConfig.adec_idx_end(2));
                FMthin_M = log(app.FM_thin_linM);
                app.Dt_M = app.logthin_M (1) - FMthin_M (1);
                % fit FM thin as third order polynomial
                emf_poly_M = polyfit(x_M, FMthin_M, 3);
                app.Pt_M = emf_poly_M(1);
                app.Qt_M = emf_poly_M(2);
                app.Rt_M = emf_poly_M(3);
                app.St_M = emf_poly_M(4);
                % polyfit max FM
                app.logmax_M = log(app.log_Max (app.testConfig.adec_idx_start(2) : ...
                    app.testConfig.adec_idx_end(2)));
                FMmax_M = log(FM_MaxM);
                % fit FM thin as third order polynomial
                emf_poly_M = polyfit(x_M, FMmax_M, 3);
                app.Pm_M = emf_poly_M(1);
                app.Qm_M = emf_poly_M(2);
                app.Rm_M = emf_poly_M(3);
                app.Sm_M = emf_poly_M(4);
            elseif app.testConfig.sensor(2) == 0
                app.logthin_M = double.empty;
                app.logmax_M = double.empty;
            end
            
            % L sensor
            if app.testConfig.sensor(3) == 1
                x_L = 1 : size(app.FM_thin_linL, 2);
                app.logthin_L = app.log_Thin (app.testConfig.adec_idx_start(3) : ...
                    app.testConfig.adec_idx_end(3));
                FMthin_L = log(app.FM_thin_linL);
                app.Dt_L = app.logthin_L (1) - FMthin_L (1);
                % fit FM thin as third order polynomial
                emf_poly_L = polyfit(x_L, FMthin_L, 3);
                app.Pt_L = emf_poly_L(1);
                app.Qt_L = emf_poly_L(2);
                app.Rt_L = emf_poly_L(3);
                app.St_L = emf_poly_L(4);
                % polyfit max FM
                app.logmax_L = log(app.log_Max (app.testConfig.adec_idx_start(3) : ...
                    app.testConfig.adec_idx_end(3)));
                FMmax_L = log(FM_MaxL);
                % fit FM thin as third order polynomial
                emf_poly_L = polyfit(x_L, FMmax_L, 3);
                app.Pm_L = emf_poly_L(1);
                app.Qm_L = emf_poly_L(2);
                app.Rm_L = emf_poly_L(3);
                app.Sm_L = emf_poly_L(4);
            elseif app.testConfig.sensor(3) == 0
                app.logthin_L = double.empty;
                app.logmax_L = double.empty;
            end
            
            % S sensor apply parameters A B C D
            if app.testConfig.sensor(1) == 1
                x_S = 1 : size(app.FM_thin_linS, 2);
                % calculate decay curve
                P_S = app.Pt_S * (1 + app.EditField_5.Value);
                Q_S = app.Qt_S * (1 + app.EditField_8.Value);
                R_S = app.Rt_S * (1 + app.EditField_12.Value);
                S_S = app.St_S + app.EditField_33.Value;
                emf_adj_poly = [P_S, Q_S, R_S, S_S];
                emf_adjusted = polyval(emf_adj_poly, x_S);
                emf_adj = zeros(1, max(app.testConfig.chendtime));
                emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(1)) + 1) :...
                    app.testConfig.chendtime(app.testConfig.adec_idx_end(1))) = emf_adjusted;
                for j = app.testConfig.adec_idx_start(1):app.testConfig.adec_idx_end(1)
                    FMthin_S(j) = mean(emf_adj(...
                        (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                end
                
                % make sure emf_adjusted > 0
                for i = 1:size(FMthin_S, 2)
                    if FMthin_S(i) <= 0
                        FMthin_S(i) = 0.01;
                    end
                end
                FMthin_S = FMthin_S(app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1));
                %                 FMthin_S = FMthin_S + app.EditField_29.Value;
                appFMthin_S = exp(FMthin_S);
            elseif app.testConfig.sensor(1) == 0
                appFMthin_S = double.empty;
            end
            
            % M sensor finding parameters
            if app.testConfig.sensor(2) == 1
                x_M = 1 : size(app.FM_thin_linM, 2);
                % calculate decay curve
                P_M = app.Pt_M * (1 + app.EditField_6.Value);
                Q_M = app.Qt_M * (1 + app.EditField_7.Value);
                R_M = app.Rt_M * (1 + app.EditField_11.Value);
                S_M = app.St_M + app.EditField_32.Value;
                emf_adj_poly = [P_M, Q_M, R_M, S_M];
                emf_adjusted = polyval(emf_adj_poly, x_M);
                emf_adj = zeros(1, max(app.testConfig.chendtime));
                emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(2)) + 1) :...
                    app.testConfig.chendtime(app.testConfig.adec_idx_end(2))) = emf_adjusted;
                for j = app.testConfig.adec_idx_start(2):app.testConfig.adec_idx_end(2)
                    FMthin_M(j) = mean(emf_adj(...
                        (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                end
                
                % make sure emf_adjusted > 0
                for i = 1:size(FMthin_M, 2)
                    if FMthin_M(i) <= 0
                        FMthin_M(i) = 0.01;
                    end
                end
                FMthin_M = FMthin_M(app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2));
                %                 FMthin_M = FMthin_M + app.EditField_28.Value;
                appFMthin_M = exp(FMthin_M);
            elseif app.testConfig.sensor(2) == 0
                appFMthin_M = double.empty;
            end
            
            % L sensor finding parameters
            if app.testConfig.sensor(3) == 1
                x_L = 1 : size(app.FM_thin_linL, 2);
                % calculate decay curve
                P_L = app.Pt_L * (1 + app.EditField_9.Value);
                Q_L = app.Qt_L * (1 + app.EditField_10.Value);
                R_L = app.Rt_L * (1 + app.EditField_13.Value);
                S_L = app.St_L + app.EditField_34.Value;
                emf_adj_poly = [P_L, Q_L, R_L, S_L];
                emf_adjusted = polyval(emf_adj_poly, x_L);
                emf_adj = zeros(1, max(app.testConfig.chendtime));
                emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(3)) + 1) :...
                    app.testConfig.chendtime(app.testConfig.adec_idx_end(3))) = emf_adjusted;
                for j = app.testConfig.adec_idx_start(3):app.testConfig.adec_idx_end(3)
                    FMthin_L(j) = mean(emf_adj(...
                        (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                end
                
                % make sure emf_adjusted > 0
                for i = 1:size(FMthin_L, 2)
                    if FMthin_L(i) <= 0
                        FMthin_L(i) = 0.01;
                    end
                end
                FMthin_L = FMthin_L(app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3));
                %                 FMthin_L = FMthin_L + app.EditField_31.Value;
                appFMthin_L = exp(FMthin_L);
            elseif app.testConfig.sensor(3) == 0
                appFMthin_L = double.empty;
            end
            
            % apply same magnifying process to FM decay curve
            % expand S sensor angle
            if app.testConfig.sensor(1) == 1
                if (~isempty(app.log_Med_lin)) && (~isempty(appFMthin_S)) && (~isempty(app.log_Min))
                    % expand angle in log domain
                    log_MedS = log(app.log_Med_lin(app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1)));
                end
                % calculate gap between log(med) and log(max/min), multiply
                % the index and add back to nom
                for n_CH = 1 : length(appFMthin_S)
                    if FMthin_S(n_CH) < log_MedS(n_CH)
                        appFMthin_S(n_CH) = exp(app.EditField_39.Value * (FMthin_S(n_CH) - log_MedS(n_CH)) + log_MedS(n_CH));
                    elseif FMthin_S(n_CH) >= log_MedS(n_CH)
                        appFMthin_S(n_CH) = exp(FMthin_S(n_CH));
                    end
                end
            elseif app.testConfig.sensor(1) == 0
                appFMthin_S = double.empty;
            end
            
            % expand M sensor angle
            if app.testConfig.sensor(2) == 1
                if (~isempty(app.log_Med_lin)) && (~isempty(appFMthin_M)) && (~isempty(app.log_Min))
                    % expand angle in log domain
                    log_MedM = log(app.log_Med_lin(app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2)));
                end
                % calculate gap between log(med) and log(max/min), multiply
                % the index and add back to nom
                for n_CH = 1 : length(appFMthin_M)
                    if FMthin_M(n_CH) < log_MedM(n_CH)
                        appFMthin_M(n_CH) = exp(app.EditField_40.Value * (FMthin_M(n_CH) - log_MedM(n_CH)) + log_MedM(n_CH));
                    elseif FMthin_M(n_CH) >= log_MedM(n_CH)
                        appFMthin_M(n_CH) = exp(FMthin_M(n_CH));
                    end
                end
            elseif app.testConfig.sensor(2) == 0
                appFMthin_M = double.empty;
            end
            
            % expand L sensor angle
            if app.testConfig.sensor(3) == 1
                if (~isempty(app.log_Med_lin)) && (~isempty(appFMthin_L)) && (~isempty(app.log_Min))
                    % expand angle in log domain
                    log_MedL = log(app.log_Med_lin(app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3)));
                end
                % calculate gap between log(med) and log(max/min), multiply
                % the index and add back to nom
                for n_CH = 1 : length(appFMthin_L)
                    if FMthin_L(n_CH) < log_MedL(n_CH)
                        appFMthin_L(n_CH) = exp(app.EditField_41.Value * (FMthin_L(n_CH) - log_MedL(n_CH)) + log_MedL(n_CH));
                    elseif FMthin_L(n_CH) >= log_MedL(n_CH)
                        appFMthin_L(n_CH) = exp(FMthin_L(n_CH));
                    end
                end
            elseif app.testConfig.sensor(3) == 0
                appFMthin_L = double.empty;
            end
            
            % smooth FM_L part in half and half
            appFMthin_L(1 : (length(appFMthin_L) / 2)) = transpose(smooth(appFMthin_L(1 : (length(appFMthin_L) / 2)), 3));
            appFMthin_L((length(appFMthin_L) / 2 + 1) : length(appFMthin_L)) = ...
                transpose(smooth(appFMthin_L((length(appFMthin_L) / 2 + 1) : length(appFMthin_L)), 3));
            
            % combine three sensors into one
            FMthin = [appFMthin_S, appFMthin_M, appFMthin_L];
            
            %%% plot FM_nom vs FM_x
            cla(app.UIAxes);
            % plot data
            semilogy(app.UIAxes, app.FM_x, app.log_Med_lin, 'g', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, app.log_Max, 'r', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, app.log_Thin_lin, 'Color', 'b', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, FMthin, 'Color', 'k', 'LineStyle', '-', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            leg1 = legend(app.UIAxes,{'Nom','Max','Min', 'Model'},'Location','northeast','Orientation','horizontal');
            msgbox("Finish calculating and plotting tk change values");
            
        end

        % Button pushed function: CalculateParametersButton
        function CalculateParametersButtonPushed(app, event)
            % S sensor finding parameters
            if app.testConfig.sensor(1) == 1
                msgbox("Finding parameters for S sensor");
                x_S = 1 : size(app.FM_thin_linS, 2);
                % find A and B in -50~50 range for S sensor for thin value
                % to assist the match of thin case
                % finding match should use data before magnification
                log_S = log(app.log_Min_perm (app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1)));
                i = 1;
                Sum = 1000000;
                Parameters = 0;
                for At_S = -1.5:0.1:1.5
                    for Bt_S = -2:0.1:2
                        for Ct_S = -2:0.1:2
                            
                            P_S = app.Pt_S * (1 + app.EditField_5.Value) * (1 + At_S);
                            Q_S = app.Qt_S * (1 + app.EditField_8.Value) * (1 + Bt_S);
                            R_S = app.Rt_S * (1 + app.EditField_12.Value) * (1 + Ct_S);
                            S_S = app.St_S;
                            emf_adj_poly = [P_S, Q_S, R_S, S_S];
                            emf_adjusted = polyval(emf_adj_poly, x_S);
                            emf_adj = zeros(1, max(app.testConfig.chendtime));
                            emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(1)) + 1) :...
                                app.testConfig.chendtime(app.testConfig.adec_idx_end(1))) = emf_adjusted;
                            for j = app.testConfig.adec_idx_start(1):app.testConfig.adec_idx_end(1)
                                FM_CH_S(j) = mean(emf_adj(...
                                    (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                            end
                            emf = FM_CH_S(app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1)) -...
                                (FM_CH_S(app.testConfig.adec_idx_start(1)) - log_S(1));
                            
                            for m = 1:size(log_S, 2)
                                if m <= size(log_S, 2)/3
                                    Misfit(m) = abs(1 * (emf(m) - log_S(m)) / log_S(m));
                                elseif m > size(log_S, 2)/3 && m <=  size(log_S, 2) * (2/3)
                                    Misfit(m) = abs(1 * (emf(m) - log_S(m)) / log_S(m));
                                elseif m > size(log_S, 2) * (2/3)
                                    Misfit(m) = abs(1 * (emf(m) - log_S(m)) / log_S(m));
                                end
                            end
                            Sum_misfit = sum(Misfit);
                            % for inspection
                            if abs(Sum_misfit) < abs(Sum)
                                Sum =  Sum_misfit;
                                Parameters(i, 1:3) = [At_S, Bt_S, Ct_S];
                                Parameters(i, 4) = Sum;
                                FMmin_S = emf;
                                i = i + 1;
                            end
                        end
                    end
                end
                app.At_S = Parameters(end, 1);
                app.Bt_S = Parameters(end, 2);
                app.Ct_S = Parameters(end, 3);
            end
            app.EditField_26.Value = app.Ct_S;
            app.EditField_29.Value = app.Dt_S;
            
            % M sensor finding parameters
            if app.testConfig.sensor(2) == 1
                msgbox("Finding parameters for M sensor");
                x_M = 1 : size(app.FM_thin_linM, 2);
                log_M = log(app.log_Min_perm (app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2)));
                i = 1;
                Sum = 1000000;
                Parameters = 0;
                for At_M = -5:0.1:5
                    for Bt_M = -4.5:0.1:4.5
                        for Ct_M = -4:0.1:4
                            
                            P_M = app.Pt_M * (1 + app.EditField_6.Value) * (1 + At_M);
                            Q_M = app.Qt_M * (1 + app.EditField_7.Value) * (1 + Bt_M);
                            R_M = app.Rt_M * (1 + app.EditField_11.Value) * (1 + Ct_M);
                            S_M = app.St_M;
                            emf_adj_poly = [P_M, Q_M, R_M, S_M];
                            emf_adjusted = polyval(emf_adj_poly, x_M);
                            emf_adj = zeros(1, max(app.testConfig.chendtime));
                            emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(2)) + 1) :...
                                app.testConfig.chendtime(app.testConfig.adec_idx_end(2))) = emf_adjusted;
                            for j = app.testConfig.adec_idx_start(2):app.testConfig.adec_idx_end(2)
                                FM_CH_M(j) = mean(emf_adj(...
                                    (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                            end
                            emf = FM_CH_M(app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2)) -...
                                (FM_CH_M(app.testConfig.adec_idx_start(2)) - log_M(1));
                            
                            for m = 1:size(log_M, 2)
                                if m <= size(log_M, 2)/3
                                    Misfit(m) = abs(1 * (emf(m) - log_M(m)) / log_M(m));
                                elseif m > size(log_M, 2)/3 && m <=  size(log_M, 2) * (2/3)
                                    Misfit(m) = abs(1 * (emf(m) - log_M(m)) / log_M(m));
                                elseif m > size(log_M, 2) * (2/3)
                                    Misfit(m) = abs(1 * (emf(m) - log_M(m)) / log_M(m));
                                end
                            end
                            Sum_misfit = sum(Misfit);
                            % for inspection
                            if abs(Sum_misfit) < abs(Sum)
                                Sum =  Sum_misfit;
                                Parameters(i, 1:3) = [At_M, Bt_M, Ct_M];
                                Parameters(i, 4) = Sum;
                                FMmin_S = emf;
                                i = i + 1;
                            end
                        end
                    end
                end
                app.At_M = Parameters(end, 1);
                app.Bt_M = Parameters(end, 2);
                app.Ct_M = Parameters(end, 3);
            end
            app.EditField_27.Value = app.Ct_M;
            app.EditField_28.Value = app.Dt_M;
            
            % L sensor finding parameters
            if app.testConfig.sensor(3) == 1
                msgbox("Finding parameters for L sensor");
                x_L = 1 : size(app.FM_thin_linL, 2);
                log_L = log(app.log_Min_perm (app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3)));
                i = 1;
                Sum = 1000000;
                Parameters = 0;
                % find alpha and beta in -50~50 range for L sensor
                %                 for At_L = -4.5:0.1:4.5
                for Bt_L = -4:0.1:4
                    for Ct_L = -4:0.1:4
                        
                        P_L = app.Pt_L * (1 + app.EditField_9.Value); %* (1 + At_L);
                        Q_L = app.Qt_L * (1 + app.EditField_10.Value) * (1 + Bt_L);
                        R_L = app.Rt_L * (1 + app.EditField_13.Value) * (1 + Ct_L);
                        S_L = app.St_L;
                        emf_adj_poly = [P_L, Q_L, R_L, S_L];
                        emf_adjusted = polyval(emf_adj_poly, x_L);
                        emf_adj = zeros(1, max(app.testConfig.chendtime));
                        emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(3)) + 1) :...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(3))) = emf_adjusted;
                        for j = app.testConfig.adec_idx_start(3):app.testConfig.adec_idx_end(3)
                            FM_CH_L(j) = mean(emf_adj(...
                                (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                        end
                        emf = FM_CH_L(app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3)) -...
                            (FM_CH_L(app.testConfig.adec_idx_start(3)) - log_L(1));
                        
                        % avoid effect of noise
                        n_ch = max(app.testConfig.adec_idx_end);
                        if n_ch >= 96
                            x = 7;
                        elseif n_ch == 60
                            x = 3;
                        elseif n_ch < 60
                            x = 2;
                        end
                        
                        for m = 1:size(log_L, 2)
                            if m <= size(log_L, 2)/3
                                Misfit(m) = abs(1 * (emf(m) - log_L(m)) / log_L(m));
                            elseif m > size(log_L, 2)/3 && m <=  size(log_L, 2) * (2/3)
                                Misfit(m) = abs(1 * (emf(m) - log_L(m)) / log_L(m));
                            elseif m > size(log_L, 2) * (2/3) && m <= (size(log_L, 2) - x)
                                Misfit(m) = abs(1 * (emf(m) - log_L(m)) / log_L(m));
                            elseif m > (size(log_L, 2) - x)
                                Misfit(m) = abs(0.1 * (emf(m) - log_L(m)) / log_L(m));
                            end
                        end
                        Sum_misfit = sum(Misfit);
                        % for inspection
                        if abs(Sum_misfit) < abs(Sum)
                            Sum = Sum_misfit;
                            Parameters(i, 1:3) = [1, Bt_L, Ct_L];
                            Parameters(i, 4) = Sum;
                            FMmin_L = emf;
                            i = i + 1;
                        end
                    end
                    %                     end
                end
            end
            app.At_L = Parameters(end, 1);
            app.Bt_L = Parameters(end, 2);
            app.Ct_L = Parameters(end, 3);
            
            app.EditField_30.Value = app.Ct_L;
            app.EditField_31.Value = app.Dt_L;
            
            msgbox("Parameters found!");
            
            %             % disable find parameter button after finding
            %             set(app.FindParametersButton_2, 'Enable', 'off');
            
        end

        % Button pushed function: ApplyParametersButton_2
        function ApplyParametersButton_2Pushed(app, event)
            % S sensor finding parameters
            if app.testConfig.sensor(1) == 1
                x_S = 1 : size(app.FM_thin_linS, 2);
                % calculate decay curve
                P_S = app.Pt_S * (1 + app.EditField_5.Value) * (1 + app.At_S);
                Q_S = app.Qt_S * (1 + app.EditField_8.Value) * (1 + app.Bt_S);%* (1 + app.EditField_26.Value);
                R_S = app.Rt_S * (1 + app.EditField_12.Value) * (1 + app.EditField_26.Value);
                S_S = app.St_S;
                emf_adj_poly = [P_S, Q_S, R_S, S_S];
                emf_adjusted = polyval(emf_adj_poly, x_S);
                emf_adj = zeros(1, max(app.testConfig.chendtime));
                emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(1)) + 1) :...
                    app.testConfig.chendtime(app.testConfig.adec_idx_end(1))) = emf_adjusted;
                for j = app.testConfig.adec_idx_start(1):app.testConfig.adec_idx_end(1)
                    FMthin_S(j) = mean(emf_adj(...
                        (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                end
                
                % make sure emf_adjusted > 0
                for i = 1:size(FMthin_S, 2)
                    if FMthin_S(i) <= 0
                        FMthin_S(i) = 0.01;
                    end
                end
                FMthin_S = FMthin_S(app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1));
                FMthin_S = FMthin_S + app.EditField_29.Value;
                app.FMthin_S = exp(FMthin_S);
            elseif app.testConfig.sensor(1) == 0
                app.FMthin_S = double.empty;
            end
            
            % M sensor finding parameters
            if app.testConfig.sensor(2) == 1
                x_M = 1 : size(app.FM_thin_linM, 2);
                % calculate decay curve
                P_M = app.Pt_M * (1 + app.EditField_6.Value) * (1 + app.At_M);
                Q_M = app.Qt_M * (1 + app.EditField_7.Value) * (1 + app.Bt_M);%* (1 + app.EditField_27.Value);
                R_M = app.Rt_M * (1 + app.EditField_11.Value) * (1 + app.EditField_27.Value);
                S_M = app.St_M;
                emf_adj_poly = [P_M, Q_M, R_M, S_M];
                emf_adjusted = polyval(emf_adj_poly, x_M);
                emf_adj = zeros(1, max(app.testConfig.chendtime));
                emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(2)) + 1) :...
                    app.testConfig.chendtime(app.testConfig.adec_idx_end(2))) = emf_adjusted;
                for j = app.testConfig.adec_idx_start(2):app.testConfig.adec_idx_end(2)
                    FMthin_M(j) = mean(emf_adj(...
                        (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                end
                
                % make sure emf_adjusted > 0
                for i = 1:size(FMthin_M, 2)
                    if FMthin_M(i) <= 0
                        FMthin_M(i) = 0.01;
                    end
                end
                FMthin_M = FMthin_M(app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2));
                FMthin_M = FMthin_M + app.EditField_28.Value;
                app.FMthin_M = exp(FMthin_M);
            elseif app.testConfig.sensor(2) == 0
                app.FMthin_M = double.empty;
            end
            
            % L sensor finding parameters
            if app.testConfig.sensor(3) == 1
                x_L = 1 : size(app.FM_thin_linL, 2);
                % calculate decay curve
                P_L = app.Pt_L * (1 + app.EditField_9.Value);% * (1 + app.At_L);
                Q_L = app.Qt_L * (1 + app.EditField_10.Value) * (1 + app.Bt_L);%* (1 + app.EditField_30.Value);
                R_L = app.Rt_L * (1 + app.EditField_13.Value) * (1 + app.EditField_30.Value);
                S_L = app.St_L;
                emf_adj_poly = [P_L, Q_L, R_L, S_L];
                emf_adjusted = polyval(emf_adj_poly, x_L);
                emf_adj = zeros(1, max(app.testConfig.chendtime));
                emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(3)) + 1) :...
                    app.testConfig.chendtime(app.testConfig.adec_idx_end(3))) = emf_adjusted;
                for j = app.testConfig.adec_idx_start(3):app.testConfig.adec_idx_end(3)
                    FMthin_L(j) = mean(emf_adj(...
                        (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                end
                
                % make sure emf_adjusted > 0
                for i = 1:size(FMthin_L, 2)
                    if FMthin_L(i) <= 0
                        FMthin_L(i) = 0.01;
                    end
                end
                FMthin_L = FMthin_L(app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3));
                FMthin_L = FMthin_L + app.EditField_31.Value;
                app.FMthin_L = exp(FMthin_L);
            elseif app.testConfig.sensor(3) == 0
                app.FMthin_L = double.empty;
            end
            
            % apply same magnifying process to FM decay curve
            % expand S sensor angle
            if app.testConfig.sensor(1) == 1
                if (~isempty(app.log_Med_lin)) && (~isempty(app.FMthin_S)) && (~isempty(app.log_Min))
                    % expand angle in log domain
                    log_MedS = log(app.log_Med_lin(app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1)));
                end
                % calculate gap between log(med) and log(max/min), multiply
                % the index and add back to nom
                for n_CH = 1 : length(app.FMthin_S)
                    if FMthin_S(n_CH) < log_MedS(n_CH)
                        app.FMthin_S(n_CH) = exp(app.EditField_39.Value * (FMthin_S(n_CH) - log_MedS(n_CH)) + log_MedS(n_CH));
                    elseif FMthin_S(n_CH) >= log_MedS(n_CH)
                        app.FMthin_S(n_CH) = exp(FMthin_S(n_CH));
                    end
                end
            elseif app.testConfig.sensor(1) == 0
                app.FMthin_S = double.empty;
            end
            
            % expand M sensor angle
            if app.testConfig.sensor(2) == 1
                if (~isempty(app.log_Med_lin)) && (~isempty(app.FMthin_M)) && (~isempty(app.log_Min))
                    % expand angle in log domain
                    log_MedM = log(app.log_Med_lin(app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2)));
                end
                % calculate gap between log(med) and log(max/min), multiply
                % the index and add back to nom
                for n_CH = 1 : length(app.FMthin_M)
                    if FMthin_M(n_CH) < log_MedM(n_CH)
                        app.FMthin_M(n_CH) = exp(app.EditField_40.Value * (FMthin_M(n_CH) - log_MedM(n_CH)) + log_MedM(n_CH));
                    elseif FMthin_M(n_CH) >= log_MedM(n_CH)
                        app.FMthin_M(n_CH) = exp(FMthin_M(n_CH));
                    end
                end
            elseif app.testConfig.sensor(2) == 0
                app.FMthin_M = double.empty;
            end
            
            % expand L sensor angle
            if app.testConfig.sensor(3) == 1
                if (~isempty(app.log_Med_lin)) && (~isempty(app.FMthin_L)) && (~isempty(app.log_Min))
                    % expand angle in log domain
                    log_MedL = log(app.log_Med_lin(app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3)));
                end
                % calculate gap between log(med) and log(max/min), multiply
                % the index and add back to nom
                for n_CH = 1 : length(app.FMthin_L)
                    if FMthin_L(n_CH) < log_MedL(n_CH)
                        app.FMthin_L(n_CH) = exp(app.EditField_41.Value * (FMthin_L(n_CH) - log_MedL(n_CH)) + log_MedL(n_CH));
                    elseif FMthin_L(n_CH) >= log_MedL(n_CH)
                        app.FMthin_L(n_CH) = exp(FMthin_L(n_CH));
                    end
                end
            elseif app.testConfig.sensor(3) == 0
                app.FMthin_L = double.empty;
            end
            
            % smooth FM_L part in half and half
            app.FMthin_L(1 : (length(app.FMthin_L) / 2)) = transpose(smooth(app.FMthin_L(1 : (length(app.FMthin_L) / 2)), 3));
            app.FMthin_L((length(app.FMthin_L) / 2 + 1) : length(app.FMthin_L)) = ...
                transpose(smooth(app.FMthin_L((length(app.FMthin_L) / 2 + 1) : length(app.FMthin_L)), 6));
            
            % combine three sensors into one
            app.FMthin_adj = [app.FMthin_S, app.FMthin_M, app.FMthin_L];
            
            %%% plot FM_nom vs FM_x
            cla(app.UIAxes);
            % plot data
            semilogy(app.UIAxes, app.FM_x, app.log_Med_lin, 'g', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, app.log_Max, 'r', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, app.log_Thin_lin, 'Color', 'b','LineWidth', 0.7);
            hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, app.FMthin_adj, 'Color', 'k', 'LineStyle', '-', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            leg1 = legend(app.UIAxes,{'Nom', 'Max', 'Min','Model'},'Location','northeast','Orientation','horizontal');
            
            % disabled if no app.FMnom_adj
            if isempty(app.FMthin_adj)
                set(app.CombineButton_3, 'Enable', 'off');
            else
                set(app.CombineButton_3, 'Enable', 'on');
            end
            
        end

        % Button pushed function: CombineButton_2
        function CombineButton_2Pushed(app, event)
            % connect S and M for all curves
            if app.testConfig.sensor(2) == 1
                if ~isempty(app.FMnom_adj)
                    log_FM_nom = log(app.FMnom_adj);
                    log_FM_nom(app.testConfig.adec_idx_start(2)) = log_FM_nom(app.testConfig.adec_idx_start(2) - 1) - ...
                        (log_FM_nom(app.testConfig.adec_idx_start(2) - 2) - log_FM_nom(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        log_FM_nom(i) = log(app.FMnom_adj(i)) - (log(app.FMnom_adj(app.testConfig.adec_idx_start(2))) -...
                            log_FM_nom(app.testConfig.adec_idx_start(2)));
                    end
                end
                if ~isempty(app.log_Max)
                    log_Max = log(app.log_Max);
                    log_Max(app.testConfig.adec_idx_start(2)) = log_Max(app.testConfig.adec_idx_start(2) - 1) - ...
                        (log_Max(app.testConfig.adec_idx_start(2) - 2) - log_Max(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        log_Max(i) = log(app.log_Max(i)) - (log(app.log_Max(app.testConfig.adec_idx_start(2))) -...
                            log_Max(app.testConfig.adec_idx_start(2)));
                    end
                end
                if ~isempty(app.log_Min)
                    log_Min = log(app.log_Min);
                    log_Min(app.testConfig.adec_idx_start(2)) = log_Min(app.testConfig.adec_idx_start(2) - 1) - ...
                        (log_Min(app.testConfig.adec_idx_start(2) - 2) - log_Min(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        log_Min(i) = log(app.log_Min(i)) - (log(app.log_Min(app.testConfig.adec_idx_start(2))) -...
                            log_Min(app.testConfig.adec_idx_start(2)));
                    end
                end
                if app.SectionalDataCheckBox.Value == 1
                    if ~isempty(app.log_med_sect)
                        log_Med_sect = log(app.log_med_sect);
                        log_Med_sect(app.testConfig.adec_idx_start(2)) = log_Med_sect(app.testConfig.adec_idx_start(2) - 1) - ...
                            (log_Med_sect(app.testConfig.adec_idx_start(2) - 2) - log_Med_sect(app.testConfig.adec_idx_start(2) - 1));
                        for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                            log_Med_sect(i) = log(app.log_med_sect(i)) - (log(app.log_med_sect(app.testConfig.adec_idx_start(2))) -...
                                log_Med_sect(app.testConfig.adec_idx_start(2)));
                        end
                    end
                    if ~isempty(app.log_Max_sect)
                        log_Max_sect = log(app.log_Max_sect);
                        log_Max_sect(app.testConfig.adec_idx_start(2)) = log_Max_sect(app.testConfig.adec_idx_start(2) - 1) - ...
                            (log_Max_sect(app.testConfig.adec_idx_start(2) - 2) - log_Max_sect(app.testConfig.adec_idx_start(2) - 1));
                        for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                            log_Max_sect(i) = log(app.log_Max_sect(i)) - (log(app.log_Max_sect(app.testConfig.adec_idx_start(2))) -...
                                log_Max_sect(app.testConfig.adec_idx_start(2)));
                        end
                    end
                    if ~isempty(app.log_Min_sect)
                        log_Min_sect = log(app.log_Min_sect);
                        log_Min_sect(app.testConfig.adec_idx_start(2)) = log_Min_sect(app.testConfig.adec_idx_start(2) - 1) - ...
                            (log_Min_sect(app.testConfig.adec_idx_start(2) - 2) - log_Min_sect(app.testConfig.adec_idx_start(2) - 1));
                        for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                            log_Min_sect(i) = log(app.log_Min_sect(i)) - (log(app.log_Min_sect(app.testConfig.adec_idx_start(2))) -...
                                log_Min_sect(app.testConfig.adec_idx_start(2)));
                        end
                    end
                end
            end
            
            % connect M and L for all curves
            if app.testConfig.sensor(3) == 1
                if ~isempty(app.FMnom_adj)
                    log_FM_nom(app.testConfig.adec_idx_start(3)) = log_FM_nom(app.testConfig.adec_idx_start(3) - 1) - ...
                        (log_FM_nom(app.testConfig.adec_idx_start(3) - 2) - log_FM_nom(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        log_FM_nom(i) = log(app.FMnom_adj(i)) - (log(app.FMnom_adj(app.testConfig.adec_idx_start(3))) -...
                            log_FM_nom(app.testConfig.adec_idx_start(3)));
                    end
                end
                if ~isempty(app.log_Max)
                    log_Max(app.testConfig.adec_idx_start(3)) = log_Max(app.testConfig.adec_idx_start(3) - 1) - ...
                        (log_Max(app.testConfig.adec_idx_start(3) - 2) - log_Max(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        log_Max(i) = log(app.log_Max(i)) - (log(app.log_Max(app.testConfig.adec_idx_start(3))) -...
                            log_Max(app.testConfig.adec_idx_start(3)));
                    end
                end
                if ~isempty(app.log_Min)
                    log_Min(app.testConfig.adec_idx_start(3)) = log_Min(app.testConfig.adec_idx_start(3) - 1) - ...
                        (log_Min(app.testConfig.adec_idx_start(3) - 2) - log_Min(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        log_Min(i) = log(app.log_Min(i)) - (log(app.log_Min(app.testConfig.adec_idx_start(3))) -...
                            log_Min(app.testConfig.adec_idx_start(3)));
                    end
                end
                if app.SectionalDataCheckBox.Value == 1
                    if ~isempty(app.log_med_sect)
                        log_Med_sect(app.testConfig.adec_idx_start(3)) = log_Med_sect(app.testConfig.adec_idx_start(3) - 1) - ...
                            (log_Med_sect(app.testConfig.adec_idx_start(3) - 2) - log_Med_sect(app.testConfig.adec_idx_start(3) - 1));
                        for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                            log_Med_sect(i) = log(app.log_med_sect(i)) - (log(app.log_med_sect(app.testConfig.adec_idx_start(3))) -...
                                log_Med_sect(app.testConfig.adec_idx_start(3)));
                        end
                    end
                    if ~isempty(app.log_Max_sect)
                        log_Max_sect(app.testConfig.adec_idx_start(3)) = log_Max_sect(app.testConfig.adec_idx_start(3) - 1) - ...
                            (log_Max_sect(app.testConfig.adec_idx_start(3) - 2) - log_Max_sect(app.testConfig.adec_idx_start(3) - 1));
                        for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                            log_Max_sect(i) = log(app.log_Max_sect(i)) - (log(app.log_Max_sect(app.testConfig.adec_idx_start(3))) -...
                                log_Max_sect(app.testConfig.adec_idx_start(3)));
                        end
                    end
                    if ~isempty(app.log_Min_sect)
                        log_Min_sect(app.testConfig.adec_idx_start(3)) = log_Min_sect(app.testConfig.adec_idx_start(3) - 1) - ...
                            (log_Min_sect(app.testConfig.adec_idx_start(3) - 2) - log_Min_sect(app.testConfig.adec_idx_start(3) - 1));
                        for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                            log_Min_sect(i) = log(app.log_Min_sect(i)) - (log(app.log_Min_sect(app.testConfig.adec_idx_start(3))) -...
                                log_Min_sect(app.testConfig.adec_idx_start(3)));
                        end
                    end
                end
            end
            
            % reverse natural log
            log_FM_nom = log_FM_nom - (log_FM_nom(1) - log(app.log_med_sect(1)));
            log_FM_nom = exp(log_FM_nom);
            log_Max = exp(log_Max);
            log_Min = exp(log_Min);
            if app.SectionalDataCheckBox.Value == 1
                log_Med_sect = exp(log_Med_sect);
                log_Max_sect = exp(log_Max_sect);
                log_Min_sect = exp(log_Min_sect);
            end
            
            %%% plot FM_nom vs FM_x
            cla(app.UIAxes);
            %semilogy(app.UIAxes, app.FM_x, app.log_Med_lin, 'g', 'LineWidth', 0.7);
            %hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, log_FM_nom, 'Color', 'k', 'LineStyle', '-', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, log_Max, 'r', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, log_Min, 'b', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            % if sectional line box checked, show sectional lines
            if app.SectionalDataCheckBox.Value == 1
                % plot three lines
                semilogy(app.UIAxes, app.FM_x, log_Med_sect, 'Color', 'k', 'LineStyle', '-.', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                semilogy(app.UIAxes, app.FM_x, log_Max_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                semilogy(app.UIAxes, app.FM_x, log_Min_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                leg1 = legend(app.UIAxes,{'Model','Max','Min','Sect-Nom','Sect-Max','Sect-Min'},'Location','northeast','Orientation','horizontal');
            elseif app.SectionalDataCheckBox.Value == 0
                leg1 = legend(app.UIAxes,{'Model','Max','Min'},'Location','northeast','Orientation','horizontal');
            end
            
        end

        % Button pushed function: CombineButton_3
        function CombineButton_3Pushed(app, event)
            % connect S and M for all curves
            if app.testConfig.sensor(2) == 1
                if ~isempty(app.log_Med_lin)
                    log_Med_lin = log(app.log_Med_lin);
                    log_Med_lin(app.testConfig.adec_idx_start(2)) = log_Med_lin(app.testConfig.adec_idx_start(2) - 1) - ...
                        (log_Med_lin(app.testConfig.adec_idx_start(2) - 2) - log_Med_lin(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        log_Med_lin(i) = log(app.log_Med_lin(i)) - (log(app.log_Med_lin(app.testConfig.adec_idx_start(2))) -...
                            log_Med_lin(app.testConfig.adec_idx_start(2)));
                    end
                end
                if ~isempty(app.log_Max)
                    log_Max = log(app.log_Max);
                    log_Max(app.testConfig.adec_idx_start(2)) = log_Max(app.testConfig.adec_idx_start(2) - 1) - ...
                        (log_Max(app.testConfig.adec_idx_start(2) - 2) - log_Max(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        log_Max(i) = log(app.log_Max(i)) - (log(app.log_Max(app.testConfig.adec_idx_start(2))) -...
                            log_Max(app.testConfig.adec_idx_start(2)));
                    end
                end
                if ~isempty(app.log_Thin_lin)
                    log_Thin_lin = log(app.log_Thin_lin);
                    log_Thin_lin(app.testConfig.adec_idx_start(2)) = log_Thin_lin(app.testConfig.adec_idx_start(2) - 1) - ...
                        (log_Thin_lin(app.testConfig.adec_idx_start(2) - 2) - log_Thin_lin(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        log_Thin_lin(i) = log(app.log_Thin_lin(i)) - (log(app.log_Thin_lin(app.testConfig.adec_idx_start(2))) -...
                            log_Thin_lin(app.testConfig.adec_idx_start(2)));
                    end
                end
                if ~isempty(app.FMthin_adj)
                    FMthin_adj = log(app.FMthin_adj);
                    FMthin_adj(app.testConfig.adec_idx_start(2)) = FMthin_adj(app.testConfig.adec_idx_start(2) - 1) - ...
                        (FMthin_adj(app.testConfig.adec_idx_start(2) - 2) - FMthin_adj(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        FMthin_adj(i) = log(app.FMthin_adj(i)) - (log(app.FMthin_adj(app.testConfig.adec_idx_start(2))) -...
                            FMthin_adj(app.testConfig.adec_idx_start(2)));
                    end
                end
            end
            % connect M and L for all curves
            if app.testConfig.sensor(3) == 1
                if ~isempty(app.log_Med_lin)
                    log_Med_lin(app.testConfig.adec_idx_start(3)) = log_Med_lin(app.testConfig.adec_idx_start(3) - 1) - ...
                        (log_Med_lin(app.testConfig.adec_idx_start(3) - 2) - log_Med_lin(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        log_Med_lin(i) = log(app.log_Med_lin(i)) - (log(app.log_Med_lin(app.testConfig.adec_idx_start(3))) -...
                            log_Med_lin(app.testConfig.adec_idx_start(3)));
                    end
                end
                if ~isempty(app.log_Max)
                    log_Max(app.testConfig.adec_idx_start(3)) = log_Max(app.testConfig.adec_idx_start(3) - 1) - ...
                        (log_Max(app.testConfig.adec_idx_start(3) - 2) - log_Max(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        log_Max(i) = log(app.log_Max(i)) - (log(app.log_Max(app.testConfig.adec_idx_start(3))) -...
                            log_Max(app.testConfig.adec_idx_start(3)));
                    end
                end
                if ~isempty(app.log_Thin_lin)
                    log_Thin_lin(app.testConfig.adec_idx_start(3)) = log_Thin_lin(app.testConfig.adec_idx_start(3) - 1) - ...
                        (log_Thin_lin(app.testConfig.adec_idx_start(3) - 2) - log_Thin_lin(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        log_Thin_lin(i) = log(app.log_Thin_lin(i)) - (log(app.log_Thin_lin(app.testConfig.adec_idx_start(3))) -...
                            log_Thin_lin(app.testConfig.adec_idx_start(3)));
                    end
                end
                if ~isempty(app.FMthin_adj)
                    FMthin_adj(app.testConfig.adec_idx_start(3)) = FMthin_adj(app.testConfig.adec_idx_start(3) - 1) - ...
                        (FMthin_adj(app.testConfig.adec_idx_start(3) - 2) - FMthin_adj(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        FMthin_adj(i) = log(app.FMthin_adj(i)) - (log(app.FMthin_adj(app.testConfig.adec_idx_start(3))) -...
                            FMthin_adj(app.testConfig.adec_idx_start(3)));
                    end
                end
            end
            
            % align all connected curves at first CH
            FMthin_adj = FMthin_adj - (FMthin_adj(1) - log_Thin_lin(1));
            % reverse natural log
            log_Med_lin = exp(log_Med_lin);
            log_Max = exp(log_Max);
            log_Thin_lin = exp(log_Thin_lin);
            FMthin_adj = exp(FMthin_adj);
            
            %%% plot FM_nom vs FM_x
            cla(app.UIAxes);
            % plot data
            semilogy(app.UIAxes, app.FM_x, log_Med_lin, 'g', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, log_Max, 'r', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, log_Thin_lin, 'Color', 'b', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, FMthin_adj, 'Color', 'k', 'LineStyle', '-', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            leg1 = legend(app.UIAxes,{'Nom', 'Max', 'Min','Model'},'Location','northeast','Orientation','horizontal');
            
        end

        % Callback function
        function ModifyNomButtonPushed(app, event)
            
        end

        % Value changed function: SectionsDropDown
        function SectionsDropDownValueChanged(app, event)
            value = app.SectionsDropDown.Value;
            
            depths = unique([app.pipeList.TopDepth, app.pipeList.BottomDepth]);
            
            % depth selection
            app.depth_min = depths(value);
            app.depth_max = depths(value + 1);
            
            i_Section = zeros(size(app.lasDepth, 1), 1); % logic array for selected sectional depth (1 depth only)
            
            for d = 1:size(app.lasDepth, 1)
                if app.lasDepth(d) >= app.depth_min && app.lasDepth(d) <= app.depth_max
                    i_Section(d) = 1;
                end
            end
            i_Section = logical(i_Section);
            
            % display depth of the section mid
            app.EditField_18.Value = (app.depth_min + app.depth_max)/2;
            
            % store selected ADEC in a varible for selected depth
            sectional_ADEC = app.ADEC(i_Section, :);
            
            %%% get config information
            selected_Mode = app.testMode(i_Section, :);
            app.ModeUsed = selected_Mode(1, 1);
            
            % Read ePDT ini file for certain mode
            % Input:
            %   app.configFileName    target file name
            %   testMode              selected config mode from .las file
            %
            % Output:
            %   status      success or not
            %   app.testConfig  config contents
            
            status = false;
            cfg_str = sprintf('[Config_%d]', app.ModeUsed);
            
            app.testConfig.configname = '';
            app.testConfig.cmdcode = '';
            app.testConfig.sensor = false(1, 3);      % enable status for S, M and L
            app.testConfig.txcurrent = zeros(1, 3);
            app.testConfig.txduration = zeros(1, 3);
            app.testConfig.chnum = zeros(1, 3);
            app.testConfig.chgain = cell(1, 3);       % unknown total channel number
            app.testConfig.chstarttime = [];
            app.testConfig.chendtime = [];
            app.testConfig.acq_idx_start = zeros(1, 3);
            app.testConfig.acq_idx_end = zeros(1, 3);
            app.testConfig.firingsequences = '';
            app.testConfig.fullsampletime = zeros(1, 3);
            app.testConfig.loggingspeed = 0;
            app.testConfig.adec_idx_start = zeros(1, 3);
            app.testConfig.adec_idx_end = zeros(1, 3);
            app.testConfig.chdelaytime = 20;          % defaulted to 20
            app.testConfig.turns = zeros(1, 3);
            app.testConfig.n_ch = 0;
            
            fid = fopen(app.configFileName); %% app.configFileName is the name of selected ini file
            while ~feof(fid)
                line = fgetl(fid);
                if strcmp(line, cfg_str)    % locate the correct config
                    while ~feof(fid) && ~isempty(line)
                        line = fgetl(fid);  % read line by line
                        paras = strsplit(line, '=');
                        switch paras{1}
                            case 'ConfigName'
                                app.testConfig.configname = paras{2};
                            case 'CmdCode'
                                app.testConfig.cmdcode = paras{2};
                            case 'SensorS'
                                app.testConfig.sensor(1) = strcmpi(paras{2}, 'true');
                            case 'SensorM'
                                app.testConfig.sensor(2) = strcmpi(paras{2}, 'true');
                            case 'SensorL'
                                app.testConfig.sensor(3) = strcmpi(paras{2}, 'true');
                            case 'TxCurrentS'
                                app.testConfig.txcurrent(1) = str2double(paras{2});
                            case 'TxDurationS'
                                app.testConfig.txduration(1) = str2double(paras{2});
                            case 'TxCurrentM'
                                app.testConfig.txcurrent(2) = str2double(paras{2});
                            case 'TxDurationM'
                                app.testConfig.txduration(2) = str2double(paras{2});
                            case 'TxCurrentL'
                                app.testConfig.txcurrent(3) = str2double(paras{2});
                            case 'TxDurationL'
                                app.testConfig.txduration(3) = str2double(paras{2});
                            case 'ChNumS'
                                app.testConfig.chnum(1) = str2double(paras{2});
                            case 'ChGainS'
                                app.testConfig.chgain{1} = str2double(strsplit(paras{2}, ','));
                            case 'ChNumM'
                                app.testConfig.chnum(2) = str2double(paras{2});
                            case 'ChGainM'
                                app.testConfig.chgain{2} = str2double(strsplit(paras{2}, ','));
                            case 'ChNumL'
                                app.testConfig.chnum(3) = str2double(paras{2});
                            case 'ChGainL'
                                app.testConfig.chgain{3} = str2double(strsplit(paras{2}, ','));
                            case 'ChStartTime'
                                app.testConfig.chstarttime = str2double(strsplit(paras{2}, ','));
                            case 'ChEndTime'
                                app.testConfig.chendtime = str2double(strsplit(paras{2}, ','));
                            case 'StartIdxSensorS'
                                app.testConfig.acq_idx_start(1) = str2double(paras{2});
                            case 'EndIdxSensorS'
                                app.testConfig.acq_idx_end(1) = str2double(paras{2});
                            case 'StartIdxSensorM'
                                app.testConfig.acq_idx_start(2) = str2double(paras{2});
                            case 'EndIdxSensorM'
                                app.testConfig.acq_idx_end(2) = str2double(paras{2});
                            case 'StartIdxSensorL'
                                app.testConfig.acq_idx_start(3) = str2double(paras{2});
                            case 'EndIdxSensorL'
                                app.testConfig.acq_idx_end(3) = str2double(paras{2});
                            case 'FiringSequences'
                                app.testConfig.firingsequences = paras{2};
                            case 'FullSampleTimeS'
                                app.testConfig.fullsampletime(1) = str2double(paras{2});
                            case 'FullSampleTimeM'
                                app.testConfig.fullsampletime(2) = str2double(paras{2});
                            case 'FullSampleTimeL'
                                app.testConfig.fullsampletime(3) = str2double(paras{2});
                            case 'LoggingSpeed'
                                app.testConfig.loggingspeed = str2double(paras{2});
                            case 'ADEC_S_START'
                                app.testConfig.adec_idx_start(1) = str2double(paras{2});
                            case 'ADEC_S_END'
                                if app.testConfig.adec_idx_start(1) == 0
                                    app.testConfig.adec_idx_end(1) = -1;
                                else
                                    app.testConfig.adec_idx_end(1) = str2double(paras{2});
                                end
                            case 'ADEC_M_START'
                                app.testConfig.adec_idx_start(2) = str2double(paras{2});
                            case 'ADEC_M_END'
                                if app.testConfig.adec_idx_start(2) == 0
                                    app.testConfig.adec_idx_end(2) = -1;
                                else
                                    app.testConfig.adec_idx_end(2) = str2double(paras{2});
                                end
                            case 'ADEC_L_START'
                                app.testConfig.adec_idx_start(3) = str2double(paras{2});
                            case 'ADEC_L_END'
                                if app.testConfig.adec_idx_start(3) == 0
                                    app.testConfig.adec_idx_end(3) = -1;
                                else
                                    app.testConfig.adec_idx_end(3) = str2double(paras{2});
                                end
                            case 'ChDelayTime'
                                app.testConfig.chdelaytime = str2double(paras{2});
                            case 'TurnsS'
                                app.testConfig.turns(1) = str2double(paras{2});
                            case 'TurnsM'
                                app.testConfig.turns(2) = str2double(paras{2});
                            case 'TurnsL'
                                app.testConfig.turns(3) = str2double(paras{2});
                        end
                    end
                    
                    n_ch = sum(app.testConfig.adec_idx_end - app.testConfig.adec_idx_start + 1);
                    if length(app.testConfig.chstarttime) ~= length(app.testConfig.chendtime) || ...
                            n_ch > length(app.testConfig.chstarttime)
                        break;
                    end
                    
                    app.testConfig.n_ch = n_ch;
                    status = true;
                    break;
                end
            end
            fclose(fid);
            
            %%% get, degain and plot max, min, med and plot
            % get, degain and plot max, min, med
            log_med = median(sectional_ADEC);
            app.FM_x = 1:size(log_med, 2);
            window_Gain = app.testConfig.chendtime - app.testConfig.chstarttime;
            coilTurn_Gain = ones(size(window_Gain));
            for CH_coil = 1:size(app.testConfig.chstarttime, 2)
                if CH_coil >= app.testConfig.adec_idx_start(1) && CH_coil <= app.testConfig.adec_idx_end(1) &&...
                        app.testConfig.adec_idx_start(1) < app.testConfig.adec_idx_end(1)
                    coilTurn_Gain(CH_coil) = 1 / app.testConfig.turns(1);
                elseif CH_coil >= app.testConfig.adec_idx_start(2) && CH_coil <= app.testConfig.adec_idx_end(2) &&...
                        app.testConfig.adec_idx_start(2) < app.testConfig.adec_idx_end(2)
                    coilTurn_Gain(CH_coil) = 1 / app.testConfig.turns(2);
                elseif CH_coil >= app.testConfig.adec_idx_start(3) && CH_coil <= app.testConfig.adec_idx_end(3) &&...
                        app.testConfig.adec_idx_start(3) < app.testConfig.adec_idx_end(3)
                    coilTurn_Gain(CH_coil) = 1 / app.testConfig.turns(3);
                end
            end
            analog_Gain = 0.175 * ones(size(window_Gain)); % as long as analog_Gain is 0.175
            app.log_Max_sect = max(sectional_ADEC) ./ (window_Gain .* coilTurn_Gain .* analog_Gain);
            app.log_Min_sect = min(sectional_ADEC) ./ (window_Gain .* coilTurn_Gain .* analog_Gain);
            app.log_Max_sect_perm = app.log_Max_sect;
            app.log_Min_sect_perm = app.log_Min_sect;
            app.log_med_sect = median(sectional_ADEC) ./ (window_Gain .* coilTurn_Gain .* analog_Gain);
            app.degain = ones(1, size(coilTurn_Gain, 2)) .* window_Gain .* coilTurn_Gain .* analog_Gain;
            
            % if sectional line box checked, show sectional lines
            if app.SectionalDataCheckBox.Value == 1
                
                % plot three lines
                cla(app.UIAxes);
                semilogy(app.UIAxes, app.FM_x, app.log_med_sect, 'Color', 'k', 'LineStyle', '-.', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                semilogy(app.UIAxes, app.FM_x, app.log_Max_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                semilogy(app.UIAxes, app.FM_x, app.log_Min_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                leg1 = legend(app.UIAxes,{'Sect-Nom','Sect-Max','Sect-Min'},'Location','northeast','Orientation','horizontal');
            elseif app.SectionalDataCheckBox.Value == 0
                cla(app.UIAxes);
            end
            
            
        end

        % Button pushed function: CombineButton
        function CombineButtonPushed(app, event)
            % connect S and M for all curves
            if app.testConfig.sensor(2) == 1
                if ~isempty(app.log_Med_lin)
                    log_Med_lin = log(app.log_Med_lin);
                    log_Med_lin(app.testConfig.adec_idx_start(2)) = log_Med_lin(app.testConfig.adec_idx_start(2) - 1) - ...
                        (log_Med_lin(app.testConfig.adec_idx_start(2) - 2) - log_Med_lin(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        log_Med_lin(i) = log(app.log_Med_lin(i)) - (log(app.log_Med_lin(app.testConfig.adec_idx_start(2))) -...
                            log_Med_lin(app.testConfig.adec_idx_start(2)));
                    end
                end
                if ~isempty(app.log_Max)
                    log_Max = log(app.log_Max);
                    log_Max(app.testConfig.adec_idx_start(2)) = log_Max(app.testConfig.adec_idx_start(2) - 1) - ...
                        (log_Max(app.testConfig.adec_idx_start(2) - 2) - log_Max(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        log_Max(i) = log(app.log_Max(i)) - (log(app.log_Max(app.testConfig.adec_idx_start(2))) -...
                            log_Max(app.testConfig.adec_idx_start(2)));
                    end
                end
                if ~isempty(app.log_Min)
                    log_Min = log(app.log_Min);
                    log_Min(app.testConfig.adec_idx_start(2)) = log_Min(app.testConfig.adec_idx_start(2) - 1) - ...
                        (log_Min(app.testConfig.adec_idx_start(2) - 2) - log_Min(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        log_Min(i) = log(app.log_Min(i)) - (log(app.log_Min(app.testConfig.adec_idx_start(2))) -...
                            log_Min(app.testConfig.adec_idx_start(2)));
                    end
                end
                if app.SectionalDataCheckBox.Value == 1
                    if ~isempty(app.log_med_sect)
                        log_Med_sect = log(app.log_med_sect);
                        log_Med_sect(app.testConfig.adec_idx_start(2)) = log_Med_sect(app.testConfig.adec_idx_start(2) - 1) - ...
                            (log_Med_sect(app.testConfig.adec_idx_start(2) - 2) - log_Med_sect(app.testConfig.adec_idx_start(2) - 1));
                        for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                            log_Med_sect(i) = log(app.log_med_sect(i)) - (log(app.log_med_sect(app.testConfig.adec_idx_start(2))) -...
                                log_Med_sect(app.testConfig.adec_idx_start(2)));
                        end
                    end
                    if ~isempty(app.log_Max_sect)
                        log_Max_sect = log(app.log_Max_sect);
                        log_Max_sect(app.testConfig.adec_idx_start(2)) = log_Max_sect(app.testConfig.adec_idx_start(2) - 1) - ...
                            (log_Max_sect(app.testConfig.adec_idx_start(2) - 2) - log_Max_sect(app.testConfig.adec_idx_start(2) - 1));
                        for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                            log_Max_sect(i) = log(app.log_Max_sect(i)) - (log(app.log_Max_sect(app.testConfig.adec_idx_start(2))) -...
                                log_Max_sect(app.testConfig.adec_idx_start(2)));
                        end
                    end
                    if ~isempty(app.log_Min_sect)
                        log_Min_sect = log(app.log_Min_sect);
                        log_Min_sect(app.testConfig.adec_idx_start(2)) = log_Min_sect(app.testConfig.adec_idx_start(2) - 1) - ...
                            (log_Min_sect(app.testConfig.adec_idx_start(2) - 2) - log_Min_sect(app.testConfig.adec_idx_start(2) - 1));
                        for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                            log_Min_sect(i) = log(app.log_Min_sect(i)) - (log(app.log_Min_sect(app.testConfig.adec_idx_start(2))) -...
                                log_Min_sect(app.testConfig.adec_idx_start(2)));
                        end
                    end
                end
            end
            
            % connect M and L for all curves
            if app.testConfig.sensor(3) == 1
                if ~isempty(app.log_Med_lin)
                    log_Med_lin(app.testConfig.adec_idx_start(3)) = log_Med_lin(app.testConfig.adec_idx_start(3) - 1) - ...
                        (log_Med_lin(app.testConfig.adec_idx_start(3) - 2) - log_Med_lin(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        log_Med_lin(i) = log(app.log_Med_lin(i)) - (log(app.log_Med_lin(app.testConfig.adec_idx_start(3))) -...
                            log_Med_lin(app.testConfig.adec_idx_start(3)));
                    end
                end
                if ~isempty(app.log_Max)
                    log_Max(app.testConfig.adec_idx_start(3)) = log_Max(app.testConfig.adec_idx_start(3) - 1) - ...
                        (log_Max(app.testConfig.adec_idx_start(3) - 2) - log_Max(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        log_Max(i) = log(app.log_Max(i)) - (log(app.log_Max(app.testConfig.adec_idx_start(3))) -...
                            log_Max(app.testConfig.adec_idx_start(3)));
                    end
                end
                if ~isempty(app.log_Min)
                    log_Min(app.testConfig.adec_idx_start(3)) = log_Min(app.testConfig.adec_idx_start(3) - 1) - ...
                        (log_Min(app.testConfig.adec_idx_start(3) - 2) - log_Min(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        log_Min(i) = log(app.log_Min(i)) - (log(app.log_Min(app.testConfig.adec_idx_start(3))) -...
                            log_Min(app.testConfig.adec_idx_start(3)));
                    end
                end
                if app.SectionalDataCheckBox.Value == 1
                    if ~isempty(app.log_med_sect)
                        log_Med_sect(app.testConfig.adec_idx_start(3)) = log_Med_sect(app.testConfig.adec_idx_start(3) - 1) - ...
                            (log_Med_sect(app.testConfig.adec_idx_start(3) - 2) - log_Med_sect(app.testConfig.adec_idx_start(3) - 1));
                        for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                            log_Med_sect(i) = log(app.log_med_sect(i)) - (log(app.log_med_sect(app.testConfig.adec_idx_start(3))) -...
                                log_Med_sect(app.testConfig.adec_idx_start(3)));
                        end
                    end
                    if ~isempty(app.log_Max_sect)
                        log_Max_sect(app.testConfig.adec_idx_start(3)) = log_Max_sect(app.testConfig.adec_idx_start(3) - 1) - ...
                            (log_Max_sect(app.testConfig.adec_idx_start(3) - 2) - log_Max_sect(app.testConfig.adec_idx_start(3) - 1));
                        for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                            log_Max_sect(i) = log(app.log_Max_sect(i)) - (log(app.log_Max_sect(app.testConfig.adec_idx_start(3))) -...
                                log_Max_sect(app.testConfig.adec_idx_start(3)));
                        end
                    end
                    if ~isempty(app.log_Min_sect)
                        log_Min_sect(app.testConfig.adec_idx_start(3)) = log_Min_sect(app.testConfig.adec_idx_start(3) - 1) - ...
                            (log_Min_sect(app.testConfig.adec_idx_start(3) - 2) - log_Min_sect(app.testConfig.adec_idx_start(3) - 1));
                        for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                            log_Min_sect(i) = log(app.log_Min_sect(i)) - (log(app.log_Min_sect(app.testConfig.adec_idx_start(3))) -...
                                log_Min_sect(app.testConfig.adec_idx_start(3)));
                        end
                    end
                end
            end
            
            % reverse natural log
            log_Med_lin = exp(log_Med_lin);
            log_Max = exp(log_Max);
            log_Min = exp(log_Min);
            if app.SectionalDataCheckBox.Value == 1
                log_Med_sect = exp(log_Med_sect);
                log_Max_sect = exp(log_Max_sect);
                log_Min_sect = exp(log_Min_sect);
            end
            
            %%% plot FM_nom vs FM_x
            cla(app.UIAxes);
            % plot data
            %semilogy(app.UIAxes, app.FM_x, log_Med_lin, 'g', 'LineWidth', 0.7);
            %hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, log_Max, 'r', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, log_Min, 'b', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            
            % if sectional line box checked, show sectional lines
            if app.SectionalDataCheckBox.Value == 1
                % plot three lines
                semilogy(app.UIAxes, app.FM_x, log_Med_sect, 'Color', 'k', 'LineStyle', '-.', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                semilogy(app.UIAxes, app.FM_x, log_Max_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                semilogy(app.UIAxes, app.FM_x, log_Min_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                leg1 = legend(app.UIAxes,{'Max','Min','Sect-Nom','Sect-Max','Sect-Min'},'Location','northeast','Orientation','horizontal');
            elseif app.SectionalDataCheckBox.Value == 0
                leg1 = legend(app.UIAxes,{'Max','Min'},'Location','northeast','Orientation','horizontal');
            end
            
            
        end

        % Button pushed function: ApplyAngleButton
        function ApplyAngleButtonPushed(app, event)
            % this function is to enlarge the angle between max/min and
            % horizontal line draw from the beginning CH of the sensor
            % to show the clients a larger feature
            
            % expand S sensor angle
            if app.testConfig.sensor(1) == 1
                if (~isempty(app.log_Max)) && (~isempty(app.log_Min))
                    % expand angle in log domain
                    log_MaxS = log(app.log_Max_perm(app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1)));
                    log_MinS = log(app.log_Min_perm(app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1)));
                    % create horiz array as a horizontal line from the beginning CH
                    horiz_MaxS = ones(size(log_MaxS)) * log_MaxS(1);
                    horiz_MinS = ones(size(log_MinS)) * log_MinS(1);
                end
                % calculate gap between log(med) and log(max/min), multiply
                % the index and add back to nom
                adj_MaxS = app.EditField_36.Value * (log_MaxS - horiz_MaxS) + horiz_MaxS;
                adj_MinS = app.EditField_39.Value * (log_MinS - horiz_MinS) + horiz_MinS;
            else
                adj_MaxS = double.empty;
                adj_MinS = double.empty;
            end
            
            % expand M sensor angle
            if app.testConfig.sensor(2) == 1
                if (~isempty(app.log_Max)) && (~isempty(app.log_Min))
                    % expand angle in log domain
                    log_MaxM = log(app.log_Max_perm(app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2)));
                    log_MinM = log(app.log_Min_perm(app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2)));
                    horiz_MaxM = ones(size(log_MaxM)) * log_MaxM(1);
                    horiz_MinM = ones(size(log_MinM)) * log_MinM(1);
                end
                adj_MaxM = app.EditField_37.Value * (log_MaxM - horiz_MaxM) + horiz_MaxM;
                adj_MinM = app.EditField_40.Value * (log_MinM - horiz_MinM) + horiz_MinM;
            else
                adj_MaxM = double.empty;
                adj_MinM = double.empty;
            end
            
            % expand L sensor angle
            if app.testConfig.sensor(3) == 1
                if (~isempty(app.log_Max)) && (~isempty(app.log_Min))
                    % expand angle in log domain
                    log_MaxL = log(app.log_Max_perm(app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3)));
                    log_MinL = log(app.log_Min_perm(app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3)));
                    horiz_MaxL = ones(size(log_MaxL)) * log_MaxL(1);
                    horiz_MinL = ones(size(log_MinL)) * log_MinL(1);
                end
                adj_MaxL = app.EditField_38.Value * (log_MaxL - horiz_MaxL) + horiz_MaxL;
                adj_MinL = app.EditField_41.Value * (log_MinL - horiz_MinL) + horiz_MinL;
            else
                adj_MaxL = double.empty;
                adj_MinL = double.empty;
            end
            
            % smooth FM_L part in half and half
            adj_MinL(1 : (length(adj_MinL) / 2)) = transpose(smooth(adj_MinL(1 : (length(adj_MinL) / 2)), 3));
            adj_MinL((length(adj_MinL) / 2 + 1) : length(adj_MinL)) = ...
                transpose(smooth(adj_MinL((length(adj_MinL) / 2 + 1) : length(adj_MinL)), 3));
            
            % reverse natural log
            adj_Max = exp([adj_MaxS, adj_MaxM, adj_MaxL]);
            adj_Min = exp([adj_MinS, adj_MinM, adj_MinL]);
            
            % store magnified max and min into global varibles
            app.log_Max = adj_Max;
            app.log_Min = adj_Min;
            
            %% do same steps to sectional lines
            % expand S sensor angle
            if app.testConfig.sensor(1) == 1
                if (~isempty(app.log_Max_sect)) && (~isempty(app.log_Min_sect))
                    % expand angle in log domain
                    log_MaxS = log(app.log_Max_sect_perm(app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1)));
                    log_MinS = log(app.log_Min_sect_perm(app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1)));
                    % create horiz array as a horizontal line from the beginning CH
                    horiz_MaxS = ones(size(log_MaxS)) * log_MaxS(1);
                    horiz_MinS = ones(size(log_MinS)) * log_MinS(1);
                end
                % calculate gap between log(med) and log(max/min), multiply
                % the index and add back to nom
                adj_MaxS = app.EditField_36.Value * (log_MaxS - horiz_MaxS) + horiz_MaxS;
                adj_MinS = app.EditField_39.Value * (log_MinS - horiz_MinS) + horiz_MinS;
            else
                adj_MaxS = double.empty;
                adj_MinS = double.empty;
            end
            
            % expand M sensor angle
            if app.testConfig.sensor(2) == 1
                if (~isempty(app.log_Max_sect)) && (~isempty(app.log_Min_sect))
                    % expand angle in log domain
                    log_MaxM = log(app.log_Max_sect_perm(app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2)));
                    log_MinM = log(app.log_Min_sect_perm(app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2)));
                    horiz_MaxM = ones(size(log_MaxM)) * log_MaxM(1);
                    horiz_MinM = ones(size(log_MinM)) * log_MinM(1);
                end
                adj_MaxM = app.EditField_37.Value * (log_MaxM - horiz_MaxM) + horiz_MaxM;
                adj_MinM = app.EditField_40.Value * (log_MinM - horiz_MinM) + horiz_MinM;
            else
                adj_MaxM = double.empty;
                adj_MinM = double.empty;
            end
            
            % expand L sensor angle
            if app.testConfig.sensor(3) == 1
                if (~isempty(app.log_Max_sect)) && (~isempty(app.log_Min_sect))
                    % expand angle in log domain
                    log_MaxL = log(app.log_Max_sect_perm(app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3)));
                    log_MinL = log(app.log_Min_sect_perm(app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3)));
                    horiz_MaxL = ones(size(log_MaxL)) * log_MaxL(1);
                    horiz_MinL = ones(size(log_MinL)) * log_MinL(1);
                end
                adj_MaxL = app.EditField_38.Value * (log_MaxL - horiz_MaxL) + horiz_MaxL;
                adj_MinL = app.EditField_41.Value * (log_MinL - horiz_MinL) + horiz_MinL;
            else
                adj_MaxL = double.empty;
                adj_MinL = double.empty;
            end
            
            % smooth FM_L part in half and half
            adj_MinL(1 : (length(adj_MinL) / 2)) = transpose(smooth(adj_MinL(1 : (length(adj_MinL) / 2)), 3));
            adj_MinL((length(adj_MinL) / 2 + 1) : length(adj_MinL)) = ...
                transpose(smooth(adj_MinL((length(adj_MinL) / 2 + 1) : length(adj_MinL)), 3));
            
            % reverse natural log
            adj_Max = exp([adj_MaxS, adj_MaxM, adj_MaxL]);
            adj_Min = exp([adj_MinS, adj_MinM, adj_MinL]);
            
            % store magnified max and min into global varibles
            app.log_Max_sect = adj_Max;
            app.log_Min_sect = adj_Min;
            
            % plot three lines
            cla(app.UIAxes);
            %semilogy(app.UIAxes, app.FM_x, app.log_Med_lin, 'g', 'LineWidth', 0.7);
            %hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, app.log_Max, 'r', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            semilogy(app.UIAxes, app.FM_x, app.log_Min, 'b', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            % if sectional line box checked, show sectional lines
            if app.SectionalDataCheckBox.Value == 1
                % plot three lines
                semilogy(app.UIAxes, app.FM_x, app.log_med_sect, 'Color', 'k', 'LineStyle', '-.', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                semilogy(app.UIAxes, app.FM_x, app.log_Max_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                semilogy(app.UIAxes, app.FM_x, app.log_Min_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                leg1 = legend(app.UIAxes,{'Max','Min','Sect-Nom','Sect-Max','Sect-Min'},'Location','northeast','Orientation','horizontal');
            elseif app.SectionalDataCheckBox.Value == 0
                leg1 = legend(app.UIAxes,{'Max','Min'},'Location','northeast','Orientation','horizontal');
            end
            
            
        end

        % Button pushed function: RescaleButton
        function RescaleButtonPushed(app, event)
            %rescale voltage axis of plots according to input ranges
            ylim(app.UIAxes, [app.VoltageEditField_2.Value, app.toEditField_3.Value]);
            
            %rescale CH of plots according to input ranges
            xlim(app.UIAxes, [app.CHEditField.Value, app.toEditField_2.Value]);
        end

        % Value changed function: SectionalDataCheckBox
        function SectionalDataCheckBoxValueChanged(app, event)
            value = app.SectionalDataCheckBox.Value;
            
            % find all lines with width == 0.5 and ready for delete
            h1 = findobj (app.UIAxes);
            h2 = findobj (app.UIAxes, 'LineWidth', 0.5);
            
            % plot three lines
            %cla(app.UIAxes);
            switch value
                case 1
                    if ~isempty(app.log_Med_lin)
                        for n = 2 : size(h2, 1)
                            set(h2(n), 'visible', 'on');                            
                        end
                    end
                case 0
                    if ~isempty(app.log_Med_lin)
                        for n = 2 : size(h2, 1)
                            set(h2(n), 'visible', 'off');                            
                        end
                    end                    
                    if size(h2, 1) < 2
                        msgbox("No sectional lines plotted");
                    end
            end
            
                        
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            fid = fopen('Saved Data.txt', 'w+');
            if fid < 0
                fprintf('error opening file\n');
            end


            [filename, pathname] = uiputfile({'*.txt'}, 'Export thickness data');
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg("No file selected", 'Error');
            end
            
            app.exportDataPath = strcat(pathname, filename);
            app.ExportThicknessPathEditField.Value = app.exportDataPath;
            app.lasDstFilename = filename;
            
            % replace old data with new data
            inject_data = app.lasData;
            i_start = 1;
            i_stop = size(app.lasData, 1);
            
            app.deltaD(:, 1) = app.CorrThickCorrosionPerc_1(:);
            app.deltaD(:, 2) = app.CorrThickCorrosionPerc_2(:);
            app.deltaD(:, 3) = app.CorrThickCorrosionPerc_3(:);
            app.deltaD(:, 4) = app.CorrThickCorrosionPerc_4(:);
            app.deltaD(:, 5) = app.CorrThickCorrosionPerc_5(:);
            
            inject_data = InjectThickness(inject_data, app.lasHeader, app.deltaD, i_start, ...
                i_stop);
            
            msgbox("Data writing started. It may take several minutes.");
            
            try
                WriteBackLAS(app.lasSrcFilename, app.lasDstFilename, inject_data, app.lasDetails);
            catch
                errordlg("Failed to write target LAS file", 'Error');
            end
            
            msgbox("Data writing complete!");
            
            function [ ] = WriteBackLAS(src, dst, data, detail)
                %WriteBackLAS Write back a new las from an old one with different data.
                % Input:
                %   src         source las file
                %   dst         target las file
                %   data        data to be written
                %   detail  	las format information
                %
                % Output:
                %   None
                
                % open files
                src_file = fopen(src);
                dst_file = fopen(dst, 'w+');
                
                % copy header
                for i = 1:(detail.i_datastart - 1)
                    line = fgets(src_file);
                    fwrite(dst_file, line);
                end
                
                % write data
                n_depth = size(data, 1);
                waitbar_f = waitbar(0, 'Please wait...');
                for i = 1:n_depth
                    blk = Vector2Block(data(i, :), detail.n_col);
                    for j = 1:length(blk)
                        fwrite(dst_file, blk{j});
                    end
                    if i == (ceil(n_depth/4))
                        waitbar(i/n_depth, waitbar_f, 'Writing data...');
                    elseif i == (ceil(n_depth/2))
                        waitbar(i/n_depth, waitbar_f, 'Writing data...');
                    elseif i == (ceil(n_depth*3/4))
                        waitbar(i/n_depth, waitbar_f, 'Writing data...');
                    elseif i == (ceil(n_depth))
                        waitbar(i/n_depth, waitbar_f, 'Finish writing!');
                    end
                end
                
                % close files
                fclose(src_file);
                fclose(dst_file);
                
            end
            
            function [ blk ] = Vector2Block( vec, n_col )
                %Vector2Block Convert vector to block given the format.
                % Input:
                %   vec         input data, 1 x n vector
                %   n_col       column number of each row, m x 1 vector
                %
                % Output:
                %   blk         output data, m x 1 string cell
                
                n_row = length(n_col);
                blk = cell(n_row, 1);
                
                % transpose vector
                index = 0;
                for i = 1:n_row
                    % indent
                    if i == 1
                        % no indent on the first row
                        str = '';
                    else
                        % indent 4 for later rows
                        str = '    ';
                    end
                    
                    for j = 1:n_col(i)
                        index = index + 1;
                        if index == 1
                            formatSpec = '%s%.6f    ';
                        else
                            formatSpec = '%s%.3f  ';
                        end
                        str = sprintf(formatSpec, str, vec(index));
                    end
                    
                    % output
                    blk{i} = sprintf('%s\n', str);
                end
                
            end
            
            % --- Replace old las thickness data with newly calculated results.
            function [ new_data ] = InjectThickness(old_data, header, ...
                    deltaD, i_start, i_stop)
                % old_data      data from original LAS
                % header        original LAS header
                % deltaD        thickness estimation results
                % i_start       start index
                % i_stop        stop index
                % collar_table  collar table
                
                % no thickness results
                if isempty(deltaD)
                    throw(MException('MyComponent:LasInjectMessage', ...
                        'Cannot find thickness results to inject. '));
                end
                
                % check source las
                i_NomThick = find(~cellfun('isempty', ...
                    regexp(header, '^(\w*_Nom_Thick)$', 'match')));
                i_Thick = find(~cellfun('isempty', ...
                    regexp(header, '^(Thick_)', 'match')));
                if isempty(i_NomThick) || isempty(i_Thick)
                    throw(MException('MyComponent:LasInjectMessage', ...
                        'The source las file does not contain thickness information. '));
                end
                
                % get thickness indices in header
                i_ThickCorrosion = find(~cellfun('isempty', ...
                    regexp(header, '^(ThickCorrosion_)', 'match')));
                i_ThickCorrosionPercentage = find(~cellfun('isempty', ...
                    regexp(header, '^(ThickCorrosionPercentage_)', 'match')));
                i_Perc_Thick = find(~cellfun('isempty', ...
                    regexp(header, '^(Perc_Thick_)', 'match')));
                i_Perc_Wall_Loss = find(~cellfun('isempty', ...
                    regexp(header, '^(Perc_Wall_Loss_)', 'match')));
                i_Pipe_Thickness = find(~cellfun('isempty', ...
                    regexp(header, '(_Thickness)$', 'match')));
                i_Pipe_Perc_Thick = find(~cellfun('isempty', ...
                    regexp(header, '(_Perc_Thick)$', 'match')));
                i_Pipe_Perc_Wall_Loss = find(~cellfun('isempty', ...
                    regexp(header, '(_Perc_Wall_Loss)$', 'match')));
                
                % inject data
                n_pipe = sum(~cellfun('isempty', regexp(header, '^(\w*_Nom_Thick)$', 'match')));
                new_data = old_data;
                for i = i_start:i_stop
                    for j = 1:n_pipe
                        % get nominal thickness
                        nom_thick = old_data(i, i_NomThick(j));
                        
                        % calculate actual thickness from deltaD
                        thick = nom_thick * (1 + deltaD(i, j)/100);
                        new_data(i, i_Thick(j)) = thick;
                        
                        % fill in the conversion data
                        new_data(i, i_ThickCorrosion(j)) = ...
                            nom_thick - thick;
                        new_data(i, i_ThickCorrosionPercentage(j)) = ...
                            (1 - thick / nom_thick) * 100;
                        new_data(i, i_Perc_Thick(j)) = ...
                            (thick / nom_thick) * 100;
                        new_data(i, i_Perc_Wall_Loss(j)) = ...
                            (1 - thick / nom_thick) * 100;
                        new_data(i, i_Pipe_Thickness(j)) = ...
                            thick;
                        new_data(i, i_Pipe_Perc_Thick(j)) = ...
                            (thick / nom_thick) * 100;
                        new_data(i, i_Pipe_Perc_Wall_Loss(j)) = ...
                            (1 - thick / nom_thick) * 100;
                    end
                end
            end
            
            
        end

        % Value changed function: EditField_23
        function EditField_23ValueChanged(app, event)
            value = app.EditField_23.Value;
            
        end

        % Button pushed function: LoadSavedDataButton
        function LoadSavedDataButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ePDT Model vs Tool Verification')
            xlabel(app.UIAxes, 'ePDT Channels')
            ylabel(app.UIAxes, 'Voltage, mV')
            app.WellschematicLabel = uilabel(app.UIFigure);
            app.WellschematicLabel.Text = 'Well schematic';
            app.PipeThicknessChangePanel = uipanel(app.UIFigure);
            app.PipeThicknessChangePanel.Title = 'Pipe Thickness Change';
            app.ToolDataPanel = uipanel(app.PipeThicknessChangePanel);
            app.ToolDataPanel.Title = 'Tool Data';
            app.LoadToolDataButton = uibutton(app.ToolDataPanel, 'push');
            app.LoadToolDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadToolDataButtonPushed, true);
            app.LoadToolDataButton.Text = 'Load Tool Data';
            app.EditField_17 = uieditfield(app.ToolDataPanel, 'numeric');
            app.EditField_18 = uieditfield(app.ToolDataPanel, 'numeric');
            app.mLabel = uilabel(app.ToolDataPanel);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.ToolDataPanel);
            app.mLabel_2.Text = 'm';
            app.DataDEPTHforCalcLabel = uilabel(app.ToolDataPanel);
            app.DataDEPTHforCalcLabel.Text = 'Data @DEPTH for Calc:';
            app.DepthwindowLabel = uilabel(app.ToolDataPanel);
            app.DepthwindowLabel.Text = 'Depth window:';
            app.EditField_19 = uieditfield(app.ToolDataPanel, 'text');
            app.DisplayWindowButton = uibutton(app.ToolDataPanel, 'push');
            app.DisplayWindowButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayWindowButtonPushed, true);
            app.DisplayWindowButton.Text = 'Display Window';
            app.LoadINIFileButton = uibutton(app.ToolDataPanel, 'push');
            app.LoadINIFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadINIFileButtonPushed, true);
            app.LoadINIFileButton.Text = 'Load INI File';
            app.EditField_20 = uieditfield(app.ToolDataPanel, 'text');
            app.LoadWellSchematicButton = uibutton(app.ToolDataPanel, 'push');
            app.LoadWellSchematicButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWellSchematicButtonPushed, true);
            app.LoadWellSchematicButton.Text = 'Load Well Schematic';
            app.EditField_23 = uieditfield(app.ToolDataPanel, 'text');
            app.EditField_23.ValueChangedFcn = createCallbackFcn(app, @EditField_23ValueChanged, true);
            app.CombineButton = uibutton(app.ToolDataPanel, 'push');
            app.CombineButton.ButtonPushedFcn = createCallbackFcn(app, @CombineButtonPushed, true);
            app.CombineButton.Text = 'Combine';
            app.SectionsDropDownLabel = uilabel(app.ToolDataPanel);
            app.SectionsDropDownLabel.Text = 'Sections:';
            app.SectionsDropDown = uidropdown(app.ToolDataPanel);
            app.SectionsDropDown.Items = {'Piple Sections'};
            app.SectionsDropDown.ValueChangedFcn = createCallbackFcn(app, @SectionsDropDownValueChanged, true);
            app.SectionsDropDown.Value = 'Piple Sections';
            app.MaxIndexSMLLabel = uilabel(app.ToolDataPanel);
            app.MaxIndexSMLLabel.Text = 'Max Index (S, M, L):';
            app.EditField_36 = uieditfield(app.ToolDataPanel, 'numeric');
            app.EditField_36.Value = 1;
            app.EditField_37 = uieditfield(app.ToolDataPanel, 'numeric');
            app.EditField_37.Value = 1;
            app.EditField_38 = uieditfield(app.ToolDataPanel, 'numeric');
            app.EditField_38.Value = 1;
            app.ApplyAngleButton = uibutton(app.ToolDataPanel, 'push');
            app.ApplyAngleButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyAngleButtonPushed, true);
            app.ApplyAngleButton.Text = 'Apply Angle';
            app.MinIndexSMLLabel = uilabel(app.ToolDataPanel);
            app.MinIndexSMLLabel.Text = 'Min Index (S, M, L):';
            app.EditField_39 = uieditfield(app.ToolDataPanel, 'numeric');
            app.EditField_39.Value = 1;
            app.EditField_40 = uieditfield(app.ToolDataPanel, 'numeric');
            app.EditField_40.Value = 1;
            app.EditField_41 = uieditfield(app.ToolDataPanel, 'numeric');
            app.EditField_41.Value = 1;
            app.LoadSavedDataButton = uibutton(app.ToolDataPanel, 'push');
            app.LoadSavedDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSavedDataButtonPushed, true);
            app.LoadSavedDataButton.Text = 'Load Saved Data';
            app.EditField_48 = uieditfield(app.ToolDataPanel, 'text');
            app.SectionalDataCheckBox = uicheckbox(app.ToolDataPanel);
            app.SectionalDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @SectionalDataCheckBoxValueChanged, true);
            app.SectionalDataCheckBox.Text = 'Sectional Data';
            app.SectionalDataCheckBox.Value = true;
            app.ExportDataButton = uibutton(app.ToolDataPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.ModelPanel = uipanel(app.PipeThicknessChangePanel);
            app.ModelPanel.Title = 'Model';
            app.SSensorLabel = uilabel(app.ModelPanel);
            app.SSensorLabel.Text = 'S Sensor:';
            app.MSensorLabel = uilabel(app.ModelPanel);
            app.MSensorLabel.Text = 'M Sensor:';
            app.EditField_5 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_6 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_7 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_8 = uieditfield(app.ModelPanel, 'numeric');
            app.LSensorLabel = uilabel(app.ModelPanel);
            app.LSensorLabel.Text = 'L Sensor:';
            app.EditField_9 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_10 = uieditfield(app.ModelPanel, 'numeric');
            app.BLabel = uilabel(app.ModelPanel);
            app.BLabel.Text = 'B:';
            app.ALabel = uilabel(app.ModelPanel);
            app.ALabel.Text = 'A:';
            app.EditField_11 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_12 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_13 = uieditfield(app.ModelPanel, 'numeric');
            app.CLabel = uilabel(app.ModelPanel);
            app.CLabel.Text = 'C:';
            app.EditField_14 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_15 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_16 = uieditfield(app.ModelPanel, 'numeric');
            app.WallLossLabel = uilabel(app.ModelPanel);
            app.WallLossLabel.Text = 'Wall Loss:';
            app.Label_3 = uilabel(app.ModelPanel);
            app.Label_3.Text = '  %';
            app.Label_4 = uilabel(app.ModelPanel);
            app.Label_4.Text = '  %';
            app.Label_5 = uilabel(app.ModelPanel);
            app.Label_5.Text = '  %';
            app.Pipe1Label = uilabel(app.ModelPanel);
            app.Pipe1Label.Text = 'Pipe1:';
            app.Pipe2Label = uilabel(app.ModelPanel);
            app.Pipe2Label.Text = 'Pipe2:';
            app.Pipe3Label = uilabel(app.ModelPanel);
            app.Pipe3Label.Text = 'Pipe3:';
            app.NominalLabel = uilabel(app.ModelPanel);
            app.NominalLabel.Text = 'Nominal:';
            app.TubingLabel = uilabel(app.ModelPanel);
            app.TubingLabel.Text = 'Tubing:';
            app.CasingLabel = uilabel(app.ModelPanel);
            app.CasingLabel.Text = 'Casing:';
            app.MuLabel = uilabel(app.ModelPanel);
            app.MuLabel.Text = 'Mu:';
            app.SigmaLabel = uilabel(app.ModelPanel);
            app.SigmaLabel.Text = 'Sigma:';
            app.EditField = uieditfield(app.ModelPanel, 'numeric');
            app.EditField.Value = 7;
            app.EditField_2 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_2.Value = 7;
            app.EditField_3 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_3.Value = 110;
            app.EditField_4 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_4.Value = 110;
            app.FindParametersButton = uibutton(app.ModelPanel, 'push');
            app.FindParametersButton.ButtonPushedFcn = createCallbackFcn(app, @FindParametersButtonPushed, true);
            app.FindParametersButton.Text = 'Find Parameters';
            app.CalcandPlotButton = uibutton(app.ModelPanel, 'push');
            app.CalcandPlotButton.ButtonPushedFcn = createCallbackFcn(app, @CalcandPlotButtonPushed, true);
            app.CalcandPlotButton.Text = 'Calc and Plot';
            app.ApplyParametersButton = uibutton(app.ModelPanel, 'push');
            app.ApplyParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyParametersButtonPushed, true);
            app.ApplyParametersButton.Text = 'Apply Parameters';
            app.EditField_24 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_25 = uieditfield(app.ModelPanel, 'numeric');
            app.Label_8 = uilabel(app.ModelPanel);
            app.Label_8.Text = '  %';
            app.Label_9 = uilabel(app.ModelPanel);
            app.Label_9.Text = '  %';
            app.Pipe4Label = uilabel(app.ModelPanel);
            app.Pipe4Label.Text = 'Pipe4:';
            app.Pipe5Label = uilabel(app.ModelPanel);
            app.Pipe5Label.Text = 'Pipe5:';
            app.SLabel = uilabel(app.ModelPanel);
            app.SLabel.Text = 'S:';
            app.MLabel = uilabel(app.ModelPanel);
            app.MLabel.Text = 'M:';
            app.EditField_26 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_26.ValueDisplayFormat = '%11.3g';
            app.EditField_27 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_27.ValueDisplayFormat = '%11.3g';
            app.EditField_28 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_29 = uieditfield(app.ModelPanel, 'numeric');
            app.LLabel = uilabel(app.ModelPanel);
            app.LLabel.Text = 'L:';
            app.EditField_30 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_30.ValueDisplayFormat = '%11.3g';
            app.EditField_31 = uieditfield(app.ModelPanel, 'numeric');
            app.Label_11 = uilabel(app.ModelPanel);
            app.Label_11.Text = 'β:';
            app.Label_10 = uilabel(app.ModelPanel);
            app.Label_10.Text = 'α:';
            app.CalculateParametersButton = uibutton(app.ModelPanel, 'push');
            app.CalculateParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateParametersButtonPushed, true);
            app.CalculateParametersButton.Text = 'Calculate Parameters';
            app.CalcandPlotButton_2 = uibutton(app.ModelPanel, 'push');
            app.CalcandPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcandPlotButton_2Pushed, true);
            app.CalcandPlotButton_2.Text = 'Calc and Plot';
            app.ApplyParametersButton_2 = uibutton(app.ModelPanel, 'push');
            app.ApplyParametersButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyParametersButton_2Pushed, true);
            app.ApplyParametersButton_2.Text = 'Apply Parameters';
            app.EditField_32 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_33 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_34 = uieditfield(app.ModelPanel, 'numeric');
            app.DLabel = uilabel(app.ModelPanel);
            app.DLabel.Text = 'D:';
            app.CombineButton_2 = uibutton(app.ModelPanel, 'push');
            app.CombineButton_2.ButtonPushedFcn = createCallbackFcn(app, @CombineButton_2Pushed, true);
            app.CombineButton_2.Enable = 'off';
            app.CombineButton_2.Text = 'Combine';
            app.CombineButton_3 = uibutton(app.ModelPanel, 'push');
            app.CombineButton_3.ButtonPushedFcn = createCallbackFcn(app, @CombineButton_3Pushed, true);
            app.CombineButton_3.Enable = 'off';
            app.CombineButton_3.Text = 'Combine';
            app.MSmLabel = uilabel(app.ModelPanel);
            app.MSmLabel.Text = 'MS/m';
            app.MSmLabel_2 = uilabel(app.ModelPanel);
            app.MSmLabel_2.Text = 'MS/m';
            app.EditField_35 = uieditfield(app.ModelPanel, 'numeric');
            app.Label_12 = uilabel(app.ModelPanel);
            app.Label_12.Text = '  %';
            app.Pipe6Label = uilabel(app.ModelPanel);
            app.Pipe6Label.Text = 'Pipe6:';
            app.EditField_42 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_43 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_44 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_45 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_46 = uieditfield(app.ModelPanel, 'numeric');
            app.EditField_47 = uieditfield(app.ModelPanel, 'numeric');
            app.CHLabel = uilabel(app.ModelPanel);
            app.CHLabel.Text = 'CH:';
            app.WLLabel = uilabel(app.ModelPanel);
            app.WLLabel.Text = 'WL:';
            app.ePDTModelvsToolVerificationLabel = uilabel(app.UIFigure);
            app.ePDTModelvsToolVerificationLabel.Text = 'ePDT - Model vs Tool Verification';
            app.RescaleButton = uibutton(app.UIFigure, 'push');
            app.RescaleButton.ButtonPushedFcn = createCallbackFcn(app, @RescaleButtonPushed, true);
            app.RescaleButton.Text = 'Rescale';
            app.VoltageEditField_2Label = uilabel(app.UIFigure);
            app.VoltageEditField_2Label.Text = 'Voltage';
            app.VoltageEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.VoltageEditField_2.Value = 0.1;
            app.CHEditFieldLabel = uilabel(app.UIFigure);
            app.CHEditFieldLabel.Text = 'CH';
            app.CHEditField = uieditfield(app.UIFigure, 'numeric');
            app.toEditField_2Label = uilabel(app.UIFigure);
            app.toEditField_2Label.Text = 'to';
            app.toEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.toEditField_2.Value = 100;
            app.toEditField_3Label = uilabel(app.UIFigure);
            app.toEditField_3Label.Text = 'to';
            app.toEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.toEditField_3.Value = 10000;
            app.mVLabel = uilabel(app.UIFigure);
            app.mVLabel.Text = 'mV';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ePDT_Model_vs_Tool_VerificationV21_NewCali

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end