classdef ePDT_Model_vs_Tool_VerificationV24_splitsensors < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        PipeThicknessChangePanel  matlab.ui.container.Panel
        ToolDataPanel             matlab.ui.container.Panel
        LoadToolDataButton        matlab.ui.control.Button
        EditField_17              matlab.ui.control.NumericEditField
        EditField_18              matlab.ui.control.NumericEditField
        ftmLabel                  matlab.ui.control.Label
        ftmLabel_2                matlab.ui.control.Label
        DepthofInterestLabel      matlab.ui.control.Label
        DepthwindowLabel          matlab.ui.control.Label
        EditField_19              matlab.ui.control.EditField
        DisplayWindowButton       matlab.ui.control.Button
        LoadINIFileButton         matlab.ui.control.Button
        EditField_20              matlab.ui.control.EditField
        LoadWellSchematicButton   matlab.ui.control.Button
        EditField_23              matlab.ui.control.EditField
        SectionsDropDownLabel     matlab.ui.control.Label
        SectionsDropDown          matlab.ui.control.DropDown
        EditField_66              matlab.ui.control.NumericEditField
        ftmLabel_3                matlab.ui.control.Label
        DepthofNominalLabel       matlab.ui.control.Label
        NomCalibratedLampLabel    matlab.ui.control.Label
        NomCalibratedLamp         matlab.ui.control.Lamp
        ModelingPanel             matlab.ui.container.Panel
        EditField_14              matlab.ui.control.NumericEditField
        EditField_15              matlab.ui.control.NumericEditField
        EditField_16              matlab.ui.control.NumericEditField
        WallLossLabel             matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        Label_4                   matlab.ui.control.Label
        Label_5                   matlab.ui.control.Label
        Pipe1Label                matlab.ui.control.Label
        Pipe2Label                matlab.ui.control.Label
        Pipe3Label                matlab.ui.control.Label
        NominalLabel              matlab.ui.control.Label
        PlotCalibratedNomButton   matlab.ui.control.Button
        CalibrateNomButton        matlab.ui.control.Button
        EditField_24              matlab.ui.control.NumericEditField
        EditField_25              matlab.ui.control.NumericEditField
        Label_8                   matlab.ui.control.Label
        Label_9                   matlab.ui.control.Label
        Pipe4Label                matlab.ui.control.Label
        Pipe5Label                matlab.ui.control.Label
        CalculateWLButton         matlab.ui.control.Button
        EditField_42              matlab.ui.control.NumericEditField
        EditField_43              matlab.ui.control.NumericEditField
        EditField_44              matlab.ui.control.NumericEditField
        EditField_45              matlab.ui.control.NumericEditField
        EditField_46              matlab.ui.control.NumericEditField
        CHLabel                   matlab.ui.control.Label
        WLLabel                   matlab.ui.control.Label
        EditField_55              matlab.ui.control.NumericEditField
        EditField_56              matlab.ui.control.NumericEditField
        EditField_57              matlab.ui.control.NumericEditField
        EditField_58              matlab.ui.control.NumericEditField
        EditField_59              matlab.ui.control.NumericEditField
        CleancollardepthLabel     matlab.ui.control.Label
        EditField_63              matlab.ui.control.NumericEditField
        Label_10                  matlab.ui.control.Label
        Pipe6Label                matlab.ui.control.Label
        EditField_64              matlab.ui.control.NumericEditField
        EditField_65              matlab.ui.control.NumericEditField
        PlotWLModelingButton      matlab.ui.control.Button
        PlotControlPanel          matlab.ui.container.Panel
        PlotinTimeCheckBox        matlab.ui.control.CheckBox
        MaxCheckBox               matlab.ui.control.CheckBox
        NomCheckBox               matlab.ui.control.CheckBox
        MinCheckBox               matlab.ui.control.CheckBox
        SectionalDataCheckBox     matlab.ui.control.CheckBox
        OriginalModelNomCheckBox  matlab.ui.control.CheckBox
        CalibratedNomCheckBox     matlab.ui.control.CheckBox
        CalibratedWLCheckBox      matlab.ui.control.CheckBox
        LogYCheckBox              matlab.ui.control.CheckBox
        DiaplayCHCheckBox         matlab.ui.control.CheckBox
        SensorDropDownLabel       matlab.ui.control.Label
        SensorDropDown            matlab.ui.control.DropDown
        ePDTModelvsToolVerificationLabel  matlab.ui.control.Label
        RescaleButton             matlab.ui.control.Button
        VoltageEditField_2Label   matlab.ui.control.Label
        VoltageEditField_2        matlab.ui.control.NumericEditField
        TimeorCHEditFieldLabel    matlab.ui.control.Label
        TimeorCHEditField         matlab.ui.control.NumericEditField
        toEditField_2Label        matlab.ui.control.Label
        toEditField_2             matlab.ui.control.NumericEditField
        toEditField_3Label        matlab.ui.control.Label
        toEditField_3             matlab.ui.control.NumericEditField
        mVLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        % general varibles
        FM_x % time axis
        
        % load log data
        logDataPath % log data path
        lasSrcFilename % log data filename
        lasData % log las data
        lasHeader % log las header
        lasDetails % log las details
        lasDepth % log las depth
        testMode % test mode from las
        ADEC % decay data from log las
        
        % load config info
        configFileName % config file name
        configPath % config file path
        testConfig % config info from ini
        
        % load well schematic info
        wellSchemPath % well schematic xml file path
        pipeList % pipe list read from xml file
        sectionList % list for section name
        depth_max % bottom (max) of selected section
        depth_min % top (min) of selected section
        
        % data initialization
        ModeUsed % mode used according to config and ini
        selected_ADEC % selected ADEC for processing
        ADEC_section % sectional ADEC for selected section of depth
        log_Max % max value of log raw data
        log_Min % min value of log raw data
        log_Med_lin % median value of log raw data, used as nominal values
        log_Med % median value of log raw data, used as nominal values with natural log
        log_Thin_lin % median value of log raw data at selected depth
        log_Thin % % median value of log raw data at selected depth, used as nominal values with natural log
        log_Max_perm % max value reserved for matching WL and enlarge gap before display
        log_Min_perm % min value reserved for matching WL and enlarge gap before display
        log_Max_sect % sectional max of selected section
        log_Min_sect % sectional min of selected section
        log_med_sect % sectional nom of selected section
        
        % data processing
        CH_S % channel for S sensor
        CH_M % channel for M sensor
        CH_L % channel for L sensor
        
        % data for FM
        n_p % number of pipes
        od_p % outer diameter of each pipe, mm
        tk_p % thickness of each pipe, mm
        loss_tk % thickness of each pipe after wall loss, mm
        sig_p % sigma of pipes
        mur_p % mu of pipes
        pipe % pipe index of enabled blank for ML
        FM_nom_lin % calculated nominal FM data in CH of all sensors
        FM_nom_linS % calculated nominal FM data in CH of S sensors
        FM_nom_linM % calculated nominal FM data in CH of M sensors
        FM_nom_linL % calculated nominal FM data in CH of L sensors
        FM_nom % calculated nominal FM data in CH of all sensors with natural log
        % FM of Thin/Thick
        tk_pThin % thickness of each pipe of selected depth, mm
        FM_thin_lin % calculated thin FM data in CH of all sensors
        FM_thin_linS % calculated thin FM data in CH of S sensors
        FM_thin_linM % calculated thin FM data in CH of M sensors
        FM_thin_linL % calculated thin FM data in CH of L sensors
        FM_thin % calculated thin FM data in CH of all sensors with natural log FM of Max
        FM_Max_lin % calculated max FM data in CH of all sensors with natural log
        degain % array for degain
        
        % data for adjustment
        lognom_S % log data slice
        logthin_S % log data slice
        logmax_S % log data slice
        lognom_M % log data slice
        logthin_M % log data slice
        logmax_M % log data slice
        lognom_L % log data slice
        logthin_L % log data slice
        logmax_L % log data slice
        sensiRatio % sensitivity ratio of log max to FM max
        
        Pn_S % index of third order term of FM nom
        Qn_S % index of second order term of FM nom
        Rn_S % index of first order term of FM nom
        Sn_S % index of constant term of FM nom
        
        Pn_M % index of third order term of FM nom
        Qn_M % index of second order term of FM nom
        Rn_M % index of first order term of FM nom
        Sn_M % index of constant term of FM nom
        
        Pn_L % index of third order term of FM nom
        Qn_L % index of second order term of FM nom
        Rn_L % index of first order term of FM nom
        Sn_L % index of constant term of FM nom
        
        Pt_S % index of third order term of FM thin
        Qt_S % index of second order term of FM thin
        Rt_S % index of first order term of FM thin
        St_S % index of constant term of FM thin
        
        Pt_M % index of third order term of FM thin
        Qt_M % index of second order term of FM thin
        Rt_M % index of first order term of FM thin
        St_M % index of constant term of FM thin
        
        Pt_L % index of third order term of FM thin
        Qt_L % index of second order term of FM thin
        Rt_L % index of first order term of FM thin
        St_L % index of constant term of FM thin
        
        Pm_S % index of third order term of FM thin
        Qm_S % index of second order term of FM thin
        Rm_S % index of first order term of FM thin
        Sm_S % index of constant term of FM thin
        
        Pm_M % index of third order term of FM thin
        Qm_M % index of second order term of FM thin
        Rm_M % index of first order term of FM thin
        Sm_M % index of constant term of FM thin
        
        Pm_L % index of third order term of FM thin
        Qm_L % index of second order term of FM thin
        Rm_L % index of first order term of FM thin
        Sm_L % index of constant term of FM thin
        
        % factors for adjustment
        An_S % factor for adjusting third order term of FM nom
        Bn_S % factor for adjusting second order term of FM nom
        Cn_S % factor for adjusting first order term of FM nom
        Dn_S % factor for adjusting constant term of FM nom
        
        An_M % factor for adjusting third order term of FM nom
        Bn_M % factor for adjusting second order term of FM nom
        Cn_M % factor for adjusting first order term of FM nom
        Dn_M % factor for adjusting constant term of FM nom
        
        An_L % factor for adjusting third order term of FM nom
        Bn_L % factor for adjusting second order term of FM nom
        Cn_L % factor for adjusting first order term of FM nom
        Dn_L % factor for adjusting constant term of FM nom
        
        alphaS % factor for sensitivity ratio
        alphaM % factor for sensitivity ratio
        alphaL % factor for sensitivity ratio
        
        At_S % factor for adjusting second and first order term of FM thin
        Bt_S % factor for adjusting constant term of FM thin
        Ct_S % factor for assist matching
        Dt_S % factor for up and down
        
        At_M % factor for adjusting second and first order term of FM thin
        Bt_M % factor for adjusting constant term of FM thin
        Ct_M % factor for assist matching
        Dt_M % factor for up and down
        
        At_L % factor for adjusting second and first order term of FM thin
        Bt_L % factor for adjusting constant term of FM thin
        Ct_L % factor for assist matching
        Dt_L % factor for up and down
        
        % adjusted data
        FMnom_S % adjusted nominal data for plot
        FMnom_M % adjusted nominal data for plot
        FMnom_L % adjusted nominal data for plot
        FMnom_adj % combined data for plot
        FMthin_S
        FMthin_M
        FMthin_L
        FMthin_adj
        FMmax % FM max data
        FM_nom_ms % nominal value in ms
        FM_thin_final % calibrated FM thin
        
        flag_nomcalib % flag if nom calibration ABCD is still usable
        plotsensorFLag % flag for which sensor to plot, 'a','s','m','l'
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotCalibratedNomButton
        function PlotCalibratedNomButtonPushed(app, event)
            % plot data
            cla(app.UIAxes);
            leg1 = legend(app.UIAxes);
            leg1.AutoUpdate = 'on';
            
            % get sensor flag according to dropdown value
            if app.SensorDropDown.Value == 4
                app.plotsensorFLag = 4;
            elseif app.SensorDropDown.Value == 1
                app.plotsensorFLag = 1;
            elseif app.SensorDropDown.Value == 2
                app.plotsensorFLag = 2;
            elseif app.SensorDropDown.Value == 3
                app.plotsensorFLag = 3;
            end
            
            % sensor index will be according to sensorflag
            switch app.plotsensorFLag
                case 4
                    istart_ch = app.testConfig.adec_idx_start(1);
                    iend_ch = max(app.testConfig.adec_idx_end(:));
                case 1
                    istart_ch = app.testConfig.adec_idx_start(1);
                    iend_ch = app.testConfig.adec_idx_end(1);
                case 2
                    istart_ch = app.testConfig.adec_idx_start(2);
                    iend_ch = app.testConfig.adec_idx_end(2);
                case 3
                    istart_ch = app.testConfig.adec_idx_start(3);
                    iend_ch = app.testConfig.adec_idx_end(3);
            end
            
            
            % plot calibrated FM nom
            if ~isempty(app.FMnom_adj)
                FMnom_adj = app.FMnom_adj;
                if ~isempty(app.FMnom_adj) && (app.CalibratedNomCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), FMnom_adj(istart_ch:iend_ch), 'Color', 'k', 'LineStyle', '-', 'LineWidth', 0.7, 'DisplayName', 'Calib Model Nom');
                elseif ~isempty(app.FMnom_adj) && (app.CalibratedNomCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(FMnom_adj, app.testConfig, app.plotsensorFLag);
                    y = correctdecay(y, 0, 0, app.testConfig);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'Color', 'k', 'LineStyle', '-', 'LineWidth', 0.7, 'DisplayName', 'Calib Model Nom');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            % plot orignal FM nom
            if ~isempty(app.FM_nom_lin)
                FM_nom_lin = app.FM_nom_lin;
                if ~isempty(app.FM_nom_lin) && (app.OriginalModelNomCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), FM_nom_lin(istart_ch:iend_ch), 'Color', [0.7 0.7 0.7], 'LineStyle', '-', 'LineWidth', 0.5, 'DisplayName', 'Orig Model Nom');
                elseif ~isempty(app.FM_nom_lin) && (app.OriginalModelNomCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(FM_nom_lin, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'Color', [0.7 0.7 0.7], 'LineStyle', '-', 'LineWidth', 0.5, 'DisplayName', 'Orig Model Nom');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            if ~isempty(app.log_Med_lin)
                log_Med_lin = app.log_Med_lin;
                if ~isempty(app.log_Med_lin) && (app.NomCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), log_Med_lin(istart_ch:iend_ch), 'g', 'LineWidth', 0.7, 'DisplayName', 'Nom');
                elseif ~isempty(app.log_Med_lin) && (app.NomCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(log_Med_lin, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'g', 'LineWidth', 0.7, 'DisplayName', 'Nom');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            if ~isempty(app.log_Max)
                log_Max = app.log_Max;
                if ~isempty(app.log_Max) && (app.MaxCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), log_Max(istart_ch:iend_ch), 'r', 'LineWidth', 0.7, 'DisplayName', 'Max');
                elseif ~isempty(app.log_Max) && (app.MaxCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(log_Max, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'r', 'LineWidth', 0.7, 'DisplayName', 'Max');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            if ~isempty(app.log_Min)
                log_Min = app.log_Min;
                if ~isempty(app.log_Min) && (app.MinCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), log_Min(istart_ch:iend_ch), 'b', 'LineWidth', 0.7, 'DisplayName', 'Min');
                elseif ~isempty(app.log_Min) && (app.MinCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(log_Min, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'b', 'LineWidth', 0.7, 'DisplayName', 'Min');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            % if sectional line box checked, show sectional lines
            if ~isempty(app.log_med_sect)
                log_med_sect = app.log_med_sect;
                if ~isempty(app.log_med_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), log_med_sect(istart_ch:iend_ch), 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Nom');
                elseif ~isempty(app.log_med_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(log_med_sect, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'Color', 'k', 'LineStyle', '-.', 'LineWidth', 0.5,'DisplayName', 'Sect-Nom');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            if ~isempty(app.log_Max_sect)
                log_Max_sect = app.log_Max_sect;
                if ~isempty(app.log_Max_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), log_Max_sect(istart_ch:iend_ch), 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Max');
                elseif ~isempty(app.log_Max_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(log_Max_sect, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Max');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            if ~isempty(app.log_Min_sect)
                log_Min_sect = app.log_Min_sect;
                if ~isempty(app.log_Min_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), log_Min_sect(istart_ch:iend_ch), 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Min');
                elseif ~isempty(app.log_Min_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(log_Min_sect, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Min');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            % logy scale
            if app.LogYCheckBox.Value == 1
                set(app.UIAxes, 'YScale', 'log');
            else
                set(app.UIAxes, 'YScale', 'linear');
            end
            
            % plot CHs for time display
            leg1.AutoUpdate = 'off'; % cancel autoupdate of legend
            if (app.PlotinTimeCheckBox.Value == 1) && (app.DiaplayCHCheckBox.Value == 1)
                for n = 1:size(app.testConfig.chendtime, 2)
                    if mod(n, 5) == 0
                        xline(app.UIAxes, app.testConfig.chendtime(n), ':', n, 'LabelHorizontalAlignment', 'center');
                    end
                end
            end
            
        end

        % Button pushed function: CalibrateNomButton
        function CalibrateNomButtonPushed(app, event)
            %%% check if nom calibration flag is on or off
            % if flag on
            if app.flag_nomcalib == 1
                %%% apply parameters
                % S sensor finding parameters
                if app.testConfig.sensor(1) == 1
                    x_S = 1 : size(app.FM_nom_linS, 2);
                    % calculate decay curve
                    P_S = app.Pn_S * (1 + app.An_S);
                    Q_S = app.Qn_S * (1 + app.Bn_S);
                    R_S = app.Rn_S * (1 + app.Cn_S);
                    S_S = app.Sn_S;
                    emf_adj_poly = [P_S, Q_S, R_S, S_S];
                    emf_adjusted = polyval(emf_adj_poly, x_S);
                    emf_adjA = zeros(1, max(app.testConfig.chendtime));
                    emf_adjA ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(1)) + 1) :...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(1))) = emf_adjusted;
                    for j = app.testConfig.adec_idx_start(1):app.testConfig.adec_idx_end(1)
                        FMnom_S(j) = mean(emf_adjA(...
                            (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                    end
                    
                    % make sure emf_adjusted > 0
                    for i = 1:size(FMnom_S, 2)
                        if FMnom_S(i) <= 0
                            FMnom_S(i) = 0.01;
                        end
                    end
                    FMnom_S = FMnom_S(app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1));
                    %                 FMnom_S = FMnom_S - (FMnom_S(1) - app.lognom_S(1));
                    FMnom_S = FMnom_S + app.Dn_S;
                    app.FMnom_S = exp(FMnom_S);
                elseif app.testConfig.sensor(1) == 0
                    app.FMnom_S = double.empty;
                end
                
                % M sensor finding parameters
                if app.testConfig.sensor(2) == 1
                    x_M = 1 : size(app.FM_nom_linM, 2);
                    % calculate decay curve
                    P_M = app.Pn_M * (1 + app.An_M);
                    Q_M = app.Qn_M * (1 + app.Bn_M);
                    R_M = app.Rn_M * (1 + app.Cn_M);
                    S_M = app.Sn_M;
                    emf_adj_poly = [P_M, Q_M, R_M, S_M];
                    emf_adjusted = polyval(emf_adj_poly, x_M);
                    %emf_adjA = zeros(1, max(app.testConfig.chendtime));
                    emf_adjA ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(2)) + 1) :...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(2))) = emf_adjusted;
                    for j = app.testConfig.adec_idx_start(2):app.testConfig.adec_idx_end(2)
                        FMnom_M(j) = mean(emf_adjA(...
                            (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                    end
                    
                    % make sure emf_adjusted > 0
                    for i = 1:size(FMnom_M, 2)
                        if FMnom_M(i) <= 0
                            FMnom_M(i) = 0.01;
                        end
                    end
                    FMnom_M = FMnom_M(app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2));
                    %                 FMnom_M = FMnom_M - (FMnom_M(1) - app.lognom_M(1));
                    FMnom_M = FMnom_M + app.Dn_M;
                    app.FMnom_M = exp(FMnom_M);
                elseif app.testConfig.sensor(2) == 0
                    app.FMnom_M = double.empty;
                end
                
                % L sensor finding parameters
                if app.testConfig.sensor(3) == 1
                    x_L = 1 : size(app.FM_nom_linL, 2);
                    % calculate decay curve
                    P_L = app.Pn_L * (1 + app.An_L);
                    Q_L = app.Qn_L * (1 + app.Bn_L);
                    R_L = app.Rn_L * (1 + app.Cn_L);
                    S_L = app.Sn_L;
                    emf_adj_poly = [P_L, Q_L, R_L, S_L];
                    emf_adjusted = polyval(emf_adj_poly, x_L);
                    %emf_adjA = zeros(1, max(app.testConfig.chendtime));
                    emf_adjA ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(3)) + 1) :...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(3))) = emf_adjusted;
                    for j = app.testConfig.adec_idx_start(3):app.testConfig.adec_idx_end(3)
                        FMnom_L(j) = mean(emf_adjA(...
                            (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                    end
                    
                    % make sure emf_adjusted > 0
                    for i = 1:size(FMnom_L, 2)
                        if FMnom_L(i) <= 0
                            FMnom_L(i) = 0.01;
                        end
                    end
                    FMnom_L = FMnom_L(app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3));
                    %                 FMnom_L = FMnom_L - (FMnom_L(1) - app.lognom_L(1));
                    FMnom_L = FMnom_L + app.Dn_L;
                    app.FMnom_L = exp(FMnom_L);
                elseif app.testConfig.sensor(3) == 0
                    app.FMnom_L = double.empty;
                end
                
                % combine three sensors into one
                app.FMnom_adj = [app.FMnom_S, app.FMnom_M, app.FMnom_L];
                app.FM_nom_ms = exp(emf_adjA);
                
                msgbox("Nominal Value calibrated.")
                
                % enable original Nom and Calibrated Nom checkbox
                set(app.OriginalModelNomCheckBox, 'Enable', 'on');
                set(app.CalibratedNomCheckBox, 'Enable', 'on');
                
                app.flag_nomcalib = 1;
                
            elseif app.flag_nomcalib == 0
                % if flag off
                %%% forward modeling of nominal value
                % data initialization for FM
                app.sig_p = ones(1, app.n_p) * 7; %app.EditField_2.Value; Revised according to request 9.13.23
                app.sig_p(1) = 7; %app.EditField.Value;
                app.mur_p = ones(1, app.n_p) * 110; %app.EditField_3.Value;
                app.mur_p(1) = 110; %app.EditField_4.Value;
                
                % prepare data for log data
                %%% may need to cut off 0
                log_Med_crct = correctdecay(app.log_Med_lin, 1, 0, app.testConfig);
                app.log_Med = log(log_Med_crct);
                
                % run FM function
                % calculate three sensor FM data, assign to varibles and channeling
                n_ch = max(app.testConfig.adec_idx_end);
                FM_ch = zeros(1, n_ch);
                coils = 'SML';
                for i = 1:3
                    if i == 1 && app.testConfig.sensor(i)
                        msgbox("Calculating Sensors");
                        v_ms = CalcePDTBSegMuAvgEMF(app.n_p, app.od_p, app.tk_p, coils(i), app.sig_p, app.mur_p, ...
                            app.testConfig.txcurrent(i), app.testConfig.txduration(i), app.testConfig.chdelaytime, ...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                        app.FM_nom_linS = v_ms((app.testConfig.chstarttime(app.testConfig.adec_idx_start(i))+1) : ...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                        for j = app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)
                            FM_ch(j) = mean(v_ms(...
                                (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                        end
                        % align first CH of current sensor FM with log
                        FM_ch(app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)) = ...
                            FM_ch(app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)) * ...
                            (app.log_Med_lin(app.testConfig.adec_idx_start(i))/FM_ch(app.testConfig.adec_idx_start(i)));
                    elseif i == 2 && app.testConfig.sensor(i)
                        v_ms = CalcePDTBSegMuAvgEMF(app.n_p, app.od_p, app.tk_p, coils(i), app.sig_p, app.mur_p, ...
                            app.testConfig.txcurrent(i), app.testConfig.txduration(i), app.testConfig.chdelaytime, ...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                        app.FM_nom_linM = v_ms((app.testConfig.chstarttime(app.testConfig.adec_idx_start(i))+1) : ...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                        for j = app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)
                            FM_ch(j) = mean(v_ms(...
                                (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                        end
                        % align first CH of current sensor FM with log
                        FM_ch(app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)) = ...
                            FM_ch(app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)) * ...
                            (app.log_Med_lin(app.testConfig.adec_idx_start(i))/FM_ch(app.testConfig.adec_idx_start(i)));
                    elseif i == 3 && app.testConfig.sensor(i)
                        v_ms = CalcePDTBSegMuAvgEMF(app.n_p, app.od_p, app.tk_p, coils(i), app.sig_p, app.mur_p, ...
                            app.testConfig.txcurrent(i), app.testConfig.txduration(i), app.testConfig.chdelaytime, ...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                        app.FM_nom_linL = v_ms((app.testConfig.chstarttime(app.testConfig.adec_idx_start(i))+1) : ...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                        for j = app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)
                            FM_ch(j) = mean(v_ms(...
                                (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                        end
                        % align first CH of current sensor FM with log
                        FM_ch(app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)) = ...
                            FM_ch(app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)) * ...
                            (app.log_Med_lin(app.testConfig.adec_idx_start(i))/FM_ch(app.testConfig.adec_idx_start(i)));
                    end
                end
                app.FM_nom_lin = FM_ch;
                
                %%% polyfit the FM_nom in separate sensors
                % S sensor
                if app.testConfig.sensor(1) == 1
                    x_S = 1 : size(app.FM_nom_linS, 2);
                    app.lognom_S = app.log_Med (1:app.testConfig.adec_idx_end(1));
                    FMnom_S = log(app.FM_nom_linS);
                    FMnom_S_1 = log(mean(app.FM_nom_linS(...
                        1:(app.testConfig.chendtime(app.testConfig.adec_idx_start(1)) - ...
                        app.testConfig.chstarttime(app.testConfig.adec_idx_start(1)))), 2));
                    app.Dn_S = app.lognom_S (1) - FMnom_S_1(1);
                    % fit FM nom as third order polynomial
                    emf_poly_S = polyfit(x_S, FMnom_S, 3);
                    app.Pn_S = emf_poly_S(1);
                    app.Qn_S = emf_poly_S(2);
                    app.Rn_S = emf_poly_S(3);
                    app.Sn_S = emf_poly_S(4);
                elseif app.testConfig.sensor(1) == 0
                    app.lognom_S = double.empty;
                end
                
                % M sensor
                if app.testConfig.sensor(2) == 1
                    x_M = 1 : size(app.FM_nom_linM, 2);
                    app.lognom_M = app.log_Med (app.testConfig.adec_idx_start(2) : ...
                        app.testConfig.adec_idx_end(2));
                    FMnom_M = log(app.FM_nom_linM);
                    FMnom_M_1 = log(mean(app.FM_nom_linM(...
                        1:(app.testConfig.chendtime(app.testConfig.adec_idx_start(2)) - ...
                        app.testConfig.chstarttime(app.testConfig.adec_idx_start(2)))), 2));
                    app.Dn_M = app.lognom_M (1) - FMnom_M_1(1);
                    % fit FM nom as third order polynomial
                    emf_poly_M = polyfit(x_M, FMnom_M, 3);
                    app.Pn_M = emf_poly_M(1);
                    app.Qn_M = emf_poly_M(2);
                    app.Rn_M = emf_poly_M(3);
                    app.Sn_M = emf_poly_M(4);
                elseif app.testConfig.sensor(2) == 0
                    app.lognom_M = double.empty;
                end
                
                % L sensor
                if app.testConfig.sensor(3) == 1
                    x_L = 1 : size(app.FM_nom_linL, 2);
                    app.lognom_L = app.log_Med (app.testConfig.adec_idx_start(3) : ...
                        app.testConfig.adec_idx_end(3));
                    FMnom_L = log(app.FM_nom_linL);
                    FMnom_L_1 = log(mean(app.FM_nom_linL(...
                        1:(app.testConfig.chendtime(app.testConfig.adec_idx_start(3)) - ...
                        app.testConfig.chstarttime(app.testConfig.adec_idx_start(3)))), 2));
                    app.Dn_L = app.lognom_L (1) - FMnom_L_1(1);
                    % fit FM nom as third order polynomial
                    emf_poly_L = polyfit(x_L, FMnom_L, 3);
                    app.Pn_L = emf_poly_L(1);
                    app.Qn_L = emf_poly_L(2);
                    app.Rn_L = emf_poly_L(3);
                    app.Sn_L = emf_poly_L(4);
                elseif app.testConfig.sensor(3) == 0
                    app.lognom_L = double.empty;
                end
                
                
                %%% find parameters ABCD
                % S sensor finding parameters
                if app.testConfig.sensor(1) == 1
                    msgbox("Finding calibration parameters for sensors");
                    % first half of sensor
                    log_S = app.lognom_S; % add 10 to avoid negative value or 0
                    x_S = 1 : size(app.FM_nom_linS, 2);
                    i = 1;
                    Sum = 1000000;
                    Parameters = 0;
                    % find alpha and beta in -50~50 range for L sensor
                    for An_S = -4.5:0.1:4.5
                        for Bn_S = -3:0.1:3
                            for Cn_S = -3:0.1:3
                                
                                P_S = app.Pn_S * (1 + An_S);
                                Q_S = app.Qn_S * (1 + Bn_S);
                                R_S = app.Rn_S * (1 + Cn_S);
                                S_S = app.Sn_S;
                                emf_adj_poly = [P_S, Q_S, R_S, S_S];
                                emf_adjusted = polyval(emf_adj_poly, x_S);
                                emf_adj = zeros(1, max(app.testConfig.chendtime));
                                emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(1)) + 1) :...
                                    app.testConfig.chendtime(app.testConfig.adec_idx_end(1))) = emf_adjusted;
                                for j = app.testConfig.adec_idx_start(1):app.testConfig.adec_idx_end(1)
                                    FM_CH_S(j) = mean(emf_adj(...
                                        (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                                end
                                emf = FM_CH_S(app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1)) -...
                                    (FM_CH_S(app.testConfig.adec_idx_start(1)) - log_S(1));
                                
                                for m = 1:size(log_S, 2)
                                    if m <= size(log_S, 2)/3
                                        Misfit(m) = abs(1 * (emf(m) - log_S(m)) / log_S(m));
                                    elseif m > size(log_S, 2)/3 && m <=  size(log_S, 2) * (2/3)
                                        Misfit(m) = abs(1 * (emf(m) - log_S(m)) / log_S(m));
                                    elseif m > size(log_S, 2) * (2/3)
                                        Misfit(m) = abs(1 * (emf(m) - log_S(m)) / log_S(m));
                                    end
                                end
                                Sum_misfit = sum(Misfit);
                                % for inspection
                                if abs(Sum_misfit) < abs(Sum)
                                    Sum =  Sum_misfit;
                                    Parameters(i, 1:3) = [An_S, Bn_S, Cn_S];
                                    Parameters(i, 4) = Sum;
                                    FMmin_S = emf;
                                    i = i + 1;
                                end
                            end
                        end
                    end
                    app.An_S = Parameters(end, 1);
                    app.Bn_S = Parameters(end, 2);
                    app.Cn_S = Parameters(end, 3);
                    app.Dn_S = app.Dn_S;
                    %                 % displayed A B C D is the ones from second half sensor
                    %                 app.EditField_5.Value = app.An_S;
                    %                 app.EditField_8.Value = app.Bn_S;
                    %                 app.EditField_12.Value = app.Cn_S;
                    %                 app.EditField_33.Value = app.Dn_S;
                end
                
                % M sensor finding parameters
                if app.testConfig.sensor(2) == 1
                    % adjust searching boundary and weight according to
                    % existance of L sensor
                    if app.testConfig.sensor(3) == 1
                        A_bound = 4;
                        B_bound = 3;
                        C_bound = 3;
                        stepA = 0.1;
                        stepB = 0.1;
                        stepC = 0.1;
                        second_third = 1;
                        third_third = 0.5;
                    elseif app.testConfig.sensor(3) == 0
                        A_bound = 2;
                        B_bound = 2;
                        C_bound = 2;
                        stepA = 0.2;
                        stepB = 0.2;
                        stepC = 0.2;
                        second_third = 1;
                        third_third = 0.1;
                    end
                    %msgbox("Finding parameters for M sensor");
                    log_M = app.lognom_M; % add 10 to avoid negative value or 0
                    x_M = 1 : size(app.FM_nom_linM, 2);
                    i = 1;
                    Sum = 1000000;
                    Parameters = 0;
                    % find alpha and beta in -50~50 range for L sensor
                    for An_M = (-1*A_bound):stepA:(A_bound)
                        for Bn_M = (-1*B_bound):stepB:(B_bound)
                            for Cn_M = (-1*C_bound):stepC:(C_bound)
                                
                                P_M = app.Pn_M * (1 + An_M);
                                Q_M = app.Qn_M * (1 + Bn_M);
                                R_M = app.Rn_M * (1 + Cn_M);
                                S_M = app.Sn_M;
                                emf_adj_poly = [P_M, Q_M, R_M, S_M];
                                emf_adjusted = polyval(emf_adj_poly, x_M);
                                emf_adj = zeros(1, max(app.testConfig.chendtime));
                                emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(2)) + 1) :...
                                    app.testConfig.chendtime(app.testConfig.adec_idx_end(2))) = emf_adjusted;
                                for j = app.testConfig.adec_idx_start(2):app.testConfig.adec_idx_end(2)
                                    FM_CH_M(j) = mean(emf_adj(...
                                        (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                                end
                                emf = FM_CH_M(app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2)) -...
                                    (FM_CH_M(app.testConfig.adec_idx_start(2)) - log_M(1));
                                
                                for m = 1:size(log_M, 2)
                                    if m <= size(log_M, 2)/3
                                        Misfit(m) = abs(1 * (emf(m) - log_M(m)) / log_M(m));
                                    elseif m > size(log_M, 2)/3 && m <=  size(log_M, 2) * (2/3)
                                        Misfit(m) = abs(second_third * (emf(m) - log_M(m)) / log_M(m));
                                    elseif m > size(log_M, 2) * (2/3)
                                        Misfit(m) = abs(third_third * (emf(m) - log_M(m)) / log_M(m));
                                    end
                                end
                                Sum_misfit = sum(Misfit);
                                % for inspection
                                if abs(Sum_misfit) < abs(Sum)
                                    Sum =  Sum_misfit;
                                    Parameters(i, 1:3) = [An_M, Bn_M, Cn_M];
                                    Parameters(i, 4) = Sum;
                                    FMmin_M = emf;
                                    i = i + 1;
                                end
                            end
                        end
                    end
                    app.An_M = Parameters(end, 1);
                    app.Bn_M = Parameters(end, 2);
                    app.Cn_M = Parameters(end, 3);
                    app.Dn_M = app.Dn_M;
                    %                 % displayed A B C D is the ones from second half sensor
                    %                 app.EditField_6.Value = app.An_M;
                    %                 app.EditField_7.Value = app.Bn_M;
                    %                 app.EditField_11.Value = app.Cn_M;
                    %                 app.EditField_32.Value = app.Dn_M;
                end
                
                % L sensor finding parameters
                if app.testConfig.sensor(3) == 1
                    %msgbox("Finding parameters for L sensor");
                    log_L = app.lognom_L; % add 10 to avoid negative value or 0
                    x_L = 1 : size(app.FM_nom_linL, 2);
                    i = 1;
                    Sum = 1000000;
                    Parameters = 0;
                    % find alpha and beta in -50~50 range for L sensor
                    for An_L = -2:0.2:2
                        for Bn_L = -2:0.2:2
                            for Cn_L = -2:0.2:2
                                
                                P_L = app.Pn_L * (1 + An_L);
                                Q_L = app.Qn_L * (1 + Bn_L);
                                R_L = app.Rn_L * (1 + Cn_L);
                                S_L = app.Sn_L;
                                emf_adj_poly = [P_L, Q_L, R_L, S_L];
                                emf_adjusted = polyval(emf_adj_poly, x_L);
                                emf_adj = zeros(1, max(app.testConfig.chendtime));
                                emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(3)) + 1) :...
                                    app.testConfig.chendtime(app.testConfig.adec_idx_end(3))) = emf_adjusted;
                                for j = app.testConfig.adec_idx_start(3):app.testConfig.adec_idx_end(3)
                                    FM_CH_L(j) = mean(emf_adj(...
                                        (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                                end
                                emf = FM_CH_L(app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3)) -...
                                    (FM_CH_L(app.testConfig.adec_idx_start(3)) - log_L(1));
                                
                                % avoid effect of noise
                                n_ch = max(app.testConfig.adec_idx_end);
                                if n_ch >= 96
                                    x = 7;
                                elseif n_ch == 60
                                    x = 5;
                                elseif n_ch < 60
                                    x = 3;
                                end
                                
                                for m = 1:size(log_L, 2)
                                    if m <= size(log_L, 2)/3
                                        Misfit(m) = abs(1 * (emf(m) - log_L(m)) / log_L(m));
                                    elseif m > size(log_L, 2)/3 && m <=  size(log_L, 2) * (2/3)
                                        Misfit(m) = abs(0.5 * (emf(m) - log_L(m)) / log_L(m));
                                    elseif m > size(log_L, 2) * (2/3) && m <= (size(log_L, 2) - x)
                                        Misfit(m) = abs(0.1 * (emf(m) - log_L(m)) / log_L(m));
                                    elseif m > (size(log_L, 2) - x)
                                        Misfit(m) = abs(0.1 * (emf(m) - log_L(m)) / log_L(m));
                                    end
                                end
                                Sum_misfit = sum(Misfit);
                                % for inspection
                                if abs(Sum_misfit) < abs(Sum)
                                    Sum = Sum_misfit;
                                    Parameters(i, 1:3) = [An_L, Bn_L, Cn_L];
                                    Parameters(i, 4) = Sum;
                                    FMmin_L = emf;
                                    i = i + 1;
                                end
                            end
                        end
                    end
                    app.An_L = Parameters(end, 1);
                    app.Bn_L = Parameters(end, 2);
                    app.Cn_L = Parameters(end, 3);
                    app.Dn_L = app.Dn_L;
                    
                    % displayed A B C D is the ones from second half sensor
                    %                 app.EditField_9.Value = app.An_L;
                    %                 app.EditField_10.Value = app.Bn_L;
                    %                 app.EditField_13.Value = app.Cn_L;
                    %                 app.EditField_34.Value = app.Dn_L;
                end
                
                %%% apply parameters
                % S sensor finding parameters
                if app.testConfig.sensor(1) == 1
                    x_S = 1 : size(app.FM_nom_linS, 2);
                    % calculate decay curve
                    P_S = app.Pn_S * (1 + app.An_S);
                    Q_S = app.Qn_S * (1 + app.Bn_S);
                    R_S = app.Rn_S * (1 + app.Cn_S);
                    S_S = app.Sn_S;
                    emf_adj_poly = [P_S, Q_S, R_S, S_S];
                    emf_adjusted = polyval(emf_adj_poly, x_S);
                    emf_adjA = zeros(1, max(app.testConfig.chendtime));
                    emf_adjA ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(1)) + 1) :...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(1))) = emf_adjusted;
                    for j = app.testConfig.adec_idx_start(1):app.testConfig.adec_idx_end(1)
                        FMnom_S(j) = mean(emf_adjA(...
                            (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                    end
                    
                    % make sure emf_adjusted > 0
                    for i = 1:size(FMnom_S, 2)
                        if FMnom_S(i) <= 0
                            FMnom_S(i) = 0.01;
                        end
                    end
                    FMnom_S = FMnom_S(app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1));
                    %                 FMnom_S = FMnom_S - (FMnom_S(1) - app.lognom_S(1));
                    FMnom_S = FMnom_S + app.Dn_S;
                    app.FMnom_S = exp(FMnom_S);
                elseif app.testConfig.sensor(1) == 0
                    app.FMnom_S = double.empty;
                end
                
                % M sensor finding parameters
                if app.testConfig.sensor(2) == 1
                    x_M = 1 : size(app.FM_nom_linM, 2);
                    % calculate decay curve
                    P_M = app.Pn_M * (1 + app.An_M);
                    Q_M = app.Qn_M * (1 + app.Bn_M);
                    R_M = app.Rn_M * (1 + app.Cn_M);
                    S_M = app.Sn_M;
                    emf_adj_poly = [P_M, Q_M, R_M, S_M];
                    emf_adjusted = polyval(emf_adj_poly, x_M);
                    %emf_adjA = zeros(1, max(app.testConfig.chendtime));
                    emf_adjA ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(2)) + 1) :...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(2))) = emf_adjusted;
                    for j = app.testConfig.adec_idx_start(2):app.testConfig.adec_idx_end(2)
                        FMnom_M(j) = mean(emf_adjA(...
                            (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                    end
                    
                    % make sure emf_adjusted > 0
                    for i = 1:size(FMnom_M, 2)
                        if FMnom_M(i) <= 0
                            FMnom_M(i) = 0.01;
                        end
                    end
                    FMnom_M = FMnom_M(app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2));
                    %                 FMnom_M = FMnom_M - (FMnom_M(1) - app.lognom_M(1));
                    FMnom_M = FMnom_M + app.Dn_M;
                    app.FMnom_M = exp(FMnom_M);
                elseif app.testConfig.sensor(2) == 0
                    app.FMnom_M = double.empty;
                end
                
                % L sensor finding parameters
                if app.testConfig.sensor(3) == 1
                    x_L = 1 : size(app.FM_nom_linL, 2);
                    % calculate decay curve
                    P_L = app.Pn_L * (1 + app.An_L);
                    Q_L = app.Qn_L * (1 + app.Bn_L);
                    R_L = app.Rn_L * (1 + app.Cn_L);
                    S_L = app.Sn_L;
                    emf_adj_poly = [P_L, Q_L, R_L, S_L];
                    emf_adjusted = polyval(emf_adj_poly, x_L);
                    %emf_adjA = zeros(1, max(app.testConfig.chendtime));
                    emf_adjA ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(3)) + 1) :...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(3))) = emf_adjusted;
                    for j = app.testConfig.adec_idx_start(3):app.testConfig.adec_idx_end(3)
                        FMnom_L(j) = mean(emf_adjA(...
                            (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                    end
                    
                    % make sure emf_adjusted > 0
                    for i = 1:size(FMnom_L, 2)
                        if FMnom_L(i) <= 0
                            FMnom_L(i) = 0.01;
                        end
                    end
                    FMnom_L = FMnom_L(app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3));
                    %                 FMnom_L = FMnom_L - (FMnom_L(1) - app.lognom_L(1));
                    FMnom_L = FMnom_L + app.Dn_L;
                    app.FMnom_L = exp(FMnom_L);
                elseif app.testConfig.sensor(3) == 0
                    app.FMnom_L = double.empty;
                end
                
                % combine three sensors into one
                app.FMnom_adj = [app.FMnom_S, app.FMnom_M, app.FMnom_L];
                app.FM_nom_ms = exp(emf_adjA);
                
                msgbox("Nominal Value calibrated.")
                
                % enable original Nom and Calibrated Nom checkbox
                set(app.OriginalModelNomCheckBox, 'Enable', 'on');
                set(app.CalibratedNomCheckBox, 'Enable', 'on');
                
                % turn lamp to green
                app.NomCalibratedLamp.Color = "green";
                app.flag_nomcalib = 1;
            end
            %             %%% plot FM_nom vs FM_x
            %             cla(app.UIAxes);
            %             % plot data
            %             plot(app.UIAxes, app.FM_x, app.log_Med_lin, 'g', 'LineWidth', 0.7);
            %             hold(app.UIAxes, "on");
            %             plot(app.UIAxes, app.FM_x, app.log_Max, 'r', 'LineWidth', 0.7);
            %             hold(app.UIAxes, "on");
            %             plot(app.UIAxes, app.FM_x, app.log_Min, 'b', 'LineWidth', 0.7);
            %             hold(app.UIAxes, "on");
            %             plot(app.UIAxes, app.FM_x, app.FMnom_adj, 'Color', 'k', 'LineStyle', '-', 'LineWidth', 0.7);
            %             hold(app.UIAxes, "on");
            %             leg1 = legend(app.UIAxes,{'Nom','Max','Min', 'Model'},'Location','northeast','Orientation','horizontal');
            
            %             % disabled if no app.FMnom_adj
            %             if isempty(app.FMnom_adj)
            %                 set(app.CombineButton_2, 'Enable', 'off');
            %             else
            %                 set(app.CombineButton_2, 'Enable', 'on');
            %             end


        end

        % Callback function
        function ApplyParametersButtonPushed(app, event)
            
        end

        % Callback function
        function CloseButtonPushed(app, event)
            
        end

        % Button pushed function: LoadToolDataButton
        function LoadToolDataButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.las'}, 'Load Log Data');
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg('No file selected', 'Error');
            end
            
            app.logDataPath = strcat(pathname, filename);
            app.EditField_19.Value = app.logDataPath;
            app.lasSrcFilename = filename;
            
            % read las
            [ app.lasData, app.lasHeader, app.lasDetails ] = ImportLAS(filename);
            
            % get all decays and eliminate all ADEC and depth with ADEC[1] <= 0
            i_ADEC = ~cellfun('isempty', regexp(app.lasHeader, '^ADEC\[\d+\]$', 'match'));
            ADEC = app.lasData(:, i_ADEC);
            ADEC = ADEC(:, 1);
            nozeroNum = find(ADEC > 0, 1);
            app.ADEC = app.lasData(nozeroNum : end, i_ADEC);
            % get all decays cont.
            app.lasDepth = app.lasData(nozeroNum : end, strcmp(app.lasHeader, 'Depth') | strcmp(app.lasHeader, 'DEPT'));
            app.lasDepth = app.lasDepth(:, 1);
            app.testMode = app.lasData(nozeroNum : end, strcmp(app.lasHeader, 'MODE'));
            
            function [ data, header, detail ] = ImportLAS( filename )
                %ImportLAS Import .las file
                % Input:
                %   filename    target file name
                %
                % Output:
                %   data        numeric data
                %   header      header of data
                %   detail      structure of more detailed info
                
                %% File Operations
                % Open the text file.
                fileID = fopen(filename, 'r');
                
                % read in las as space delimited string
                formatSpec = '%s%[^\n\r]';
                
                % Read first 1000 lines to find header
                textArray = textscan(fileID, formatSpec, 1000, 'Delimiter', ' ', ...
                    'MultipleDelimsAsOne', true,  'ReturnOnError', false);
                
                % find '~A' as the header line start, '#' for additional header lines
                i_labeledheader = find(strcmp(textArray{1}, '~A') | ...
                    strcmp(textArray{1}, '#'));
                
                % read header lines
                i_header = GetTureHeader(textArray{2}(i_labeledheader), i_labeledheader);
                [header, n_col] = Block2Vector(textArray{2}(i_header), 'string');
                
                % Read in data block
                frewind(fileID);
                formatSpec = '%[^\n\r]';
                dataArray = textscan(fileID, formatSpec, 'Delimiter', ' ', ...
                    'MultipleDelimsAsOne', true, 'HeaderLines', i_labeledheader(end), ...
                    'ReturnOnError', false);
                
                % Close the text file.
                fclose(fileID);
                
                %% Data Output
                % parse data block
                row_block = length(i_header);
                col_total = length(header);
                raw = dataArray{1};
                n_depth = length(raw) / row_block;
                
                % read data
                data = zeros(n_depth, col_total);
                for i = 1:n_depth
                    % read data lines by block
                    [ data_depth, ~ ] = Block2Vector(raw((i - 1) * row_block + ...
                        (1:row_block)), 'float');
                    data(i, :) = data_depth;
                end
                
                % build detail structure for output
                detail = struct;
                detail.i_datastart = i_labeledheader(end) + 1;
                detail.n_col = n_col;
                
            end
            
            function [ i_trueheader ] = GetTureHeader( data, i_header )
                %GetTureHeader Remove empty lines of labeled header
                % Input:
                %   data            input data, n x 1 cell
                %   i_header        labeled header lines
                %
                % Output:
                %   i_trueheader    filled header lines
                
                i_trueheader = zeros(size(i_header));
                len = size(data, 1);
                
                index = 1;
                for i = 1:len
                    line = strtrim(data{i});
                    if isempty(line)
                        continue;
                    end
                    i_trueheader(index) = i_header(i);
                    index = index + 1;
                end
                
                i_trueheader = i_trueheader(1:(index - 1));
                
            end
            
            function [ output, n_col ] = Block2Vector( data, option )
                %Block2Vector Convert block data to 1 dimensional vector
                % Input:
                %   data        input data, n x 1 cell
                %   option      'string' or 'float'
                %
                % Output:
                %   output      output data, 1 x m vector
                %   n_col       column size, n x 1 vector
                
                if strcmp(option, 'string')
                    formatSpec = '%s';
                    output = cell(1, 200);
                    type = 1;
                else
                    formatSpec = '%f';
                    output = zeros(1, 200);
                    type = 2;
                end
                
                len = size(data, 1);
                n_col = zeros(len, 1);
                index = 0;
                for i = 1:len
                    if type == 1
                        line = strsplit(strtrim(data{i}));
                        n = size(line, 2);
                    else
                        [line, n] = sscanf(data{i}, formatSpec);
                    end
                    
                    output(index + (1:n)) = line;
                    index = index + n;
                    n_col(i) = n;
                end
                
                if index > 0
                    output = output(1:index);
                else
                    output = [];
                    n_col = [];
                end
                
            end
            % turn lamp red
            app.NomCalibratedLamp.Color = "red";
            
            % plot sensor flag
            app.plotsensorFLag = 4;
        end

        % Button pushed function: LoadINIFileButton
        function LoadINIFileButtonPushed(app, event)
            % read configuration file
            [app.configFileName, pathname] = uigetfile({'*.ini'}, 'Load Configuration');
            if isequal(app.configFileName, 0) || isequal(pathname, 0)
                errordlg("No file selected", 'Error');
            end
            
            app.configPath = strcat(pathname, app.configFileName);
            app.EditField_20.Value = app.configPath;
            
        end

        % Button pushed function: DisplayWindowButton
        function DisplayWindowButtonPushed(app, event)
            % depth selection
            if app.EditField_66.Value <= app.depth_min | app.EditField_66.Value >= app.depth_max
                errordlg("Depth of Nominal out of range", 'Error');
                return
            end
            
            depth_min = app.EditField_18.Value - (app.EditField_17.Value/2);
            depth_max = app.EditField_18.Value + (app.EditField_17.Value/2);
            if depth_min <= app.depth_min | depth_max >= app.depth_max
                errordlg("Depth of Interest out of range", 'Error');
                return
            end
            
            i_Depth = zeros(size(app.lasDepth, 1), 1); % logic array for selected section (all depth as required)
            for d = 1:size(app.lasDepth, 1)
                if app.lasDepth(d) >= depth_min && app.lasDepth(d) <= depth_max
                    i_Depth(d) = 1;
                end
            end
            i_Depth = logical(i_Depth);
            
            % store selected ADEC in a varible for selected depth
            app.selected_ADEC = app.ADEC(i_Depth, :);
            
            %%% get config information
            selected_Mode = app.testMode(i_Depth, :);
            app.ModeUsed = selected_Mode(1, 1);
            
            % Read ePDT ini file for certain mode
            % Input:
            %   app.configFileName    target file name
            %   testMode              selected config mode from .las file
            %
            % Output:
            %   status      success or not
            %   app.testConfig  config contents
            
            status = false;
            cfg_str = sprintf('[Config_%d]', app.ModeUsed);
            
            app.testConfig.configname = '';
            app.testConfig.cmdcode = '';
            app.testConfig.sensor = false(1, 3);      % enable status for S, M and L
            app.testConfig.txcurrent = zeros(1, 3);
            app.testConfig.txduration = zeros(1, 3);
            app.testConfig.chnum = zeros(1, 3);
            app.testConfig.chgain = cell(1, 3);       % unknown total channel number
            app.testConfig.chstarttime = [];
            app.testConfig.chendtime = [];
            app.testConfig.acq_idx_start = zeros(1, 3);
            app.testConfig.acq_idx_end = zeros(1, 3);
            app.testConfig.firingsequences = '';
            app.testConfig.fullsampletime = zeros(1, 3);
            app.testConfig.loggingspeed = 0;
            app.testConfig.adec_idx_start = zeros(1, 3);
            app.testConfig.adec_idx_end = zeros(1, 3);
            app.testConfig.chdelaytime = 20;          % defaulted to 20
            app.testConfig.turns = zeros(1, 3);
            app.testConfig.n_ch = 0;
            
            fid = fopen(app.configFileName); %% app.configFileName is the name of selected ini file
            while ~feof(fid)
                line = fgetl(fid);
                if strcmp(line, cfg_str)    % locate the correct config
                    while ~feof(fid) && ~isempty(line)
                        line = fgetl(fid);  % read line by line
                        paras = strsplit(line, '=');
                        switch paras{1}
                            case 'ConfigName'
                                app.testConfig.configname = paras{2};
                            case 'CmdCode'
                                app.testConfig.cmdcode = paras{2};
                            case 'SensorS'
                                app.testConfig.sensor(1) = strcmpi(paras{2}, 'true');
                            case 'SensorM'
                                app.testConfig.sensor(2) = strcmpi(paras{2}, 'true');
                            case 'SensorL'
                                app.testConfig.sensor(3) = strcmpi(paras{2}, 'true');
                            case 'TxCurrentS'
                                app.testConfig.txcurrent(1) = str2double(paras{2});
                            case 'TxDurationS'
                                app.testConfig.txduration(1) = str2double(paras{2});
                            case 'TxCurrentM'
                                app.testConfig.txcurrent(2) = str2double(paras{2});
                            case 'TxDurationM'
                                app.testConfig.txduration(2) = str2double(paras{2});
                            case 'TxCurrentL'
                                app.testConfig.txcurrent(3) = str2double(paras{2});
                            case 'TxDurationL'
                                app.testConfig.txduration(3) = str2double(paras{2});
                            case 'ChNumS'
                                app.testConfig.chnum(1) = str2double(paras{2});
                            case 'ChGainS'
                                app.testConfig.chgain{1} = str2double(strsplit(paras{2}, ','));
                            case 'ChNumM'
                                app.testConfig.chnum(2) = str2double(paras{2});
                            case 'ChGainM'
                                app.testConfig.chgain{2} = str2double(strsplit(paras{2}, ','));
                            case 'ChNumL'
                                app.testConfig.chnum(3) = str2double(paras{2});
                            case 'ChGainL'
                                app.testConfig.chgain{3} = str2double(strsplit(paras{2}, ','));
                            case 'ChStartTime'
                                app.testConfig.chstarttime = str2double(strsplit(paras{2}, ','));
                            case 'ChEndTime'
                                app.testConfig.chendtime = str2double(strsplit(paras{2}, ','));
                            case 'StartIdxSensorS'
                                app.testConfig.acq_idx_start(1) = str2double(paras{2});
                            case 'EndIdxSensorS'
                                app.testConfig.acq_idx_end(1) = str2double(paras{2});
                            case 'StartIdxSensorM'
                                app.testConfig.acq_idx_start(2) = str2double(paras{2});
                            case 'EndIdxSensorM'
                                app.testConfig.acq_idx_end(2) = str2double(paras{2});
                            case 'StartIdxSensorL'
                                app.testConfig.acq_idx_start(3) = str2double(paras{2});
                            case 'EndIdxSensorL'
                                app.testConfig.acq_idx_end(3) = str2double(paras{2});
                            case 'FiringSequences'
                                app.testConfig.firingsequences = paras{2};
                            case 'FullSampleTimeS'
                                app.testConfig.fullsampletime(1) = str2double(paras{2});
                            case 'FullSampleTimeM'
                                app.testConfig.fullsampletime(2) = str2double(paras{2});
                            case 'FullSampleTimeL'
                                app.testConfig.fullsampletime(3) = str2double(paras{2});
                            case 'LoggingSpeed'
                                app.testConfig.loggingspeed = str2double(paras{2});
                            case 'ADEC_S_START'
                                app.testConfig.adec_idx_start(1) = str2double(paras{2});
                            case 'ADEC_S_END'
                                if app.testConfig.adec_idx_start(1) == 0
                                    app.testConfig.adec_idx_end(1) = -1;
                                else
                                    app.testConfig.adec_idx_end(1) = str2double(paras{2});
                                end
                            case 'ADEC_M_START'
                                app.testConfig.adec_idx_start(2) = str2double(paras{2});
                            case 'ADEC_M_END'
                                if app.testConfig.adec_idx_start(2) == 0
                                    app.testConfig.adec_idx_end(2) = -1;
                                else
                                    app.testConfig.adec_idx_end(2) = str2double(paras{2});
                                end
                            case 'ADEC_L_START'
                                app.testConfig.adec_idx_start(3) = str2double(paras{2});
                            case 'ADEC_L_END'
                                if app.testConfig.adec_idx_start(3) == 0
                                    app.testConfig.adec_idx_end(3) = -1;
                                else
                                    app.testConfig.adec_idx_end(3) = str2double(paras{2});
                                end
                            case 'ChDelayTime'
                                app.testConfig.chdelaytime = str2double(paras{2});
                            case 'TurnsS'
                                app.testConfig.turns(1) = str2double(paras{2});
                            case 'TurnsM'
                                app.testConfig.turns(2) = str2double(paras{2});
                            case 'TurnsL'
                                app.testConfig.turns(3) = str2double(paras{2});
                        end
                    end
                    
                    n_ch = sum(app.testConfig.adec_idx_end - app.testConfig.adec_idx_start + 1);
                    if length(app.testConfig.chstarttime) ~= length(app.testConfig.chendtime) || ...
                            n_ch > length(app.testConfig.chstarttime)
                        break;
                    end
                    
                    app.testConfig.n_ch = n_ch;
                    status = true;
                    break;
                end
            end
            fclose(fid);
            
            %%% get, degain and plot max, min, med and plot
            medianArr = ones(1, size(app.selected_ADEC, 2));
            linearArr = ones(1, size(app.selected_ADEC, 2));
            linearIdx = ones(1, size(app.selected_ADEC, 2));
            
            %             % assign 85% - 55% to nom
            %             ADEC_section = sort(app.selected_ADEC, 'ascend');
            %             linearIdx(1) = 0.01 * 85;
            %             linearIdx(end) = 0.01 * 60;
            %             linearIdx_step = (linearIdx(1) - linearIdx(end)) / (size(linearIdx, 2) - 1);
            %             for i = 1:size(app.selected_ADEC, 2)
            %                 linearIdx(i) = linearIdx(1) - (i - 1) * linearIdx_step;
            %             end
            %             for CHIDX = 1:size(app.selected_ADEC, 2)
            %                 abc = floor(linearIdx(CHIDX) * size(app.selected_ADEC, 1));
            %                 if abc == 0
            %                     abc = 1;
            %                 else
            %                     linearArr(1, CHIDX) = ADEC_section(abc, CHIDX);
            %                 end
            %             end
            
            % assign ADEC at selected depth to median
            [~, i_depth_nom] = min(abs(app.lasDepth - app.EditField_66.Value));
            if app.lasDepth(i_depth_nom + 3) >= app.depth_min
                linearArr = mean(app.ADEC(i_depth_nom-3:i_depth_nom+3, :));
            elseif app.lasDepth(i_depth_nom + 3) < app.depth_min
                linearArr = mean(app.ADEC(i_depth_nom-4:i_depth_nom, :));
            end
            app.log_Med_lin = linearArr ./ app.degain;
            %app.log_Med_lin = correctdecay(app.log_Med_lin, 0, app.testConfig);
            
            % get, degain and plot max, min, med
            app.FM_x = 1:size(app.log_Med_lin, 2);
            window_Gain = app.testConfig.chendtime - app.testConfig.chstarttime;
            coilTurn_Gain = ones(size(window_Gain));
            for CH_coil = 1:size(app.testConfig.chstarttime, 2)
                if CH_coil >= app.testConfig.adec_idx_start(1) && CH_coil <= app.testConfig.adec_idx_end(1) &&...
                        app.testConfig.adec_idx_start(1) < app.testConfig.adec_idx_end(1)
                    coilTurn_Gain(CH_coil) = 1 / app.testConfig.turns(1);
                elseif CH_coil >= app.testConfig.adec_idx_start(2) && CH_coil <= app.testConfig.adec_idx_end(2) &&...
                        app.testConfig.adec_idx_start(2) < app.testConfig.adec_idx_end(2)
                    coilTurn_Gain(CH_coil) = 1 / app.testConfig.turns(2);
                elseif CH_coil >= app.testConfig.adec_idx_start(3) && CH_coil <= app.testConfig.adec_idx_end(3) &&...
                        app.testConfig.adec_idx_start(3) < app.testConfig.adec_idx_end(3)
                    coilTurn_Gain(CH_coil) = 1 / app.testConfig.turns(3);
                end
            end
            analog_Gain = 0.175 * ones(size(window_Gain)); % as long as analog_Gain is 0.175
            app.log_Max = max(app.selected_ADEC) ./ (window_Gain .* coilTurn_Gain .* analog_Gain);
            app.log_Min = min(app.selected_ADEC) ./ (window_Gain .* coilTurn_Gain .* analog_Gain);
            
            % store max and min log values before magnified with indexs
            app.log_Max_perm = app.log_Max;
            app.log_Min_perm = app.log_Min;
            
            % plot three lines
            cla(app.UIAxes);
            leg1 = legend(app.UIAxes);
            leg1.AutoUpdate = 'on'; % turn auto update length on
            
            % get sensor flag according to dropdown value
            if app.SensorDropDown.Value == 4
                app.plotsensorFLag = 4;
            elseif app.SensorDropDown.Value == 1
                app.plotsensorFLag = 1;
            elseif app.SensorDropDown.Value == 2
                app.plotsensorFLag = 2;
            elseif app.SensorDropDown.Value == 3
                app.plotsensorFLag = 3;
            end
            
            % sensor index will be according to sensorflag
            switch app.plotsensorFLag
                case 4
                    istart_ch = app.testConfig.adec_idx_start(1);
                    iend_ch = max(app.testConfig.adec_idx_end(:));
                case 1
                    istart_ch = app.testConfig.adec_idx_start(1);
                    iend_ch = app.testConfig.adec_idx_end(1);
                case 2
                    istart_ch = app.testConfig.adec_idx_start(2);
                    iend_ch = app.testConfig.adec_idx_end(2);
                case 3
                    istart_ch = app.testConfig.adec_idx_start(3);
                    iend_ch = app.testConfig.adec_idx_end(3);
            end
            
            if ~isempty(app.log_Med_lin)
                log_Med_lin = app.log_Med_lin;
                
                if ~isempty(app.log_Med_lin) && (app.NomCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), log_Med_lin(istart_ch:iend_ch), 'g', 'LineWidth', 0.7, 'DisplayName', 'Nom');
                elseif ~isempty(app.log_Med_lin) && (app.NomCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(log_Med_lin, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'g', 'LineWidth', 0.7, 'DisplayName', 'Nom');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            if ~isempty(app.log_Max)
                log_Max = app.log_Max;
                if ~isempty(app.log_Max) && (app.MaxCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), log_Max(istart_ch:iend_ch), 'r', 'LineWidth', 0.7, 'DisplayName', 'Max');
                elseif ~isempty(app.log_Max) && (app.MaxCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(log_Max, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'r', 'LineWidth', 0.7, 'DisplayName', 'Max');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            if ~isempty(app.log_Min)
                log_Min = app.log_Min;
                if ~isempty(app.log_Min) && (app.MinCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), log_Min(istart_ch:iend_ch), 'b', 'LineWidth', 0.7, 'DisplayName', 'Min');
                elseif ~isempty(app.log_Min) && (app.MinCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(log_Min, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'b', 'LineWidth', 0.7, 'DisplayName', 'Min');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            % if sectional line box checked, show sectional lines
            if ~isempty(app.log_med_sect)
                log_med_sect = app.log_med_sect;
                if ~isempty(app.log_med_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), log_med_sect(istart_ch:iend_ch), 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Nom');
                elseif ~isempty(app.log_med_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(log_med_sect, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'Color', 'k', 'LineStyle', '-.', 'LineWidth', 0.5,'DisplayName', 'Sect-Nom');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            if ~isempty(app.log_Max_sect)
                log_Max_sect = app.log_Max_sect;
                if ~isempty(app.log_Max_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), log_Max_sect(istart_ch:iend_ch), 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Max');
                elseif ~isempty(app.log_Max_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(log_Max_sect, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Max');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            if ~isempty(app.log_Min_sect)
                log_Min_sect = app.log_Min_sect;
                if ~isempty(app.log_Min_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), log_Min_sect(istart_ch:iend_ch), 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Min');
                elseif ~isempty(app.log_Min_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(log_Min_sect, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Min');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            leg1 = legend(app.UIAxes);
            
            % logy scale
            if app.LogYCheckBox.Value == 1
                set(app.UIAxes, 'YScale', 'log');
            else
                set(app.UIAxes, 'YScale', 'linear');
            end
            
            % plot CHs for time display
            leg1.AutoUpdate = 'off'; % cancel autoupdate of legend
            if (app.PlotinTimeCheckBox.Value == 1) && (app.DiaplayCHCheckBox.Value == 1)
                for n = 1:size(app.testConfig.chendtime, 2)
                    if mod(n, 5) == 0
                        xline(app.UIAxes, app.testConfig.chendtime(n), ':', n, 'LabelHorizontalAlignment', 'center');
                    end
                end
            end
            
            %%% get well schematic of target depth for FM
            % read OD, TK for FM according to selected depth
            app.n_p = 0;
            app.od_p = 0;
            app.tk_p = 0;
            app.pipe = 0;
            g = 0;
            for i = 1:length(app.pipeList)
                if app.pipeList(i).TopDepth <= app.EditField_18.Value && app.pipeList(i).BottomDepth >= app.EditField_18.Value
                    g = g + 1;
                    app.n_p = g;%app.pipeList(i).ItemID;
                    app.od_p(g) = app.pipeList(i).NomOD * 25.4;
                    app.tk_p(g) = app.pipeList(i).NomThickness * 25.4;
                    app.pipe(g) = i;
                end
            end
            
            % only activate pipe ML input for activated pipes
            set(app.EditField_43, 'Enable', 'off');
            set(app.EditField_15, 'Enable', 'off');
            set(app.EditField_56, 'Enable', 'off');
            
            set(app.EditField_42, 'Enable', 'off');
            set(app.EditField_14, 'Enable', 'off');
            set(app.EditField_55, 'Enable', 'off');
            
            set(app.EditField_44, 'Enable', 'off');
            set(app.EditField_16, 'Enable', 'off');
            set(app.EditField_57, 'Enable', 'off');
            
            set(app.EditField_45, 'Enable', 'off');
            set(app.EditField_24, 'Enable', 'off');
            set(app.EditField_58, 'Enable', 'off');
            
            set(app.EditField_25, 'Enable', 'off');
            set(app.EditField_46, 'Enable', 'off');
            set(app.EditField_59, 'Enable', 'off');
            
            set(app.EditField_64, 'Enable', 'off');
            set(app.EditField_63, 'Enable', 'off');
            set(app.EditField_65, 'Enable', 'off');
            
            if size(app.pipe(:), 1) >= 1
                set(app.EditField_43, 'Enable', 'on');
                set(app.EditField_15, 'Enable', 'on');
                set(app.EditField_56, 'Enable', 'on');
            end
            if (size(app.pipe(:), 1) >= 2)
                set(app.EditField_42, 'Enable', 'on');
                set(app.EditField_14, 'Enable', 'on');
                set(app.EditField_55, 'Enable', 'on');
            end
            if (size(app.pipe(:), 1) >= 3)
                set(app.EditField_44, 'Enable', 'on');
                set(app.EditField_16, 'Enable', 'on');
                set(app.EditField_57, 'Enable', 'on');
            end
            if (size(app.pipe(:), 1) >= 4)
                set(app.EditField_45, 'Enable', 'on');
                set(app.EditField_24, 'Enable', 'on');
                set(app.EditField_58, 'Enable', 'on');
            end
            if (size(app.pipe(:), 1) >= 5)
                set(app.EditField_25, 'Enable', 'on');
                set(app.EditField_46, 'Enable', 'on');
                set(app.EditField_59, 'Enable', 'on');
            end
            if (app.pipe(:) == 6)
                set(app.EditField_64, 'Enable', 'on');
                set(app.EditField_63, 'Enable', 'on');
                set(app.EditField_65, 'Enable', 'on');
            end
            
        end

        % Button pushed function: LoadWellSchematicButton
        function LoadWellSchematicButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.xml'}, 'Load Well Schematics');
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg("No file selected", 'Error');
            end
            
            app.wellSchemPath = strcat(pathname, filename);
            app.EditField_23.Value = app.wellSchemPath;
            
            % Import well schematic in xml file
            % Input:
            %   filename    target file name
            %
            % Output:
            %   pipeList    pipe list
            
            % labels to read
            labels = {'ItemID', 'TopDepth', 'BottomDepth', 'NomOD', 'NomThickness'};
            
            % as = fullfile(pathname, filename);
            % read xml file
            xDoc = xmlread(filename);
            
            % find all pipe section
            allListitems = xDoc.getElementsByTagName('EMDSPipeSection');
            
            % get listnode information
            for i = 1:allListitems.getLength
                thisListitem = allListitems.item(i - 1);
                
                % read the labels
                for j = 1:length(labels)
                    thisList = thisListitem.getElementsByTagName(labels{j});
                    thisElement = thisList.item(0);
                    app.pipeList(i).(labels{j}) = ...
                        str2double(thisElement.getFirstChild.getData);
                end
            end
            
            % generate a string for the dropdown button
            depths = unique([app.pipeList.TopDepth, app.pipeList.BottomDepth]);
            app.sectionList = strings(1, (length(depths) - 1));
            for j = 1:(length(depths) - 1)
                n = 0;
                % use median of section depth to count pipe number
                depthmid = mean([depths(j), depths(j + 1)]);
                for k = 1 : app.pipeList(end).ItemID
                    if depthmid >= app.pipeList(k).TopDepth && depthmid <= app.pipeList(k).BottomDepth
                        n = n + 1;
                    end
                end
                pipeNum = num2str(n);
                app.sectionList(j) = strcat(pipeNum, " Pipes Section");
            end
            
            app.SectionsDropDown.Items = app.sectionList;
            app.SectionsDropDown.ItemsData = 1 : length(app.SectionsDropDown.Items);
            
        end

        % Button pushed function: CalculateWLButton
        function CalculateWLButtonPushed(app, event)
            % warning if nom not calibrated
            if app.flag_nomcalib == 0
                errordlg("Nom is not calibrated", "Error");
                return
            end
            
            % warning if collar data less than nom data
            if strcmp(app.EditField_56.Enable, 'on')
                [~, i_depth_collar] = min(abs(app.lasDepth - app.EditField_56.Value));
                collarArr = mean(app.ADEC(i_depth_collar-3:i_depth_collar+3, :));
                log_Collar = collarArr ./ app.degain;
                flag_comp = log_Collar < app.log_Med_lin;
                window_temp = ceil(max(app.testConfig.adec_idx_end)/20);
                if sum(flag_comp(app.EditField_43.Value:max(app.testConfig.adec_idx_end))) > window_temp
                    errordlg("Please correct pipe 1 collar or CH input, possible problem:" + newline...
                        + "Min of selected collar depth is less than Nom after input CH", 'Error');
                    return
                end                
            end
            if strcmp(app.EditField_55.Enable, 'on')
                [~, i_depth_collar] = min(abs(app.lasDepth - app.EditField_55.Value));
                collarArr = mean(app.ADEC(i_depth_collar-3:i_depth_collar+3, :));
                log_Collar = collarArr ./ app.degain;
                flag_comp = log_Collar < app.log_Med_lin;
                window_temp = ceil(max(app.testConfig.adec_idx_end)/20);
                if sum(flag_comp(app.EditField_42.Value:max(app.testConfig.adec_idx_end))) > window_temp
                    errordlg("Please correct pipe 2 collar or CH input, possible problem:" + newline...
                        + "Min of selected collar depth is less than Nom after input CH", 'Error');
                    return
                end                
            end
            if strcmp(app.EditField_57.Enable, 'on')
                [~, i_depth_collar] = min(abs(app.lasDepth - app.EditField_57.Value));
                collarArr = mean(app.ADEC(i_depth_collar-3:i_depth_collar+3, :));
                log_Collar = collarArr ./ app.degain;
                flag_comp = log_Collar < app.log_Med_lin;
                window_temp = ceil(max(app.testConfig.adec_idx_end)/20);
                if sum(flag_comp(app.EditField_44.Value:max(app.testConfig.adec_idx_end))) > window_temp
                    errordlg("Please correct pipe 3 collar or CH input, possible problem:" + newline...
                        + "Min of selected collar depth is less than Nom after input CH", 'Error');
                    return
                end                
            end
            if strcmp(app.EditField_58.Enable, 'on')
                [~, i_depth_collar] = min(abs(app.lasDepth - app.EditField_58.Value));
                collarArr = mean(app.ADEC(i_depth_collar-3:i_depth_collar+3, :));
                log_Collar = collarArr ./ app.degain;
                flag_comp = log_Collar < app.log_Med_lin;
                window_temp = ceil(max(app.testConfig.adec_idx_end)/20);
                if sum(flag_comp(app.EditField_45.Value:max(app.testConfig.adec_idx_end))) > window_temp
                    errordlg("Please correct pipe 4 collar or CH input, possible problem:" + newline...
                        + "Min of selected collar depth is less than Nom after input CH", 'Error');
                    return
                end                
            end
            if strcmp(app.EditField_59.Enable, 'on')
                [~, i_depth_collar] = min(abs(app.lasDepth - app.EditField_59.Value));
                collarArr = mean(app.ADEC(i_depth_collar-3:i_depth_collar+3, :));
                log_Collar = collarArr ./ app.degain;
                flag_comp = log_Collar < app.log_Med_lin;
                window_temp = ceil(max(app.testConfig.adec_idx_end)/20);
                if sum(flag_comp(app.EditField_46.Value:max(app.testConfig.adec_idx_end))) > window_temp
                    errordlg("Please correct pipe 5 collar or CH input, possible problem:" + newline...
                        + "Min of selected collar depth is less than Nom after input CH", 'Error');
                    return
                end                
            end
            if strcmp(app.EditField_65.Enable, 'on')
                [~, i_depth_collar] = min(abs(app.lasDepth - app.EditField_65.Value));
                collarArr = mean(app.ADEC(i_depth_collar-3:i_depth_collar+3, :));
                log_Collar = collarArr ./ app.degain;
                flag_comp = log_Collar < app.log_Med_lin;
                window_temp = ceil(max(app.testConfig.adec_idx_end)/20);
                if sum(flag_comp(app.EditField_64.Value:max(app.testConfig.adec_idx_end))) > window_temp
                    errordlg("Please correct pipe 6 collar or CH input, possible problem:" + newline...
                        + "Min of selected collar depth is less than Nom after input CH", 'Error');
                    return
                end                
            end
            
            
            %%% find data of nominal, and all clean collars
            idx_depth = zeros(1, app.n_p);
            iii = 1;
            if app.EditField_56.Enable == "on"
                if app.EditField_56.Value <= app.depth_min | app.EditField_56.Value >= app.depth_max
                    errordlg("One depth out of range", 'Error');
                    return
                end
                cleanCollarDepth(iii) = app.EditField_56.Value;
                iii = iii + 1;
            end
            if app.EditField_55.Enable == "on"
                if app.EditField_55.Value <= app.depth_min | app.EditField_55.Value >= app.depth_max
                    errordlg("One depth out of range", 'Error');
                    return
                end
                cleanCollarDepth(iii) = app.EditField_55.Value;
                iii = iii + 1;
            end
            if app.EditField_57.Enable == "on"
                if app.EditField_57.Value <= app.depth_min | app.EditField_57.Value >= app.depth_max
                    errordlg("One depth out of range", 'Error');
                    return
                end
                cleanCollarDepth(iii) = app.EditField_57.Value;
                iii = iii + 1;
            end
            if app.EditField_58.Enable == "on"
                if app.EditField_58.Value <= app.depth_min | app.EditField_58.Value >= app.depth_max
                    errordlg("One depth out of range", 'Error');
                    return
                end
                cleanCollarDepth(iii) = app.EditField_58.Value;
                iii = iii + 1;
            end
            if app.EditField_59.Enable == "on"
                if app.EditField_59.Value <= app.depth_min | app.EditField_59.Value >= app.depth_max
                    errordlg("One depth out of range", 'Error');
                    return
                end
                cleanCollarDepth(iii) = app.EditField_59.Value;
                iii = iii + 1;
            end
            if app.EditField_65.Enable == "on"
                if app.EditField_65.Value <= app.depth_min | app.EditField_65.Value >= app.depth_max
                    errordlg("One depth out of range", 'Error');
                    return
                end
                cleanCollarDepth(iii) = app.EditField_65.Value;
                iii = iii + 1;
            end
            %             cleanCollarDepth = cleanCollarDepth(1:app.n_p);
            cleanCollarADEC = zeros(app.n_p, size(app.ADEC, 2));
            for i = 1:app.n_p
                [~, idx_depth(i)] = min(abs(app.lasDepth - cleanCollarDepth(i)));
                cleanCollarADEC(i, :) = app.ADEC(idx_depth(i), :) ./ app.degain;
            end
            
            %%% FM calculationg with ABCD
            % data initialization for FM
            app.sig_p = ones(1, app.n_p) * 7; %app.EditField_2.Value;
            app.sig_p(1) = 7; %app.EditField.Value;
            app.mur_p = ones(1, app.n_p) * 110; %app.EditField_3.Value;
            app.mur_p(1) = 110; %app.EditField_4.Value;
            
            % run FM to calculate thin or thick situation and max situation (45% gain for all pipes)
            % assign app.tk_p with input thickness change
            jj = 1;
            if (size(app.pipe(:), 1) >= 1)
                tk_perc(jj) = 0.01 * app.EditField_15.Value;
                pickedCH(jj) = app.EditField_43.Value;
                jj = jj + 1;
            end
            if (size(app.pipe(:), 1) >= 2)
                tk_perc(jj) = 0.01 * app.EditField_14.Value;
                pickedCH(jj) = app.EditField_42.Value;
                jj = jj + 1;
            end
            if (size(app.pipe(:), 1) >= 3)
                tk_perc(jj) = 0.01 * app.EditField_16.Value;
                pickedCH(jj) = app.EditField_44.Value;
                jj = jj + 1;
            end
            if (size(app.pipe(:), 1) >= 4)
                tk_perc(jj) = 0.01 * app.EditField_24.Value;
                pickedCH(jj) = app.EditField_45.Value;
                jj = jj + 1;
            end
            if (size(app.pipe(:), 1) >= 5)
                tk_perc(jj) = 0.01 * app.EditField_25.Value;
                pickedCH(jj) = app.EditField_46.Value;
                jj = jj + 1;
            end
            if (size(app.pipe(:), 1) >= 6)
                tk_perc(jj) = 0.01 * app.EditField_63.Value;
                pickedCH(jj) = app.EditField_64.Value;
                jj = jj + 1;
            end
            
            app.tk_pThin = (1 - tk_perc) .* app.tk_p;
            % max situation
            tk_pMax = 1.5 * app.tk_p;
            
            % when WL is 0, use calibrated nom
            % this is verified as true when using 0 WL
            flag_nom = 1;
            if any(tk_perc~=0)
                flag_nom = 0;
            end
            
            % calculate three sensor FM data, assign to varibles and channeling
            n_ch = max(app.testConfig.adec_idx_end);
            FM_ch = zeros(1, n_ch);
            coils = 'SML';
            FM_calib = zeros(app.n_p+1, size(app.ADEC, 2));
            
            % loop through all pipe calculate FM and apply abcd
            msgbox("Calculating all sensor");
            for n = 1:(app.n_p+1)
                if n > app.n_p
                    tk_current = app.tk_pThin;
                else
                    tk_current = app.tk_p;
                    tk_current(n) = app.tk_p(n) * 1.5;
                end
                for i = 1:3
                    if i == 1 && app.testConfig.sensor(i)
                        
                        % calculate thin
                        v_ms = CalcePDTBSegMuAvgEMF(app.n_p, app.od_p, tk_current, coils(i), app.sig_p, app.mur_p, ...
                            app.testConfig.txcurrent(i), app.testConfig.txduration(i), app.testConfig.chdelaytime, ...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                        FM_current_linS = v_ms((app.testConfig.chstarttime(app.testConfig.adec_idx_start(i))+1) : ...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                        for j = app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)
                            FM_ch(j) = mean(v_ms(...
                                (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                        end
                    elseif i == 2 && app.testConfig.sensor(i)
                        % calculate thin
                        v_ms = CalcePDTBSegMuAvgEMF(app.n_p, app.od_p, tk_current, coils(i), app.sig_p, app.mur_p, ...
                            app.testConfig.txcurrent(i), app.testConfig.txduration(i), app.testConfig.chdelaytime, ...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                        FM_current_linM = v_ms((app.testConfig.chstarttime(app.testConfig.adec_idx_start(i))+1) : ...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                        for j = app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)
                            FM_ch(j) = mean(v_ms(...
                                (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                        end
                    elseif i == 3 && app.testConfig.sensor(i)
                        % calculate thin
                        v_ms = CalcePDTBSegMuAvgEMF(app.n_p, app.od_p, tk_current, coils(i), app.sig_p, app.mur_p, ...
                            app.testConfig.txcurrent(i), app.testConfig.txduration(i), app.testConfig.chdelaytime, ...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                        FM_current_linL = v_ms((app.testConfig.chstarttime(app.testConfig.adec_idx_start(i))+1) : ...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(i)));
                        for j = app.testConfig.adec_idx_start(i):app.testConfig.adec_idx_end(i)
                            FM_ch(j) = mean(v_ms(...
                                (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                        end
                    end
                end
                
                % poly fit
                %%% polyfit the FM_nom in separate sensors
                % S sensor
                if app.testConfig.sensor(1) == 1
                    x_S = 1 : size(FM_current_linS, 2);
                    % polyfit thin FM
                    app.logthin_S = app.log_Med (1:app.testConfig.adec_idx_end(1));
                    FMthin_S = log(FM_current_linS);
                    app.Dt_S = app.logthin_S (1) - FMthin_S (1);
                    % fit FM thin as third order polynomial
                    emf_poly_S = polyfit(x_S, FMthin_S, 3);
                    app.Pt_S = emf_poly_S(1);
                    app.Qt_S = emf_poly_S(2);
                    app.Rt_S = emf_poly_S(3);
                    app.St_S = emf_poly_S(4);
                elseif app.testConfig.sensor(1) == 0
                    app.logthin_S = double.empty;
                    app.logmax_S = double.empty;
                end
                
                % M sensor
                if app.testConfig.sensor(2) == 1
                    x_M = 1 : size(FM_current_linM, 2);
                    app.logthin_M = app.log_Med (app.testConfig.adec_idx_start(2) : ...
                        app.testConfig.adec_idx_end(2));
                    FMthin_M = log(FM_current_linM);
                    app.Dt_M = app.logthin_M (1) - FMthin_M (1);
                    % fit FM thin as third order polynomial
                    emf_poly_M = polyfit(x_M, FMthin_M, 3);
                    app.Pt_M = emf_poly_M(1);
                    app.Qt_M = emf_poly_M(2);
                    app.Rt_M = emf_poly_M(3);
                    app.St_M = emf_poly_M(4);
                elseif app.testConfig.sensor(2) == 0
                    app.logthin_M = double.empty;
                    app.logmax_M = double.empty;
                end
                
                % L sensor
                if app.testConfig.sensor(3) == 1
                    x_L = 1 : size(FM_current_linL, 2);
                    app.logthin_L = app.log_Med (app.testConfig.adec_idx_start(3) : ...
                        app.testConfig.adec_idx_end(3));
                    FMthin_L = log(FM_current_linL);
                    app.Dt_L = app.logthin_L (1) - FMthin_L (1);
                    % fit FM thin as third order polynomial
                    emf_poly_L = polyfit(x_L, FMthin_L, 3);
                    app.Pt_L = emf_poly_L(1);
                    app.Qt_L = emf_poly_L(2);
                    app.Rt_L = emf_poly_L(3);
                    app.St_L = emf_poly_L(4);
                elseif app.testConfig.sensor(3) == 0
                    app.logthin_L = double.empty;
                    app.logmax_L = double.empty;
                end
                
                % apply abcd
                % S sensor apply parameters A B C D
                if app.testConfig.sensor(1) == 1
                    x_S = 1 : size(FM_current_linS, 2);
                    % calculate decay curve
                    P_S = app.Pt_S * (1 + app.An_S);
                    Q_S = app.Qt_S * (1 + app.Bn_S);
                    R_S = app.Rt_S * (1 + app.Cn_S);
                    S_S = app.St_S;% + app.Dn_S;
                    emf_adj_poly = [P_S, Q_S, R_S, S_S];
                    emf_adjusted = polyval(emf_adj_poly, x_S);
                    emf_adj = zeros(1, max(app.testConfig.chendtime));
                    emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(1)) + 1) :...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(1))) = emf_adjusted;
                    for j = app.testConfig.adec_idx_start(1):app.testConfig.adec_idx_end(1)
                        FM_abcd_S(j) = mean(emf_adj(...
                            (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                    end
                    
                    % make sure emf_adjusted > 0
                    for i = 1:size(FMthin_S, 2)
                        if FMthin_S(i) <= 0
                            FMthin_S(i) = 0.01;
                        end
                    end
                    FMthin_S = FMthin_S(app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1));
                    appFMthin_S = exp(FMthin_S);
                elseif app.testConfig.sensor(1) == 0
                    appFMthin_S = double.empty;
                end
                
                % M sensor finding parameters
                if app.testConfig.sensor(2) == 1
                    x_M = 1 : size(FM_current_linM, 2);
                    % calculate decay curve
                    P_M = app.Pt_M * (1 + app.An_M);
                    Q_M = app.Qt_M * (1 + app.Bn_M);
                    R_M = app.Rt_M * (1 + app.Cn_M);
                    S_M = app.St_M;% + app.Dn_M;
                    emf_adj_poly = [P_M, Q_M, R_M, S_M];
                    emf_adjusted = polyval(emf_adj_poly, x_M);
                    %emf_adj = zeros(1, max(app.testConfig.chendtime));
                    emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(2)) + 1) :...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(2))) = emf_adjusted;
                    for j = app.testConfig.adec_idx_start(2):app.testConfig.adec_idx_end(2)
                        FMthin_M(j) = mean(emf_adj(...
                            (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                    end
                    
                    % make sure emf_adjusted > 0
                    for i = 1:size(FMthin_M, 2)
                        if FMthin_M(i) <= 0
                            FMthin_M(i) = 0.01;
                        end
                    end
                    FMthin_M = FMthin_M(app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2));
                    appFMthin_M = exp(FMthin_M);
                elseif app.testConfig.sensor(2) == 0
                    appFMthin_M = double.empty;
                end
                
                % L sensor finding parameters
                if app.testConfig.sensor(3) == 1
                    x_L = 1 : size(FM_current_linL, 2);
                    % calculate decay curve
                    P_L = app.Pt_L * (1 + app.An_L);
                    Q_L = app.Qt_L * (1 + app.Bn_L);
                    R_L = app.Rt_L * (1 + app.Cn_L);
                    S_L = app.St_L;% + app.Dn_L;
                    emf_adj_poly = [P_L, Q_L, R_L, S_L];
                    emf_adjusted = polyval(emf_adj_poly, x_L);
                    %emf_adj = zeros(1, max(app.testConfig.chendtime));
                    emf_adj ((app.testConfig.chstarttime(app.testConfig.adec_idx_start(3)) + 1) :...
                        app.testConfig.chendtime(app.testConfig.adec_idx_end(3))) = emf_adjusted;
                    for j = app.testConfig.adec_idx_start(3):app.testConfig.adec_idx_end(3)
                        FMthin_L(j) = mean(emf_adj(...
                            (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2);
                    end
                    
                    % make sure emf_adjusted > 0
                    for i = 1:size(FMthin_L, 2)
                        if FMthin_L(i) <= 0
                            FMthin_L(i) = 0.01;
                        end
                    end
                    FMthin_L = FMthin_L(app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3));
                    appFMthin_L = exp(FMthin_L);
                elseif app.testConfig.sensor(3) == 0
                    appFMthin_L = double.empty;
                end
                
                % collect data
                FM_calib(n, :) = [appFMthin_S, appFMthin_M, appFMthin_L];
                
                if n > app.n_p
                    FM_thin_ms = exp(emf_adj);
                end
            end
            
            % align on each select CH(beta) and calculate ks
            aligned_log = zeros(size(cleanCollarADEC));
            aligned_FM = zeros(size(cleanCollarADEC));
            k = zeros(app.n_p, 1);
            for j = 1:app.n_p
                [aligned_log, D1, ~, ~] = align(cleanCollarADEC(j, :), app.log_Med_lin, pickedCH(j), app.testConfig);
                [aligned_FM, D2, pickedCH(j), alignCH(j, 1)] = align(FM_calib(j, :), app.log_Med_lin, pickedCH(j), app.testConfig);
                k(j, 1) = D2 / D1;
                if k(j, 1) > 10
                    k(j, 1) = 10;
                end
            end
            
            % consume k by sensor
            emf_thin_final = app.FM_nom_ms;
            l = 1;
            Nsensor = find(app.testConfig.adec_idx_end > 0, 1, 'last');
            for l = 1:Nsensor
                % s sensor
                if l == 1
                    if isempty(find(pickedCH <= app.testConfig.adec_idx_end(1)))
                        % if no alignment needed in S sensor
                        emf_temp = exp(log(app.FM_nom_ms) + app.Dn_S);
                        emf_thin_final((app.testConfig.chstarttime(app.testConfig.adec_idx_start(1)) + 1) :...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(1))) = ...
                            emf_temp((app.testConfig.chstarttime(app.testConfig.adec_idx_start(1)) + 1) :...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(1)));
                    else
                        % fill alpha(k) into blank
                        k_meanS = mean(k(find(pickedCH <= app.testConfig.adec_idx_end(1))));
                        n1 = find(pickedCH <= app.testConfig.adec_idx_end(1), 1);
                        emf_temp = cosumek(FM_thin_ms, app.FM_nom_ms, k_meanS, pickedCH(n1), alignCH(n1), app.testConfig, l);
                        emf_temp = exp(log(emf_temp) + app.Dn_S);
                        emf_thin_final((app.testConfig.chstarttime(app.testConfig.adec_idx_start(1)) + 1) :...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(1))) = ...
                            emf_temp((app.testConfig.chstarttime(app.testConfig.adec_idx_start(1)) + 1) :...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(1)));
                    end
                    anchorS = mean(emf_thin_final(...
                        (app.testConfig.chstarttime(app.testConfig.adec_idx_start(1)) + 1):...
                        (app.testConfig.chendtime(app.testConfig.adec_idx_start(1)))), 2);
                elseif l == 2
                    if isempty(pickedCH(find(pickedCH >= app.testConfig.adec_idx_start(2), 1, "first") : ...
                            (find(pickedCH <= app.testConfig.adec_idx_end(2), 1, "last"))))
                        emf_temp = exp(log(app.FM_nom_ms) + app.Dn_M);
                        emf_thin_final((app.testConfig.chstarttime(app.testConfig.adec_idx_start(2)) + 1) :...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(2))) = ...
                            emf_temp((app.testConfig.chstarttime(app.testConfig.adec_idx_start(2)) + 1) :...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(2)));
                    else
                        k_meanM = mean(k(find(pickedCH >= app.testConfig.adec_idx_start(2), 1, "first") : ...
                            (find(pickedCH <= app.testConfig.adec_idx_end(2), 1, "last"))));
                        n2 = find(pickedCH > app.testConfig.adec_idx_end(1), 1);
                        emf_temp = cosumek(FM_thin_ms, app.FM_nom_ms, k_meanM, pickedCH(n2), alignCH(n2), app.testConfig, l);
                        emf_temp = exp(log(emf_temp) + app.Dn_M);
                        emf_thin_final((app.testConfig.chstarttime(app.testConfig.adec_idx_start(2)) + 1) :...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(2))) = ...
                            emf_temp((app.testConfig.chstarttime(app.testConfig.adec_idx_start(2)) + 1) :...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(2)));
                    end
                    anchorM = mean(emf_thin_final(...
                        (app.testConfig.chstarttime(app.testConfig.adec_idx_start(2)) + 1):...
                        (app.testConfig.chendtime(app.testConfig.adec_idx_start(2)))), 2);
                elseif l == 3
                    if isempty(pickedCH(find(pickedCH >= app.testConfig.adec_idx_start(3), 1, "first") : ...
                            (find(pickedCH <= app.testConfig.adec_idx_end(3), 1, "last"))))
                        %emf_temp = exp(log(app.FM_nom_ms) + app.Dn_L);
                        emf_thin_final((app.testConfig.chstarttime(app.testConfig.adec_idx_start(3)) + 1) :...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(3))) = ...
                            emf_temp((app.testConfig.chstarttime(app.testConfig.adec_idx_start(3)) + 1) :...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(3)));
                    else
                        k_meanL = mean(k(find(pickedCH >= app.testConfig.adec_idx_start(3), 1, "first") : ...
                            (find(pickedCH <= app.testConfig.adec_idx_end(3), 1, "last"))));
                        n3 = find(pickedCH > app.testConfig.adec_idx_end(2), 1);
                        emf_temp = cosumek(FM_thin_ms, app.FM_nom_ms, k_meanM, pickedCH(n3), alignCH(n3), app.testConfig, l);
                        emf_temp = exp(log(emf_temp) + app.Dn_L);
                        emf_thin_final((app.testConfig.chstarttime(app.testConfig.adec_idx_start(3)) + 1) :...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(3))) = ...
                            emf_temp((app.testConfig.chstarttime(app.testConfig.adec_idx_start(3)) + 1) :...
                            app.testConfig.chendtime(app.testConfig.adec_idx_end(3)));
                    end
                    anchorL = mean(emf_thin_final(...
                        (app.testConfig.chstarttime(app.testConfig.adec_idx_start(3)) + 1):...
                        (app.testConfig.chendtime(app.testConfig.adec_idx_start(3)))), 2);
                end
            end
            for j = app.testConfig.adec_idx_start(1):max(app.testConfig.adec_idx_end)
                if (j>=app.testConfig.adec_idx_start(1)) && (j<=app.testConfig.adec_idx_end(1))
                    FM_thin_final(j) = mean(emf_thin_final(...
                        (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2) / ...
                        (anchorS / app.log_Min(app.testConfig.adec_idx_start(1)));
                elseif (j>=app.testConfig.adec_idx_start(2)) && (j<=app.testConfig.adec_idx_end(2))
                    FM_thin_final(j) = mean(emf_thin_final(...
                        (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2) / ...
                        (anchorM / app.log_Min(app.testConfig.adec_idx_start(2)));
                elseif (j>=app.testConfig.adec_idx_start(3)) && (j<=app.testConfig.adec_idx_end(3))
                    FM_thin_final(j) = mean(emf_thin_final(...
                        (app.testConfig.chstarttime(j) + 1):app.testConfig.chendtime(j)), 2) / ...
                        (anchorL / app.log_Min(app.testConfig.adec_idx_start(3)));
                end
                if FM_thin_final(j)<=0.01
                    FM_thin_final(j) = 0.01;
                end
            end
            
            if flag_nom == 1
                FM_thin_final = app.FMnom_adj;
            end
            
            app.FM_thin_final = FM_thin_final;
            %app.FM_thin_final = correctdecay(app.FM_thin_final, 1, 0, app.testConfig);
            msgbox("Wall loss modeling calculated.");
            
            set(app.CalibratedWLCheckBox, 'enable', 'on');
            
            
        end

        % Callback function
        function CalculateParametersButtonPushed(app, event)
            
        end

        % Callback function
        function ApplyParametersButton_2Pushed(app, event)
            
        end

        % Callback function
        function CombineButton_2Pushed(app, event)
            % connect S and M for all curves
            if app.testConfig.sensor(2) == 1
                if ~isempty(app.log_Med_lin)
                    log_Med_lin = log(app.log_Med_lin);
                    log_Med_lin(app.testConfig.adec_idx_start(2)) = log_Med_lin(app.testConfig.adec_idx_start(2) - 1) - ...
                        (log_Med_lin(app.testConfig.adec_idx_start(2) - 2) - log_Med_lin(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        log_Med_lin(i) = log(app.log_Med_lin(i)) - (log(app.log_Med_lin(app.testConfig.adec_idx_start(2))) -...
                            log_Med_lin(app.testConfig.adec_idx_start(2)));
                    end
                end
                if ~isempty(app.log_Max)
                    log_Max = log(app.log_Max);
                    log_Max(app.testConfig.adec_idx_start(2)) = log_Max(app.testConfig.adec_idx_start(2) - 1) - ...
                        (log_Max(app.testConfig.adec_idx_start(2) - 2) - log_Max(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        log_Max(i) = log(app.log_Max(i)) - (log(app.log_Max(app.testConfig.adec_idx_start(2))) -...
                            log_Max(app.testConfig.adec_idx_start(2)));
                    end
                end
                if ~isempty(app.log_Min)
                    log_Min = log(app.log_Min);
                    log_Min(app.testConfig.adec_idx_start(2)) = log_Min(app.testConfig.adec_idx_start(2) - 1) - ...
                        (log_Min(app.testConfig.adec_idx_start(2) - 2) - log_Min(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        log_Min(i) = log(app.log_Min(i)) - (log(app.log_Min(app.testConfig.adec_idx_start(2))) -...
                            log_Min(app.testConfig.adec_idx_start(2)));
                    end
                end
                if ~isempty(app.FMnom_adj)
                    FMnom_adj = log(app.FMnom_adj);
                    FMnom_adj(app.testConfig.adec_idx_start(2)) = FMnom_adj(app.testConfig.adec_idx_start(2) - 1) - ...
                        (FMnom_adj(app.testConfig.adec_idx_start(2) - 2) - FMnom_adj(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        FMnom_adj(i) = log(app.FMnom_adj(i)) - (log(app.FMnom_adj(app.testConfig.adec_idx_start(2))) -...
                            FMnom_adj(app.testConfig.adec_idx_start(2)));
                    end
                end
            end
            % connect M and L for all curves
            if app.testConfig.sensor(3) == 1
                if ~isempty(app.log_Med_lin)
                    log_Med_lin(app.testConfig.adec_idx_start(3)) = log_Med_lin(app.testConfig.adec_idx_start(3) - 1) - ...
                        (log_Med_lin(app.testConfig.adec_idx_start(3) - 2) - log_Med_lin(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        log_Med_lin(i) = log(app.log_Med_lin(i)) - (log(app.log_Med_lin(app.testConfig.adec_idx_start(3))) -...
                            log_Med_lin(app.testConfig.adec_idx_start(3)));
                    end
                end
                if ~isempty(app.log_Max)
                    log_Max(app.testConfig.adec_idx_start(3)) = log_Max(app.testConfig.adec_idx_start(3) - 1) - ...
                        (log_Max(app.testConfig.adec_idx_start(3) - 2) - log_Max(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        log_Max(i) = log(app.log_Max(i)) - (log(app.log_Max(app.testConfig.adec_idx_start(3))) -...
                            log_Max(app.testConfig.adec_idx_start(3)));
                    end
                end
                if ~isempty(app.log_Min)
                    log_Min(app.testConfig.adec_idx_start(3)) = log_Min(app.testConfig.adec_idx_start(3) - 1) - ...
                        (log_Min(app.testConfig.adec_idx_start(3) - 2) - log_Min(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        log_Min(i) = log(app.log_Min(i)) - (log(app.log_Min(app.testConfig.adec_idx_start(3))) -...
                            log_Min(app.testConfig.adec_idx_start(3)));
                    end
                end
                if ~isempty(app.FMnom_adj)
                    FMnom_adj(app.testConfig.adec_idx_start(3)) = FMnom_adj(app.testConfig.adec_idx_start(3) - 1) - ...
                        (FMnom_adj(app.testConfig.adec_idx_start(3) - 2) - FMnom_adj(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        FMnom_adj(i) = log(app.FMnom_adj(i)) - (log(app.FMnom_adj(app.testConfig.adec_idx_start(3))) -...
                            FMnom_adj(app.testConfig.adec_idx_start(3)));
                    end
                end
            end
            
            % align all connected curves at first CH
            FMnom_adj = FMnom_adj - (FMnom_adj(1) - log_Med_lin(1));
            % reverse natural log
            log_Med_lin = exp(log_Med_lin);
            log_Max = exp(log_Max);
            log_Min = exp(log_Min);
            FMnom_adj = exp(FMnom_adj);
            
            %%% plot FM_nom vs FM_x
            cla(app.UIAxes);
            % plot data
            plot(app.UIAxes, app.FM_x, log_Med_lin, 'g', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            plot(app.UIAxes, app.FM_x, log_Max, 'r', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            plot(app.UIAxes, app.FM_x, log_Min, 'b', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            plot(app.UIAxes, app.FM_x, FMnom_adj, 'Color', 'k', 'LineStyle', '-', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            leg1 = legend(app.UIAxes,{'Nom','Max','Min', 'Model'},'Location','northeast','Orientation','horizontal');
            
        end

        % Callback function
        function CombineButton_3Pushed(app, event)
            % connect S and M for all curves
            if app.testConfig.sensor(2) == 1
                if ~isempty(app.log_Med_lin)
                    log_Med_lin = log(app.log_Med_lin);
                    log_Med_lin(app.testConfig.adec_idx_start(2)) = log_Med_lin(app.testConfig.adec_idx_start(2) - 1) - ...
                        (log_Med_lin(app.testConfig.adec_idx_start(2) - 2) - log_Med_lin(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        log_Med_lin(i) = log(app.log_Med_lin(i)) - (log(app.log_Med_lin(app.testConfig.adec_idx_start(2))) -...
                            log_Med_lin(app.testConfig.adec_idx_start(2)));
                    end
                end
                if ~isempty(app.log_Max)
                    log_Max = log(app.log_Max);
                    log_Max(app.testConfig.adec_idx_start(2)) = log_Max(app.testConfig.adec_idx_start(2) - 1) - ...
                        (log_Max(app.testConfig.adec_idx_start(2) - 2) - log_Max(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        log_Max(i) = log(app.log_Max(i)) - (log(app.log_Max(app.testConfig.adec_idx_start(2))) -...
                            log_Max(app.testConfig.adec_idx_start(2)));
                    end
                end
                if ~isempty(app.log_Thin_lin)
                    log_Thin_lin = log(app.log_Thin_lin);
                    log_Thin_lin(app.testConfig.adec_idx_start(2)) = log_Thin_lin(app.testConfig.adec_idx_start(2) - 1) - ...
                        (log_Thin_lin(app.testConfig.adec_idx_start(2) - 2) - log_Thin_lin(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        log_Thin_lin(i) = log(app.log_Thin_lin(i)) - (log(app.log_Thin_lin(app.testConfig.adec_idx_start(2))) -...
                            log_Thin_lin(app.testConfig.adec_idx_start(2)));
                    end
                end
                if ~isempty(app.FMthin_adj)
                    FMthin_adj = log(app.FMthin_adj);
                    FMthin_adj(app.testConfig.adec_idx_start(2)) = FMthin_adj(app.testConfig.adec_idx_start(2) - 1) - ...
                        (FMthin_adj(app.testConfig.adec_idx_start(2) - 2) - FMthin_adj(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        FMthin_adj(i) = log(app.FMthin_adj(i)) - (log(app.FMthin_adj(app.testConfig.adec_idx_start(2))) -...
                            FMthin_adj(app.testConfig.adec_idx_start(2)));
                    end
                end
            end
            % connect M and L for all curves
            if app.testConfig.sensor(3) == 1
                if ~isempty(app.log_Med_lin)
                    log_Med_lin(app.testConfig.adec_idx_start(3)) = log_Med_lin(app.testConfig.adec_idx_start(3) - 1) - ...
                        (log_Med_lin(app.testConfig.adec_idx_start(3) - 2) - log_Med_lin(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        log_Med_lin(i) = log(app.log_Med_lin(i)) - (log(app.log_Med_lin(app.testConfig.adec_idx_start(3))) -...
                            log_Med_lin(app.testConfig.adec_idx_start(3)));
                    end
                end
                if ~isempty(app.log_Max)
                    log_Max(app.testConfig.adec_idx_start(3)) = log_Max(app.testConfig.adec_idx_start(3) - 1) - ...
                        (log_Max(app.testConfig.adec_idx_start(3) - 2) - log_Max(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        log_Max(i) = log(app.log_Max(i)) - (log(app.log_Max(app.testConfig.adec_idx_start(3))) -...
                            log_Max(app.testConfig.adec_idx_start(3)));
                    end
                end
                if ~isempty(app.log_Thin_lin)
                    log_Thin_lin(app.testConfig.adec_idx_start(3)) = log_Thin_lin(app.testConfig.adec_idx_start(3) - 1) - ...
                        (log_Thin_lin(app.testConfig.adec_idx_start(3) - 2) - log_Thin_lin(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        log_Thin_lin(i) = log(app.log_Thin_lin(i)) - (log(app.log_Thin_lin(app.testConfig.adec_idx_start(3))) -...
                            log_Thin_lin(app.testConfig.adec_idx_start(3)));
                    end
                end
                if ~isempty(app.FMthin_adj)
                    FMthin_adj(app.testConfig.adec_idx_start(3)) = FMthin_adj(app.testConfig.adec_idx_start(3) - 1) - ...
                        (FMthin_adj(app.testConfig.adec_idx_start(3) - 2) - FMthin_adj(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        FMthin_adj(i) = log(app.FMthin_adj(i)) - (log(app.FMthin_adj(app.testConfig.adec_idx_start(3))) -...
                            FMthin_adj(app.testConfig.adec_idx_start(3)));
                    end
                end
            end
            
            % align all connected curves at first CH
            FMthin_adj = FMthin_adj - (FMthin_adj(1) - log_Thin_lin(1));
            % reverse natural log
            log_Med_lin = exp(log_Med_lin);
            log_Max = exp(log_Max);
            log_Thin_lin = exp(log_Thin_lin);
            FMthin_adj = exp(FMthin_adj);
            
            %%% plot FM_nom vs FM_x
            cla(app.UIAxes);
            % plot data
            plot(app.UIAxes, app.FM_x, log_Med_lin, 'g', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            plot(app.UIAxes, app.FM_x, log_Max, 'r', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            plot(app.UIAxes, app.FM_x, log_Thin_lin, 'Color', 'b', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            plot(app.UIAxes, app.FM_x, FMthin_adj, 'Color', 'k', 'LineStyle', '-', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            leg1 = legend(app.UIAxes,{'Nom', 'Max', 'Min','Model'},'Location','northeast','Orientation','horizontal');
            
        end

        % Callback function
        function ModifyNomButtonPushed(app, event)
            
        end

        % Value changed function: SectionsDropDown
        function SectionsDropDownValueChanged(app, event)
            value = app.SectionsDropDown.Value;
            
            depths = unique([app.pipeList.TopDepth, app.pipeList.BottomDepth]);
            
            % depth selection
            app.depth_min = depths(value);
            app.depth_max = depths(value + 1);
            
            i_Section = zeros(size(app.lasDepth, 1), 1); % logic array for selected sectional depth (1 depth only)
            
            for d = 1:size(app.lasDepth, 1)
                if app.lasDepth(d) >= app.depth_min && app.lasDepth(d) <= app.depth_max
                    i_Section(d) = 1;
                end
            end
            i_Section = logical(i_Section);
            
            % display depth of the section mid
            app.EditField_18.Value = (app.depth_min + app.depth_max)/2;
            
            % store selected ADEC in a varible for selected depth
            sectional_ADEC = app.ADEC(i_Section, :);
            
            %%% get config information
            selected_Mode = app.testMode(i_Section, :);
            app.ModeUsed = selected_Mode(1, 1);
            
            % Read ePDT ini file for certain mode
            % Input:
            %   app.configFileName    target file name
            %   testMode              selected config mode from .las file
            %
            % Output:
            %   status      success or not
            %   app.testConfig  config contents
            
            status = false;
            cfg_str = sprintf('[Config_%d]', app.ModeUsed);
            
            app.testConfig.configname = '';
            app.testConfig.cmdcode = '';
            app.testConfig.sensor = false(1, 3);      % enable status for S, M and L
            app.testConfig.txcurrent = zeros(1, 3);
            app.testConfig.txduration = zeros(1, 3);
            app.testConfig.chnum = zeros(1, 3);
            app.testConfig.chgain = cell(1, 3);       % unknown total channel number
            app.testConfig.chstarttime = [];
            app.testConfig.chendtime = [];
            app.testConfig.acq_idx_start = zeros(1, 3);
            app.testConfig.acq_idx_end = zeros(1, 3);
            app.testConfig.firingsequences = '';
            app.testConfig.fullsampletime = zeros(1, 3);
            app.testConfig.loggingspeed = 0;
            app.testConfig.adec_idx_start = zeros(1, 3);
            app.testConfig.adec_idx_end = zeros(1, 3);
            app.testConfig.chdelaytime = 20;          % defaulted to 20
            app.testConfig.turns = zeros(1, 3);
            app.testConfig.n_ch = 0;
            
            fid = fopen(app.configFileName); %% app.configFileName is the name of selected ini file
            while ~feof(fid)
                line = fgetl(fid);
                if strcmp(line, cfg_str)    % locate the correct config
                    while ~feof(fid) && ~isempty(line)
                        line = fgetl(fid);  % read line by line
                        paras = strsplit(line, '=');
                        switch paras{1}
                            case 'ConfigName'
                                app.testConfig.configname = paras{2};
                            case 'CmdCode'
                                app.testConfig.cmdcode = paras{2};
                            case 'SensorS'
                                app.testConfig.sensor(1) = strcmpi(paras{2}, 'true');
                            case 'SensorM'
                                app.testConfig.sensor(2) = strcmpi(paras{2}, 'true');
                            case 'SensorL'
                                app.testConfig.sensor(3) = strcmpi(paras{2}, 'true');
                            case 'TxCurrentS'
                                app.testConfig.txcurrent(1) = str2double(paras{2});
                            case 'TxDurationS'
                                app.testConfig.txduration(1) = str2double(paras{2});
                            case 'TxCurrentM'
                                app.testConfig.txcurrent(2) = str2double(paras{2});
                            case 'TxDurationM'
                                app.testConfig.txduration(2) = str2double(paras{2});
                            case 'TxCurrentL'
                                app.testConfig.txcurrent(3) = str2double(paras{2});
                            case 'TxDurationL'
                                app.testConfig.txduration(3) = str2double(paras{2});
                            case 'ChNumS'
                                app.testConfig.chnum(1) = str2double(paras{2});
                            case 'ChGainS'
                                app.testConfig.chgain{1} = str2double(strsplit(paras{2}, ','));
                            case 'ChNumM'
                                app.testConfig.chnum(2) = str2double(paras{2});
                            case 'ChGainM'
                                app.testConfig.chgain{2} = str2double(strsplit(paras{2}, ','));
                            case 'ChNumL'
                                app.testConfig.chnum(3) = str2double(paras{2});
                            case 'ChGainL'
                                app.testConfig.chgain{3} = str2double(strsplit(paras{2}, ','));
                            case 'ChStartTime'
                                app.testConfig.chstarttime = str2double(strsplit(paras{2}, ','));
                            case 'ChEndTime'
                                app.testConfig.chendtime = str2double(strsplit(paras{2}, ','));
                            case 'StartIdxSensorS'
                                app.testConfig.acq_idx_start(1) = str2double(paras{2});
                            case 'EndIdxSensorS'
                                app.testConfig.acq_idx_end(1) = str2double(paras{2});
                            case 'StartIdxSensorM'
                                app.testConfig.acq_idx_start(2) = str2double(paras{2});
                            case 'EndIdxSensorM'
                                app.testConfig.acq_idx_end(2) = str2double(paras{2});
                            case 'StartIdxSensorL'
                                app.testConfig.acq_idx_start(3) = str2double(paras{2});
                            case 'EndIdxSensorL'
                                app.testConfig.acq_idx_end(3) = str2double(paras{2});
                            case 'FiringSequences'
                                app.testConfig.firingsequences = paras{2};
                            case 'FullSampleTimeS'
                                app.testConfig.fullsampletime(1) = str2double(paras{2});
                            case 'FullSampleTimeM'
                                app.testConfig.fullsampletime(2) = str2double(paras{2});
                            case 'FullSampleTimeL'
                                app.testConfig.fullsampletime(3) = str2double(paras{2});
                            case 'LoggingSpeed'
                                app.testConfig.loggingspeed = str2double(paras{2});
                            case 'ADEC_S_START'
                                app.testConfig.adec_idx_start(1) = str2double(paras{2});
                            case 'ADEC_S_END'
                                if app.testConfig.adec_idx_start(1) == 0
                                    app.testConfig.adec_idx_end(1) = -1;
                                else
                                    app.testConfig.adec_idx_end(1) = str2double(paras{2});
                                end
                            case 'ADEC_M_START'
                                app.testConfig.adec_idx_start(2) = str2double(paras{2});
                            case 'ADEC_M_END'
                                if app.testConfig.adec_idx_start(2) == 0
                                    app.testConfig.adec_idx_end(2) = -1;
                                else
                                    app.testConfig.adec_idx_end(2) = str2double(paras{2});
                                end
                            case 'ADEC_L_START'
                                app.testConfig.adec_idx_start(3) = str2double(paras{2});
                            case 'ADEC_L_END'
                                if app.testConfig.adec_idx_start(3) == 0
                                    app.testConfig.adec_idx_end(3) = -1;
                                else
                                    app.testConfig.adec_idx_end(3) = str2double(paras{2});
                                end
                            case 'ChDelayTime'
                                app.testConfig.chdelaytime = str2double(paras{2});
                            case 'TurnsS'
                                app.testConfig.turns(1) = str2double(paras{2});
                            case 'TurnsM'
                                app.testConfig.turns(2) = str2double(paras{2});
                            case 'TurnsL'
                                app.testConfig.turns(3) = str2double(paras{2});
                        end
                    end
                    
                    n_ch = sum(app.testConfig.adec_idx_end - app.testConfig.adec_idx_start + 1);
                    if length(app.testConfig.chstarttime) ~= length(app.testConfig.chendtime) || ...
                            n_ch > length(app.testConfig.chstarttime)
                        break;
                    end
                    
                    app.testConfig.n_ch = n_ch;
                    status = true;
                    break;
                end
            end
            fclose(fid);
            
            %%% get, degain and plot max, min, med and plot
            % get, degain and plot max, min, med
            log_med = median(sectional_ADEC);
            app.FM_x = 1:size(log_med, 2);
            window_Gain = app.testConfig.chendtime - app.testConfig.chstarttime;
            coilTurn_Gain = ones(size(window_Gain));
            for CH_coil = 1:size(app.testConfig.chstarttime, 2)
                if CH_coil >= app.testConfig.adec_idx_start(1) && CH_coil <= app.testConfig.adec_idx_end(1) &&...
                        app.testConfig.adec_idx_start(1) < app.testConfig.adec_idx_end(1)
                    coilTurn_Gain(CH_coil) = 1 / app.testConfig.turns(1);
                elseif CH_coil >= app.testConfig.adec_idx_start(2) && CH_coil <= app.testConfig.adec_idx_end(2) &&...
                        app.testConfig.adec_idx_start(2) < app.testConfig.adec_idx_end(2)
                    coilTurn_Gain(CH_coil) = 1 / app.testConfig.turns(2);
                elseif CH_coil >= app.testConfig.adec_idx_start(3) && CH_coil <= app.testConfig.adec_idx_end(3) &&...
                        app.testConfig.adec_idx_start(3) < app.testConfig.adec_idx_end(3)
                    coilTurn_Gain(CH_coil) = 1 / app.testConfig.turns(3);
                end
            end
            analog_Gain = 0.175 * ones(size(window_Gain)); % as long as analog_Gain is 0.175
            app.log_Max_sect = max(sectional_ADEC) ./ (window_Gain .* coilTurn_Gain .* analog_Gain);
            app.log_Min_sect = min(sectional_ADEC) ./ (window_Gain .* coilTurn_Gain .* analog_Gain);
            app.log_med_sect = median(sectional_ADEC) ./ (window_Gain .* coilTurn_Gain .* analog_Gain);
            app.degain = ones(1, size(coilTurn_Gain, 2)) .* window_Gain .* coilTurn_Gain .* analog_Gain;
            
            % if sectional line box checked, show sectional lines
            cla(app.UIAxes);
            if ~isempty(app.log_med_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                plot(app.UIAxes, app.FM_x, app.log_med_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Nom');
            elseif ~isempty(app.log_med_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                [x, y] = CHtoTime(app.log_med_sect, app.testConfig, 4);
                plot(app.UIAxes, x, y, 'Color', 'k', 'LineStyle', '-.', 'LineWidth', 0.5,'DisplayName', 'Sect-Nom');
            end
            hold(app.UIAxes, "on");
            x=0; y=0;
            
            if ~isempty(app.log_Max_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                plot(app.UIAxes, app.FM_x, app.log_Max_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Max');
            elseif ~isempty(app.log_Max_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                [x, y] = CHtoTime(app.log_Max_sect, app.testConfig, 4);
                plot(app.UIAxes, x, y, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Max');
            end
            hold(app.UIAxes, "on");
            x=0; y=0;
            
            if ~isempty(app.log_Min_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                plot(app.UIAxes, app.FM_x, app.log_Min_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Min');
            elseif ~isempty(app.log_Min_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                [x, y] = CHtoTime(app.log_Min_sect, app.testConfig, 4);
                plot(app.UIAxes, x, y, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Min');
            end
            hold(app.UIAxes, "on");
            x=0; y=0;
            leg1 = legend(app.UIAxes);
            
            % logy scale
            if app.LogYCheckBox.Value == 1
                set(app.UIAxes, 'YScale', 'log');
            else
                set(app.UIAxes, 'YScale', 'linear');
            end
            
            % set nom uncalibrated
            app.NomCalibratedLamp.Color = "red";
            app.flag_nomcalib = 0;
            app.An_S = [];
            app.Bn_S = [];
            app.Cn_S = [];
            app.Dn_S = [];
            app.An_M = [];
            app.Bn_M = [];
            app.Cn_M = [];
            app.Dn_M = [];
            app.An_L = [];
            app.Bn_L = [];
            app.Cn_L = [];
            app.Dn_L = [];
            
            % modify plot sensor drop down items
            app.SensorDropDown.Items = cellstr('All sensors');
            app.SensorDropDown.ItemsData = 4;
            n=2;
            if app.testConfig.sensor(1) == 1
                app.SensorDropDown.Items(n) = cellstr('Small sensor');
                app.SensorDropDown.ItemsData(n) = 1;
                n = n + 1;
            end
            if app.testConfig.sensor(2) == 1
                app.SensorDropDown.Items(n) = cellstr('Medium sensor');
                app.SensorDropDown.ItemsData(n) = 2;
                n = n + 1;
            end
            if app.testConfig.sensor(3) == 1
                app.SensorDropDown.Items(n) = cellstr('Large sensor');
                app.SensorDropDown.ItemsData(n) = 3;
            end
            
            
        end

        % Callback function
        function CombineButtonPushed(app, event)
            % connect S and M for all curves
            if app.testConfig.sensor(2) == 1
                if ~isempty(app.log_Med_lin)
                    log_Med_lin = log(app.log_Med_lin);
                    log_Med_lin(app.testConfig.adec_idx_start(2)) = log_Med_lin(app.testConfig.adec_idx_start(2) - 1) - ...
                        (log_Med_lin(app.testConfig.adec_idx_start(2) - 2) - log_Med_lin(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        log_Med_lin(i) = log(app.log_Med_lin(i)) - (log(app.log_Med_lin(app.testConfig.adec_idx_start(2))) -...
                            log_Med_lin(app.testConfig.adec_idx_start(2)));
                    end
                end
                if ~isempty(app.log_Max)
                    log_Max = log(app.log_Max);
                    log_Max(app.testConfig.adec_idx_start(2)) = log_Max(app.testConfig.adec_idx_start(2) - 1) - ...
                        (log_Max(app.testConfig.adec_idx_start(2) - 2) - log_Max(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        log_Max(i) = log(app.log_Max(i)) - (log(app.log_Max(app.testConfig.adec_idx_start(2))) -...
                            log_Max(app.testConfig.adec_idx_start(2)));
                    end
                end
                if ~isempty(app.log_Min)
                    log_Min = log(app.log_Min);
                    log_Min(app.testConfig.adec_idx_start(2)) = log_Min(app.testConfig.adec_idx_start(2) - 1) - ...
                        (log_Min(app.testConfig.adec_idx_start(2) - 2) - log_Min(app.testConfig.adec_idx_start(2) - 1));
                    for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                        log_Min(i) = log(app.log_Min(i)) - (log(app.log_Min(app.testConfig.adec_idx_start(2))) -...
                            log_Min(app.testConfig.adec_idx_start(2)));
                    end
                end
                if app.SectionalDataCheckBox.Value == 1
                    if ~isempty(app.log_med_sect)
                        log_Med_sect = log(app.log_med_sect);
                        log_Med_sect(app.testConfig.adec_idx_start(2)) = log_Med_sect(app.testConfig.adec_idx_start(2) - 1) - ...
                            (log_Med_sect(app.testConfig.adec_idx_start(2) - 2) - log_Med_sect(app.testConfig.adec_idx_start(2) - 1));
                        for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                            log_Med_sect(i) = log(app.log_med_sect(i)) - (log(app.log_med_sect(app.testConfig.adec_idx_start(2))) -...
                                log_Med_sect(app.testConfig.adec_idx_start(2)));
                        end
                    end
                    if ~isempty(app.log_Max_sect)
                        log_Max_sect = log(app.log_Max_sect);
                        log_Max_sect(app.testConfig.adec_idx_start(2)) = log_Max_sect(app.testConfig.adec_idx_start(2) - 1) - ...
                            (log_Max_sect(app.testConfig.adec_idx_start(2) - 2) - log_Max_sect(app.testConfig.adec_idx_start(2) - 1));
                        for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                            log_Max_sect(i) = log(app.log_Max_sect(i)) - (log(app.log_Max_sect(app.testConfig.adec_idx_start(2))) -...
                                log_Max_sect(app.testConfig.adec_idx_start(2)));
                        end
                    end
                    if ~isempty(app.log_Min_sect)
                        log_Min_sect = log(app.log_Min_sect);
                        log_Min_sect(app.testConfig.adec_idx_start(2)) = log_Min_sect(app.testConfig.adec_idx_start(2) - 1) - ...
                            (log_Min_sect(app.testConfig.adec_idx_start(2) - 2) - log_Min_sect(app.testConfig.adec_idx_start(2) - 1));
                        for i = (app.testConfig.adec_idx_start(2) + 1) : app.testConfig.adec_idx_end(2)
                            log_Min_sect(i) = log(app.log_Min_sect(i)) - (log(app.log_Min_sect(app.testConfig.adec_idx_start(2))) -...
                                log_Min_sect(app.testConfig.adec_idx_start(2)));
                        end
                    end
                end
            end
            
            % connect M and L for all curves
            if app.testConfig.sensor(3) == 1
                if ~isempty(app.log_Med_lin)
                    log_Med_lin(app.testConfig.adec_idx_start(3)) = log_Med_lin(app.testConfig.adec_idx_start(3) - 1) - ...
                        (log_Med_lin(app.testConfig.adec_idx_start(3) - 2) - log_Med_lin(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        log_Med_lin(i) = log(app.log_Med_lin(i)) - (log(app.log_Med_lin(app.testConfig.adec_idx_start(3))) -...
                            log_Med_lin(app.testConfig.adec_idx_start(3)));
                    end
                end
                if ~isempty(app.log_Max)
                    log_Max(app.testConfig.adec_idx_start(3)) = log_Max(app.testConfig.adec_idx_start(3) - 1) - ...
                        (log_Max(app.testConfig.adec_idx_start(3) - 2) - log_Max(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        log_Max(i) = log(app.log_Max(i)) - (log(app.log_Max(app.testConfig.adec_idx_start(3))) -...
                            log_Max(app.testConfig.adec_idx_start(3)));
                    end
                end
                if ~isempty(app.log_Min)
                    log_Min(app.testConfig.adec_idx_start(3)) = log_Min(app.testConfig.adec_idx_start(3) - 1) - ...
                        (log_Min(app.testConfig.adec_idx_start(3) - 2) - log_Min(app.testConfig.adec_idx_start(3) - 1));
                    for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                        log_Min(i) = log(app.log_Min(i)) - (log(app.log_Min(app.testConfig.adec_idx_start(3))) -...
                            log_Min(app.testConfig.adec_idx_start(3)));
                    end
                end
                if app.SectionalDataCheckBox.Value == 1
                    if ~isempty(app.log_med_sect)
                        log_Med_sect(app.testConfig.adec_idx_start(3)) = log_Med_sect(app.testConfig.adec_idx_start(3) - 1) - ...
                            (log_Med_sect(app.testConfig.adec_idx_start(3) - 2) - log_Med_sect(app.testConfig.adec_idx_start(3) - 1));
                        for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                            log_Med_sect(i) = log(app.log_med_sect(i)) - (log(app.log_med_sect(app.testConfig.adec_idx_start(3))) -...
                                log_Med_sect(app.testConfig.adec_idx_start(3)));
                        end
                    end
                    if ~isempty(app.log_Max_sect)
                        log_Max_sect(app.testConfig.adec_idx_start(3)) = log_Max_sect(app.testConfig.adec_idx_start(3) - 1) - ...
                            (log_Max_sect(app.testConfig.adec_idx_start(3) - 2) - log_Max_sect(app.testConfig.adec_idx_start(3) - 1));
                        for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                            log_Max_sect(i) = log(app.log_Max_sect(i)) - (log(app.log_Max_sect(app.testConfig.adec_idx_start(3))) -...
                                log_Max_sect(app.testConfig.adec_idx_start(3)));
                        end
                    end
                    if ~isempty(app.log_Min_sect)
                        log_Min_sect(app.testConfig.adec_idx_start(3)) = log_Min_sect(app.testConfig.adec_idx_start(3) - 1) - ...
                            (log_Min_sect(app.testConfig.adec_idx_start(3) - 2) - log_Min_sect(app.testConfig.adec_idx_start(3) - 1));
                        for i = (app.testConfig.adec_idx_start(3) + 1) : app.testConfig.adec_idx_end(3)
                            log_Min_sect(i) = log(app.log_Min_sect(i)) - (log(app.log_Min_sect(app.testConfig.adec_idx_start(3))) -...
                                log_Min_sect(app.testConfig.adec_idx_start(3)));
                        end
                    end
                end
            end
            
            % reverse natural log
            log_Med_lin = exp(log_Med_lin);
            log_Max = exp(log_Max);
            log_Min = exp(log_Min);
            if app.SectionalDataCheckBox.Value == 1
                log_Med_sect = exp(log_Med_sect);
                log_Max_sect = exp(log_Max_sect);
                log_Min_sect = exp(log_Min_sect);
            end
            
            %%% plot FM_nom vs FM_x
            cla(app.UIAxes);
            % plot data
            plot(app.UIAxes, app.FM_x, log_Med_lin, 'g', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            plot(app.UIAxes, app.FM_x, log_Max, 'r', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            plot(app.UIAxes, app.FM_x, log_Min, 'b', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            
            % if sectional line box checked, show sectional lines
            if app.SectionalDataCheckBox.Value == 1
                % plot three lines
                plot(app.UIAxes, app.FM_x, log_Med_sect, 'Color', 'k', 'LineStyle', '-.', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                plot(app.UIAxes, app.FM_x, log_Max_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                plot(app.UIAxes, app.FM_x, log_Min_sect, 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5);
                hold(app.UIAxes, "on");
                leg1 = legend(app.UIAxes,{'Nom','Max','Min','Sect-Nom','Sect-Max','Sect-Min'},'Location','northeast','Orientation','horizontal');
            elseif app.SectionalDataCheckBox.Value == 0
                leg1 = legend(app.UIAxes,{'Nom','Max','Min'},'Location','northeast','Orientation','horizontal');
            end
            
            
        end

        % Callback function
        function ApplyAngleButtonPushed(app, event)
            % this function is to enlarge the angle between nom and max/min
            % to show the clients a larger feature
            
            % expand S sensor angle
            if app.testConfig.sensor(1) == 1
                if (~isempty(app.log_Med_lin)) && (~isempty(app.log_Max)) && (~isempty(app.log_Min))
                    % expand angle in log domain
                    log_MedS = log(app.log_Med_lin(app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1)));
                    log_MaxS = log(app.log_Max_perm(app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1)));
                    log_MinS = log(app.log_Min_perm(app.testConfig.adec_idx_start(1) : app.testConfig.adec_idx_end(1)));
                end
                % calculate gap between log(med) and log(max/min), multiply
                % the index and add back to nom
                adj_MaxS = app.EditField_36.Value * (log_MaxS - log_MedS) + log_MedS;
                adj_MinS = app.EditField_39.Value * (log_MinS - log_MedS) + log_MedS;
            else
                adj_MaxS = double.empty;
                adj_MinS = double.empty;
            end
            
            % expand M sensor angle
            if app.testConfig.sensor(2) == 1
                if (~isempty(app.log_Med_lin)) && (~isempty(app.log_Max)) && (~isempty(app.log_Min))
                    % expand angle in log domain
                    log_MedM = log(app.log_Med_lin(app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2)));
                    log_MaxM = log(app.log_Max_perm(app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2)));
                    log_MinM = log(app.log_Min_perm(app.testConfig.adec_idx_start(2) : app.testConfig.adec_idx_end(2)));
                end
                % calculate gap between log(med) and log(max/min), multiply
                % the index and add back to nom
                adj_MaxM = app.EditField_37.Value * (log_MaxM - log_MedM) + log_MedM;
                adj_MinM = app.EditField_40.Value * (log_MinM - log_MedM) + log_MedM;
            else
                adj_MaxM = double.empty;
                adj_MinM = double.empty;
            end
            
            % expand L sensor angle
            if app.testConfig.sensor(3) == 1
                if (~isempty(app.log_Med_lin)) && (~isempty(app.log_Max)) && (~isempty(app.log_Min))
                    % expand angle in log domain
                    log_MedL = log(app.log_Med_lin(app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3)));
                    log_MaxL = log(app.log_Max_perm(app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3)));
                    log_MinL = log(app.log_Min_perm(app.testConfig.adec_idx_start(3) : app.testConfig.adec_idx_end(3)));
                end
                % calculate gap between log(med) and log(max/min), multiply
                % the index and add back to nom
                adj_MaxL = app.EditField_38.Value * (log_MaxL - log_MedL) + log_MedL;
                adj_MinL = app.EditField_41.Value * (log_MinL - log_MedL) + log_MedL;
            else
                adj_MaxL = double.empty;
                adj_MinL = double.empty;
            end
            
            % smooth FM_L part in half and half
            adj_MinL(1 : (length(adj_MinL) / 2)) = transpose(smooth(adj_MinL(1 : (length(adj_MinL) / 2)), 3));
            adj_MinL((length(adj_MinL) / 2 + 1) : length(adj_MinL)) = ...
                transpose(smooth(adj_MinL((length(adj_MinL) / 2 + 1) : length(adj_MinL)), 3));
            
            % reverse natural log
            adj_Max = exp([adj_MaxS, adj_MaxM, adj_MaxL]);
            adj_Min = exp([adj_MinS, adj_MinM, adj_MinL]);
            
            % store magnified max and min into global varibles
            app.log_Max = adj_Max;
            app.log_Min = adj_Min;
            
            %%% plot FM_nom vs FM_x
            cla(app.UIAxes);
            % plot data
            plot(app.UIAxes, app.FM_x, app.log_Med_lin, 'g', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            plot(app.UIAxes, app.FM_x, app.log_Max, 'r', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            plot(app.UIAxes, app.FM_x, app.log_Min, 'b', 'LineWidth', 0.7);
            hold(app.UIAxes, "on");
            leg1 = legend(app.UIAxes,{'Nom','Max','Min'},'Location','northeast','Orientation','horizontal');
        end

        % Button pushed function: RescaleButton
        function RescaleButtonPushed(app, event)
            %rescale voltage axis of plots according to input ranges
            ylim(app.UIAxes, [app.VoltageEditField_2.Value, app.toEditField_3.Value]);
            
            %rescale CH of plots according to input ranges
            xlim(app.UIAxes, [app.TimeorCHEditField.Value, app.toEditField_2.Value]);
        end

        % Value changed function: SectionalDataCheckBox
        function SectionalDataCheckBoxValueChanged(app, event)
            
        end

        % Button pushed function: PlotWLModelingButton
        function PlotWLModelingButtonPushed(app, event)
            % plot data
            cla(app.UIAxes);
            
            leg1 = legend(app.UIAxes);
            leg1.AutoUpdate = 'on';
            
            % get sensor flag according to dropdown value
            if app.SensorDropDown.Value == 4
                app.plotsensorFLag = 4;
            elseif app.SensorDropDown.Value == 1
                app.plotsensorFLag = 1;
            elseif app.SensorDropDown.Value == 2
                app.plotsensorFLag = 2;
            elseif app.SensorDropDown.Value == 3
                app.plotsensorFLag = 3;
            end
            
            % sensor index will be according to sensorflag
            switch app.plotsensorFLag
                case 4
                    istart_ch = app.testConfig.adec_idx_start(1);
                    iend_ch = max(app.testConfig.adec_idx_end(:));
                case 1
                    istart_ch = app.testConfig.adec_idx_start(1);
                    iend_ch = app.testConfig.adec_idx_end(1);
                case 2
                    istart_ch = app.testConfig.adec_idx_start(2);
                    iend_ch = app.testConfig.adec_idx_end(2);
                case 3
                    istart_ch = app.testConfig.adec_idx_start(3);
                    iend_ch = app.testConfig.adec_idx_end(3);
            end
            
            % plot calibrated FM WL
            if ~isempty(app.FM_thin_final)
                FM_thin_final = app.FM_thin_final;
                if ~isempty(app.FM_thin_final) && (app.CalibratedWLCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), FM_thin_final(istart_ch:iend_ch), 'Color', 'k', 'LineStyle', '-', 'LineWidth', 1.0, 'DisplayName', 'Calib Model WL');
                elseif ~isempty(app.FM_thin_final) && (app.CalibratedWLCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(FM_thin_final, app.testConfig, app.plotsensorFLag);
                    y = correctdecay(y, 0, 0, app.testConfig);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'Color', 'k', 'LineStyle', '-', 'LineWidth', 1.0, 'DisplayName', 'Calib Model WL');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            % plot calibrated FM nom
            if ~isempty(app.FMnom_adj)
                FMnom_adj = app.FMnom_adj;
                if ~isempty(app.FMnom_adj) && (app.CalibratedNomCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), FMnom_adj(istart_ch:iend_ch), 'Color', 'k', 'LineStyle', '-', 'LineWidth', 0.7, 'DisplayName', 'Calib Model Nom');
                elseif ~isempty(app.FMnom_adj) && (app.CalibratedNomCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(FMnom_adj, app.testConfig, app.plotsensorFLag);
                    y = correctdecay(y, 0, 0, app.testConfig);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'Color', 'k', 'LineStyle', '-', 'LineWidth', 0.7, 'DisplayName', 'Calib Model Nom');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            % plot orignal FM nom
            if ~isempty(app.FM_nom_lin)
                FM_nom_lin = app.FM_nom_lin;
                if ~isempty(app.FM_nom_lin) && (app.OriginalModelNomCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), FM_nom_lin(istart_ch:iend_ch), 'Color', [0.7 0.7 0.7], 'LineStyle', '-', 'LineWidth', 0.5, 'DisplayName', 'Orig Model Nom');
                elseif ~isempty(app.FM_nom_lin) && (app.OriginalModelNomCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(FM_nom_lin, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'Color', [0.7 0.7 0.7], 'LineStyle', '-', 'LineWidth', 0.5, 'DisplayName', 'Orig Model Nom');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            if ~isempty(app.log_Med_lin)
                log_Med_lin = app.log_Med_lin;
                if ~isempty(app.log_Med_lin) && (app.NomCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), log_Med_lin(istart_ch:iend_ch), 'g', 'LineWidth', 0.7, 'DisplayName', 'Nom');
                elseif ~isempty(app.log_Med_lin) && (app.NomCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(log_Med_lin, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'g', 'LineWidth', 0.7, 'DisplayName', 'Nom');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            if ~isempty(app.log_Max)
                log_Max = app.log_Max;
                if ~isempty(app.log_Max) && (app.MaxCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), log_Max(istart_ch:iend_ch), 'r', 'LineWidth', 0.7, 'DisplayName', 'Max');
                elseif ~isempty(app.log_Max) && (app.MaxCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(log_Max, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'r', 'LineWidth', 0.7, 'DisplayName', 'Max');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            if ~isempty(app.log_Min)
                log_Min = app.log_Min;
                if ~isempty(app.log_Min) && (app.MinCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), log_Min(istart_ch:iend_ch), 'b', 'LineWidth', 0.7, 'DisplayName', 'Min');
                elseif ~isempty(app.log_Min) && (app.MinCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(log_Min, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'b', 'LineWidth', 0.7, 'DisplayName', 'Min');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            % if sectional line box checked, show sectional lines
            if ~isempty(app.log_med_sect)
                log_med_sect = app.log_med_sect;
                if ~isempty(app.log_med_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), log_med_sect(istart_ch:iend_ch), 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Nom');
                elseif ~isempty(app.log_med_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(log_med_sect, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'Color', 'k', 'LineStyle', '-.', 'LineWidth', 0.5,'DisplayName', 'Sect-Nom');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            if ~isempty(app.log_Max_sect)
                log_Max_sect = app.log_Max_sect;
                if ~isempty(app.log_Max_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), log_Max_sect(istart_ch:iend_ch), 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Max');
                elseif ~isempty(app.log_Max_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(log_Max_sect, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Max');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            if ~isempty(app.log_Min_sect)
                log_Min_sect = app.log_Min_sect;
                if ~isempty(app.log_Min_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 0)
                    plot(app.UIAxes, app.FM_x(istart_ch:iend_ch), log_Min_sect(istart_ch:iend_ch), 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Min');
                elseif ~isempty(app.log_Min_sect) && (app.SectionalDataCheckBox.Value == 1) && (app.PlotinTimeCheckBox.Value == 1)
                    [x, y] = CHtoTime(log_Min_sect, app.testConfig, app.plotsensorFLag);
                    plot(app.UIAxes, x((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)),...
                        y((app.testConfig.chstarttime(istart_ch)+1):app.testConfig.chendtime(iend_ch)), 'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5, 'DisplayName', 'Sect-Min');
                end
                hold(app.UIAxes, "on");
                x=0; y=0;
            end
            
            % logy scale
            if app.LogYCheckBox.Value == 1
                set(app.UIAxes, 'YScale', 'log');
            else
                set(app.UIAxes, 'YScale', 'linear');
            end
            
            % plot CHs for time display
            leg1.AutoUpdate = 'off'; % cancel autoupdate of legend
            if (app.PlotinTimeCheckBox.Value == 1) && (app.DiaplayCHCheckBox.Value == 1)
                for n = 1:size(app.testConfig.chendtime, 2)
                    if mod(n, 5) == 0
                        xline(app.UIAxes, app.testConfig.chendtime(n), ':', n, 'LabelHorizontalAlignment', 'center');
                    end
                end
            end
        end

        % Value changed function: EditField_66
        function EditField_66ValueChanged(app, event)
            value = app.EditField_66.Value;
            % set nom uncalibrated
            app.NomCalibratedLamp.Color = "red";
            app.flag_nomcalib = 0;
            app.An_S = [];
            app.Bn_S = [];
            app.Cn_S = [];
            app.Dn_S = [];
            app.An_M = [];
            app.Bn_M = [];
            app.Cn_M = [];
            app.Dn_M = [];
            app.An_L = [];
            app.Bn_L = [];
            app.Cn_L = [];
            app.Dn_L = [];
            
        end

        % Value changed function: DiaplayCHCheckBox
        function DiaplayCHCheckBoxValueChanged(app, event)
            value = app.DiaplayCHCheckBox.Value;
            
        end

        % Value changed function: PlotinTimeCheckBox
        function PlotinTimeCheckBoxValueChanged(app, event)
            value = app.PlotinTimeCheckBox.Value;
            
        end

        % Value changed function: SensorDropDown
        function SensorDropDownValueChanged(app, event)
            value = app.SensorDropDown.Value;
            
            if value == 1
                app.plotsensorFLag = 4;
            elseif value == 2
                app.plotsensorFLag = 1;
            elseif value == 3
                app.plotsensorFLag = 2;
            elseif value == 4
                app.plotsensorFLag = 3;
            end
            
        end

        % Size changed function: ToolDataPanel
        function ToolDataPanelSizeChanged(app, event)
            position = app.ToolDataPanel.Position;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ePDT Model vs Tool Verification')
            xlabel(app.UIAxes, 'ePDT Time (or Channels)')
            ylabel(app.UIAxes, 'Voltage, mV')
            app.PipeThicknessChangePanel = uipanel(app.UIFigure);
            app.PipeThicknessChangePanel.Title = 'Pipe Thickness Change';
            app.ToolDataPanel = uipanel(app.PipeThicknessChangePanel);
            app.ToolDataPanel.Title = 'Tool Data';
            app.ToolDataPanel.SizeChangedFcn = createCallbackFcn(app, @ToolDataPanelSizeChanged, true);
            app.LoadToolDataButton = uibutton(app.ToolDataPanel, 'push');
            app.LoadToolDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadToolDataButtonPushed, true);
            app.LoadToolDataButton.Text = 'Load Tool Data';
            app.EditField_17 = uieditfield(app.ToolDataPanel, 'numeric');
            app.EditField_18 = uieditfield(app.ToolDataPanel, 'numeric');
            app.ftmLabel = uilabel(app.ToolDataPanel);
            app.ftmLabel.Text = 'ft (m)';
            app.ftmLabel_2 = uilabel(app.ToolDataPanel);
            app.ftmLabel_2.Text = 'ft (m)';
            app.DepthofInterestLabel = uilabel(app.ToolDataPanel);
            app.DepthofInterestLabel.Text = 'Depth of Interest:';
            app.DepthwindowLabel = uilabel(app.ToolDataPanel);
            app.DepthwindowLabel.Text = 'Depth window:';
            app.EditField_19 = uieditfield(app.ToolDataPanel, 'text');
            app.DisplayWindowButton = uibutton(app.ToolDataPanel, 'push');
            app.DisplayWindowButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayWindowButtonPushed, true);
            app.DisplayWindowButton.Text = 'Display Window';
            app.LoadINIFileButton = uibutton(app.ToolDataPanel, 'push');
            app.LoadINIFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadINIFileButtonPushed, true);
            app.LoadINIFileButton.Text = 'Load INI File';
            app.EditField_20 = uieditfield(app.ToolDataPanel, 'text');
            app.LoadWellSchematicButton = uibutton(app.ToolDataPanel, 'push');
            app.LoadWellSchematicButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWellSchematicButtonPushed, true);
            app.LoadWellSchematicButton.Text = 'Load Well Schematic';
            app.EditField_23 = uieditfield(app.ToolDataPanel, 'text');
            app.SectionsDropDownLabel = uilabel(app.ToolDataPanel);
            app.SectionsDropDownLabel.Text = 'Sections:';
            app.SectionsDropDown = uidropdown(app.ToolDataPanel);
            app.SectionsDropDown.Items = {'Piple Sections'};
            app.SectionsDropDown.ValueChangedFcn = createCallbackFcn(app, @SectionsDropDownValueChanged, true);
            app.SectionsDropDown.Value = 'Piple Sections';
            app.EditField_66 = uieditfield(app.ToolDataPanel, 'numeric');
            app.EditField_66.ValueChangedFcn = createCallbackFcn(app, @EditField_66ValueChanged, true);
            app.ftmLabel_3 = uilabel(app.ToolDataPanel);
            app.ftmLabel_3.Text = 'ft (m)';
            app.DepthofNominalLabel = uilabel(app.ToolDataPanel);
            app.DepthofNominalLabel.Text = 'Depth of Nominal:';
            app.NomCalibratedLampLabel = uilabel(app.ToolDataPanel);
            app.NomCalibratedLampLabel.Text = 'Nom Calibrated';
            app.NomCalibratedLamp = uilamp(app.ToolDataPanel);
            app.NomCalibratedLamp.Tooltip = {'Red=Nom isn''t calibrated. '; 'Green=Nom calibrated.'};
            app.ModelingPanel = uipanel(app.PipeThicknessChangePanel);
            app.ModelingPanel.Title = 'Modeling';
            app.EditField_14 = uieditfield(app.ModelingPanel, 'numeric');
            app.EditField_15 = uieditfield(app.ModelingPanel, 'numeric');
            app.EditField_16 = uieditfield(app.ModelingPanel, 'numeric');
            app.WallLossLabel = uilabel(app.ModelingPanel);
            app.WallLossLabel.Text = 'Wall Loss:';
            app.Label_3 = uilabel(app.ModelingPanel);
            app.Label_3.Text = '  %';
            app.Label_4 = uilabel(app.ModelingPanel);
            app.Label_4.Text = '  %';
            app.Label_5 = uilabel(app.ModelingPanel);
            app.Label_5.Text = '  %';
            app.Pipe1Label = uilabel(app.ModelingPanel);
            app.Pipe1Label.Text = 'Pipe1:';
            app.Pipe2Label = uilabel(app.ModelingPanel);
            app.Pipe2Label.Text = 'Pipe2:';
            app.Pipe3Label = uilabel(app.ModelingPanel);
            app.Pipe3Label.Text = 'Pipe3:';
            app.NominalLabel = uilabel(app.ModelingPanel);
            app.NominalLabel.Text = 'Nominal:';
            app.PlotCalibratedNomButton = uibutton(app.ModelingPanel, 'push');
            app.PlotCalibratedNomButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCalibratedNomButtonPushed, true);
            app.PlotCalibratedNomButton.Text = 'Plot Calibrated Nom';
            app.CalibrateNomButton = uibutton(app.ModelingPanel, 'push');
            app.CalibrateNomButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateNomButtonPushed, true);
            app.CalibrateNomButton.Text = 'Calibrate Nom';
            app.EditField_24 = uieditfield(app.ModelingPanel, 'numeric');
            app.EditField_25 = uieditfield(app.ModelingPanel, 'numeric');
            app.Label_8 = uilabel(app.ModelingPanel);
            app.Label_8.Text = '  %';
            app.Label_9 = uilabel(app.ModelingPanel);
            app.Label_9.Text = '  %';
            app.Pipe4Label = uilabel(app.ModelingPanel);
            app.Pipe4Label.Text = 'Pipe4:';
            app.Pipe5Label = uilabel(app.ModelingPanel);
            app.Pipe5Label.Text = 'Pipe5:';
            app.CalculateWLButton = uibutton(app.ModelingPanel, 'push');
            app.CalculateWLButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateWLButtonPushed, true);
            app.CalculateWLButton.Text = 'Calculate WL';
            app.EditField_42 = uieditfield(app.ModelingPanel, 'numeric');
            app.EditField_43 = uieditfield(app.ModelingPanel, 'numeric');
            app.EditField_44 = uieditfield(app.ModelingPanel, 'numeric');
            app.EditField_45 = uieditfield(app.ModelingPanel, 'numeric');
            app.EditField_46 = uieditfield(app.ModelingPanel, 'numeric');
            app.CHLabel = uilabel(app.ModelingPanel);
            app.CHLabel.Text = 'CH:';
            app.WLLabel = uilabel(app.ModelingPanel);
            app.WLLabel.Text = 'WL:';
            app.EditField_55 = uieditfield(app.ModelingPanel, 'numeric');
            app.EditField_55.ValueDisplayFormat = '%.2f';
            app.EditField_56 = uieditfield(app.ModelingPanel, 'numeric');
            app.EditField_56.ValueDisplayFormat = '%.2f';
            app.EditField_57 = uieditfield(app.ModelingPanel, 'numeric');
            app.EditField_57.ValueDisplayFormat = '%.2f';
            app.EditField_58 = uieditfield(app.ModelingPanel, 'numeric');
            app.EditField_58.ValueDisplayFormat = '%.2f';
            app.EditField_59 = uieditfield(app.ModelingPanel, 'numeric');
            app.EditField_59.ValueDisplayFormat = '%.2f';
            app.CleancollardepthLabel = uilabel(app.ModelingPanel);
            app.CleancollardepthLabel.Text = 'Clean collar depth:';
            app.EditField_63 = uieditfield(app.ModelingPanel, 'numeric');
            app.Label_10 = uilabel(app.ModelingPanel);
            app.Label_10.Text = '  %';
            app.Pipe6Label = uilabel(app.ModelingPanel);
            app.Pipe6Label.Text = 'Pipe6:';
            app.EditField_64 = uieditfield(app.ModelingPanel, 'numeric');
            app.EditField_65 = uieditfield(app.ModelingPanel, 'numeric');
            app.EditField_65.ValueDisplayFormat = '%.2f';
            app.PlotWLModelingButton = uibutton(app.ModelingPanel, 'push');
            app.PlotWLModelingButton.ButtonPushedFcn = createCallbackFcn(app, @PlotWLModelingButtonPushed, true);
            app.PlotWLModelingButton.Text = 'Plot WL Modeling';
            app.PlotControlPanel = uipanel(app.PipeThicknessChangePanel);
            app.PlotControlPanel.Title = 'Plot Control';
            app.PlotinTimeCheckBox = uicheckbox(app.PlotControlPanel);
            app.PlotinTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotinTimeCheckBoxValueChanged, true);
            app.PlotinTimeCheckBox.Text = 'Plot in Time';
            app.PlotinTimeCheckBox.Value = true;
            app.MaxCheckBox = uicheckbox(app.PlotControlPanel);
            app.MaxCheckBox.Text = 'Max';
            app.MaxCheckBox.Value = true;
            app.NomCheckBox = uicheckbox(app.PlotControlPanel);
            app.NomCheckBox.Text = 'Nom';
            app.NomCheckBox.Value = true;
            app.MinCheckBox = uicheckbox(app.PlotControlPanel);
            app.MinCheckBox.Text = 'Min';
            app.MinCheckBox.Value = true;
            app.SectionalDataCheckBox = uicheckbox(app.PlotControlPanel);
            app.SectionalDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @SectionalDataCheckBoxValueChanged, true);
            app.SectionalDataCheckBox.Text = 'Sectional Data';
            app.SectionalDataCheckBox.Value = true;
            app.OriginalModelNomCheckBox = uicheckbox(app.PlotControlPanel);
            app.OriginalModelNomCheckBox.Enable = 'off';
            app.OriginalModelNomCheckBox.Text = 'Original Model Nom';
            app.CalibratedNomCheckBox = uicheckbox(app.PlotControlPanel);
            app.CalibratedNomCheckBox.Enable = 'off';
            app.CalibratedNomCheckBox.Text = 'Calibrated Nom';
            app.CalibratedWLCheckBox = uicheckbox(app.PlotControlPanel);
            app.CalibratedWLCheckBox.Enable = 'off';
            app.CalibratedWLCheckBox.Text = 'Calibrated WL';
            app.LogYCheckBox = uicheckbox(app.PlotControlPanel);
            app.LogYCheckBox.Text = 'Log Y';
            app.DiaplayCHCheckBox = uicheckbox(app.PlotControlPanel);
            app.DiaplayCHCheckBox.ValueChangedFcn = createCallbackFcn(app, @DiaplayCHCheckBoxValueChanged, true);
            app.DiaplayCHCheckBox.Tooltip = {'Vertical lines of end time of the channel will be displayed'};
            app.DiaplayCHCheckBox.Text = 'Diaplay CH';
            app.SensorDropDownLabel = uilabel(app.PlotControlPanel);
            app.SensorDropDownLabel.Text = 'Sensor';
            app.SensorDropDown = uidropdown(app.PlotControlPanel);
            app.SensorDropDown.Items = {'All sensors'};
            app.SensorDropDown.ItemsData = {'1'};
            app.SensorDropDown.ValueChangedFcn = createCallbackFcn(app, @SensorDropDownValueChanged, true);
            app.SensorDropDown.Value = '1';
            app.ePDTModelvsToolVerificationLabel = uilabel(app.UIFigure);
            app.ePDTModelvsToolVerificationLabel.Text = 'ePDT - Model vs Tool Verification';
            app.RescaleButton = uibutton(app.UIFigure, 'push');
            app.RescaleButton.ButtonPushedFcn = createCallbackFcn(app, @RescaleButtonPushed, true);
            app.RescaleButton.Text = 'Rescale';
            app.VoltageEditField_2Label = uilabel(app.UIFigure);
            app.VoltageEditField_2Label.Text = 'Voltage';
            app.VoltageEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.VoltageEditField_2.Value = 0.1;
            app.TimeorCHEditFieldLabel = uilabel(app.UIFigure);
            app.TimeorCHEditFieldLabel.Text = 'Time (or CH)';
            app.TimeorCHEditField = uieditfield(app.UIFigure, 'numeric');
            app.toEditField_2Label = uilabel(app.UIFigure);
            app.toEditField_2Label.Text = 'to';
            app.toEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.toEditField_2.Value = 100;
            app.toEditField_3Label = uilabel(app.UIFigure);
            app.toEditField_3Label.Text = 'to';
            app.toEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.toEditField_3.Value = 10000;
            app.mVLabel = uilabel(app.UIFigure);
            app.mVLabel.Text = 'mV';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ePDT_Model_vs_Tool_VerificationV24_splitsensors

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end