classdef SNT_ProbabiliyMap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ePDTModelvsToolVerificationLabel  matlab.ui.control.Label
        ePDTModelvsToolVerificationLabel_2  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SNTProbabilityMapGeneratorTab   matlab.ui.container.Tab
        LoadVDLdatacsvButton            matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        ExportStep2datacsvButton        matlab.ui.control.Button
        ParametercontrolPanel           matlab.ui.container.Panel
        FiltersizerowxcolDropDownLabel  matlab.ui.control.Label
        FiltersizerowxcolDropDown       matlab.ui.control.DropDown
        FilterlengthDropDown_3Label     matlab.ui.control.Label
        FilterlengthDropDown            matlab.ui.control.DropDown
        AfactorDropDownLabel            matlab.ui.control.Label
        AfactorDropDown                 matlab.ui.control.DropDown
        BfactorDropDownLabel            matlab.ui.control.Label
        BfactorDropDown                 matlab.ui.control.DropDown
        factor1DropDownLabel            matlab.ui.control.Label
        factor1DropDown                 matlab.ui.control.DropDown
        factor2DropDownLabel            matlab.ui.control.Label
        factor2DropDown                 matlab.ui.control.DropDown
        PlotprobabiliymapButton         matlab.ui.control.Button
        WindowsizeforEditFieldLabel     matlab.ui.control.Label
        WindowsizeforEditField          matlab.ui.control.NumericEditField
        ftmLabel                        matlab.ui.control.Label
        ThresholdforEditFieldLabel      matlab.ui.control.Label
        ThresholdforEditField           matlab.ui.control.NumericEditField
        AdjustparametersButton          matlab.ui.control.Button
        UIAxes_1                        matlab.ui.control.UIAxes
        xEditField_5                    matlab.ui.control.NumericEditField
        xLabel_2                        matlab.ui.control.Label
        toEditField_14Label             matlab.ui.control.Label
        toEditField_14                  matlab.ui.control.NumericEditField
        yEditField_5Label               matlab.ui.control.Label
        yEditField_5                    matlab.ui.control.NumericEditField
        toEditField_15Label             matlab.ui.control.Label
        toEditField_15                  matlab.ui.control.NumericEditField
        ColorbarEditField_5Label        matlab.ui.control.Label
        ColorbarEditField_5             matlab.ui.control.NumericEditField
        toEditField_16Label             matlab.ui.control.Label
        toEditField_16                  matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        RescaleButton                   matlab.ui.control.Button
        PlotVDLButton                   matlab.ui.control.Button
        xEditField_6                    matlab.ui.control.NumericEditField
        xLabel_3                        matlab.ui.control.Label
        RescaleButton_2                 matlab.ui.control.Button
        toEditField_17Label             matlab.ui.control.Label
        toEditField_17                  matlab.ui.control.NumericEditField
        yEditField_6Label               matlab.ui.control.Label
        yEditField_6                    matlab.ui.control.NumericEditField
        toEditField_18Label             matlab.ui.control.Label
        toEditField_18                  matlab.ui.control.NumericEditField
        ColorbarEditField_6Label        matlab.ui.control.Label
        ColorbarEditField_6             matlab.ui.control.NumericEditField
        toEditField_19Label             matlab.ui.control.Label
        toEditField_19                  matlab.ui.control.NumericEditField
        LocationmarkercomingsoonPanel   matlab.ui.container.Panel
        TubingEditFieldLabel            matlab.ui.control.Label
        TubingEditField                 matlab.ui.control.NumericEditField
        toEditField_20Label             matlab.ui.control.Label
        toEditField_20                  matlab.ui.control.NumericEditField
        Collar1EditFieldLabel           matlab.ui.control.Label
        Collar1EditField                matlab.ui.control.NumericEditField
        toEditField_21Label             matlab.ui.control.Label
        toEditField_21                  matlab.ui.control.NumericEditField
        Collar2EditFieldLabel           matlab.ui.control.Label
        Collar2EditField                matlab.ui.control.NumericEditField
        toEditField_22Label             matlab.ui.control.Label
        toEditField_22                  matlab.ui.control.NumericEditField
        Collar3EditFieldLabel           matlab.ui.control.Label
        Collar3EditField                matlab.ui.control.NumericEditField
        toEditField_23Label             matlab.ui.control.Label
        toEditField_23                  matlab.ui.control.NumericEditField
        Collar4EditFieldLabel           matlab.ui.control.Label
        Collar4EditField                matlab.ui.control.NumericEditField
        toEditField_24Label             matlab.ui.control.Label
        toEditField_24                  matlab.ui.control.NumericEditField
        FormationEditFieldLabel         matlab.ui.control.Label
        FormationEditField              matlab.ui.control.NumericEditField
        toEditField_25Label             matlab.ui.control.Label
        toEditField_25                  matlab.ui.control.NumericEditField
        AddlocationmarkerButton         matlab.ui.control.Button
        ParameterAssistanceTab          matlab.ui.container.Tab
    end

    
    properties (Access = private)
        all_data % all data read in
        all_depth % all depth read in
        table_data % data read in
        first_column % first column data, can be depth or time
        lambda % lambda calculated
        A % calculated A
        B % calculated B
        
        poissonMatrix % plot curve
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadVDLdatacsvButton
        function LoadVDLdatacsvButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.csv'}, 'Load VDL Data');
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg('No file selected', 'Error');
            end
            
            app.EditField.Value = strcat(pathname, filename);
            data = readtable(filename);
            data = table2array(data);
            table_data = double(data);
            app.all_data = table_data(10:end, 2:end);
            app.all_depth = table_data(10:end, 1);
            
        end

        % Button pushed function: PlotprobabiliymapButton
        function PlotprobabiliymapButtonPushed(app, event)
            msgbox('Calculation starts...');
            A_factor = str2double(app.AfactorDropDown.Value); % Initial value 1, adjustable
            B_factor = str2double(app.BfactorDropDown.Value); % Initial value 1, adjustable
            lambda_factor = str2double(app.factor1DropDown.Value); % Initial value 1, adjustable
            lambda_factor_2 = str2double(app.factor2DropDown.Value); % Initial value 1, adjustable            
            m = str2double(app.FiltersizerowxcolDropDown.Value); % Number of rows in the smoothing window
            n = str2double(app.FilterlengthDropDown.Value); % Number of columns on each side in the smoothing window
            threshold = (app.ThresholdforEditField.Value); % Threshold for lambda calculation
            
            window_size_depth = (app.WindowsizeforEditField.Value); % Window size for processing
            unit_gap = max(app.all_depth, [],'all') / length(app.all_depth);
            window_size = window_size_depth / unit_gap;
            
            % Number of rows in the data
            numRows = size(app.all_data, 1);
            poissonMatrix = zeros(numRows, 1000); % Matrix to store up-sampled Poisson curves
            
            % Initialize smoothed S20 and S42 data
            smoothed_S20 = zeros(numRows, 1);
            smoothed_S42 = zeros(numRows, 1);
            
            % Initialize sorting window for S20 and S42
            halfWindow = floor((m - 1) / 2);
            numCols = size(app.all_data, 2);
            
            % Pre-calculate 30% index position
            index_30percent = ceil(0.3 * ceil(m * (2 * n + 1)));
            
            % Initialize sorting windows for the first calculation
            columnLF = round(size(app.all_data, 2)/60 * 20);
            columnHF = round(size(app.all_data, 2)/60 * 42);
            
            windowData_LF = app.all_data(1:halfWindow * 2 + 1, (columnLF - n):(columnLF + n));
            windowData_HF = app.all_data(1:halfWindow * 2 + 1, (columnHF - n):(columnHF + n));
            
            sortedData_LF = sort(windowData_LF(:), 'descend');
            sortedData_HF = sort(windowData_HF(:), 'descend');
            
            % Start sliding window for S20 and S42
            for i = (halfWindow + 1):(numRows - halfWindow)
                % Calculate 30% value for current position
                smoothed_S20(i) = sortedData_LF(index_30percent);
                smoothed_S42(i) = sortedData_HF(index_30percent);
                
                % Remove the outgoing element from sortedData_LF and sortedData_HF
                if i > halfWindow + 1
                    out_of_window_LF = app.all_data(i - halfWindow - 1, (columnLF - n):(columnLF + n));
                    out_of_window_HF = app.all_data(i - halfWindow - 1, (columnHF - n):(columnHF + n));
                    
                    for value = out_of_window_LF(:)'
                        sortedData_LF(sortedData_LF == value) = [];
                    end
                    for value = out_of_window_HF(:)'
                        sortedData_HF(sortedData_HF == value) = [];
                    end
                end
                
                % Add new incoming element in sorted order
                in_window_LF = app.all_data(i + halfWindow, (columnLF - n):(columnLF + n));
                in_window_HF = app.all_data(i + halfWindow, (columnHF - n):(columnHF + n));
                
                for value = in_window_LF(:)'
                    % Binary insertion to maintain order
                    pos = find(sortedData_LF <= value, 1, 'first');
                    if isempty(pos)
                        sortedData_LF = [sortedData_LF; value];
                    else
                        sortedData_LF = [sortedData_LF(1:pos-1); value; sortedData_LF(pos:end)];
                    end
                end
                
                for value = in_window_HF(:)'
                    pos = find(sortedData_HF <= value, 1, 'first');
                    if isempty(pos)
                        sortedData_HF = [sortedData_HF; value];
                    else
                        sortedData_HF = [sortedData_HF(1:pos-1); value; sortedData_HF(pos:end)];
                    end
                end
            end
            
            % For rows at the end without complete window size
            for i = (numRows - halfWindow + 1):numRows
                smoothed_S20(i) = sortedData_LF(index_30percent);
                smoothed_S42(i) = sortedData_HF(index_30percent);
            end
            
            smoothed_S20_abs = smoothed_S20;
            smoothed_S42_abs = smoothed_S42;
            min_all = min(app.all_data, [], 'all');
            % Apply sliding window to processed S20 and S42 data
            for i = (halfWindow + 1):(numRows - halfWindow)
                % Extract window data for S20 and S42
                window_S20 = smoothed_S20(max(1, i-floor(window_size/2)):min(numRows, i+floor(window_size/2)));
                window_S42 = smoothed_S42(max(1, i-floor(window_size/2)):min(numRows, i+floor(window_size/2)));
                
                % Calculate A and B
                
                smoothed_S20_abs(i, 1) = smoothed_S20(i, 1) - min_all;
                smoothed_S42_abs(i, 1) = smoothed_S42(i, 1) - min_all;
                A(i, 1) = smoothed_S20_abs(i, 1);
                B(i, 1) = ((smoothed_S20_abs(i, 1) / smoothed_S42_abs(i, 1) - 1));
                %     A(i, 1) = smoothed_S20_abs(i, 1) * A_factor;
                %     B(i, 1) = (((smoothed_S20_abs(i, 1) / smoothed_S42_abs(i, 1) - 1) * B_factor) * 500);
                
                % Lambda calculation
                max_val = max(window_S20);
                min_val = min(window_S20);
                if (max_val - min_val) < threshold
                    lambda(i, 1) = 1;
                else
                    half_diff = (max_val - min_val) / 2;
                    [~, max_idx] = max(window_S20);
                    closest_idx = find(abs(window_S20 - (min_val + half_diff)) == min(abs(window_S20 - (min_val + half_diff))));
                    
                    % Find the closest index to max_idx when there are multiple values
                    if numel(closest_idx) > 1
                        [~, min_dist_idx] = min(abs(closest_idx - max_idx));
                        closest_idx = closest_idx(min_dist_idx);
                    end
                    
                    % Calculate lambda
                    lambda(i, 1) = abs(closest_idx - max_idx) / window_size;
                end
                
            end
            
            app.lambda = movmean(lambda, 10);
            app.A = (movmean(A, 100)).^2;
            app.B = movmean(B, 100);
            
            for i = (halfWindow + 1):(numRows - halfWindow)
                % Generate Poisson curve using lookup function
                poissonCurve(i,:) = lookup_pois_distr(app.lambda(i, 1), lambda_factor);
                
                % Find the maximum position in the original Poisson curve
                [~, max_idx] = max(poissonCurve(i, :));
                
                % Calculate the new width range based on lambda_factor_2
                if lambda_factor_2 <= 1
                    % Extend the range
                    half_width = round(500 / lambda_factor_2); % 500 is half of 1000 points
                    left_bound = max(1, max_idx - half_width);
                    right_bound = min(1000, max_idx + half_width);
                else
                    % Shrink the range
                    half_width = round(500 / lambda_factor_2); % Factor < 1, so reducing width
                    left_bound = max(1, max_idx - half_width);
                    right_bound = min(1000, max_idx + half_width);
                end
                
                % Extract the range and interpolate to 1000 points
                x_original = linspace(left_bound, right_bound, right_bound - left_bound + 1);
                poissonCurve_selected = poissonCurve(i, left_bound:right_bound);
                x_target = linspace(left_bound, right_bound, 1000); % Target 1000 points
                poissonCurve_resampled = interp1(x_original, poissonCurve_selected, x_target, 'linear', 0);
                
                % Apply shift based on B(i, 1) with factor B_factor
                shift_amount = app.B(i, 1) * B_factor * 1000;
                x_shifted = x_target - shift_amount; % Shifted x positions for interpolation
                poissonCurve_shifted = interp1(x_target, poissonCurve_resampled, x_shifted, 'linear', 0);
                
                % Scale by A(i, 1) and store in poissonMatrix
                poissonMatrix(i, :) = app.A(i, 1) * A_factor * poissonCurve_shifted;
            end
            app.poissonMatrix = poissonMatrix;
            
            imagesc(app.UIAxes_1, 1:100, app.all_depth, app.poissonMatrix);
            colorbar(app.UIAxes_1);
            caxis(app.UIAxes_1, [app.ColorbarEditField_5.Value, app.toEditField_16.Value]);
            xlabel(app.UIAxes_1, 'Relative distance');
            ylabel(app.UIAxes_1, 'Depth');
            title(app.UIAxes_1, 'Acoustic Probabiliy Map');
            
            
        end

        % Callback function
        function CalcPlotButtonPushed(app, event)
            
        end

        % Callback function
        function CalcPlotButton_2Pushed(app, event)
          
        end

        % Callback function
        function CalcPlotButton_3Pushed(app, event)
            
        end

        % Callback function
        function ClearallButtonPushed(app, event)
            
        end

        % Callback function
        function RescaleButtonPushed(app, event)
            
        end

        % Callback function
        function RescaleButton_2Pushed(app, event)
            
        end

        % Callback function
        function RescaleButton_3Pushed(app, event)
            
        end

        % Callback function
        function CalcPlotButton_4Pushed(app, event)
           
        end

        % Callback function
        function UseparametersButtonPushed(app, event)
       
        end

        % Button pushed function: ExportStep2datacsvButton
        function ExportStep2datacsvButtonPushed(app, event)
 
        end

        % Button pushed function: RescaleButton
        function RescaleButtonPushed2(app, event)
             imagesc(app.UIAxes_1, 1:100, app.all_depth, app.poissonMatrix);
            
            %             title(app.UIAxes, 'Original VDL');
            colorbar(app.UIAxes_1);
                        
            ylim(app.UIAxes_1, [app.yEditField_5.Value app.toEditField_15.Value]);
            xlim(app.UIAxes_1, [app.xEditField_5.Value app.toEditField_14.Value]);
            caxis(app.UIAxes_1, [app.ColorbarEditField_5.Value, app.toEditField_16.Value]);
            xlabel(app.UIAxes_1, 'Relative distance');
            ylabel(app.UIAxes_1, 'Depth');
            title(app.UIAxes_1, 'Acoustic Probabiliy Map');
            
        end

        % Callback function
        function ColorbarEditField_4ValueChanged(app, event)
   
        end

        % Callback function
        function toEditField_13ValueChanged(app, event)
  
        end

        % Button pushed function: PlotVDLButton
        function PlotVDLButtonPushed(app, event)
       
            imagesc(app.UIAxes, 1:60, app.all_depth, app.all_data);
            colorbar(app.UIAxes);
            caxis(app.UIAxes, [app.ColorbarEditField_6.Value, app.toEditField_19.Value]);
            xlabel(app.UIAxes, 'Frequency, kHz');
            ylabel(app.UIAxes, 'Depth');
            title(app.UIAxes, 'Logging VDL');
            
        end

        % Button pushed function: RescaleButton_2
        function RescaleButton_2Pushed2(app, event)
            imagesc(app.UIAxes, 1:60, app.all_depth, app.all_data);
            
            %             title(app.UIAxes, 'Original VDL');
            colorbar(app.UIAxes);
            
            caxis(app.UIAxes, [app.ColorbarEditField_6.Value app.toEditField_19.Value]);
            ylim(app.UIAxes, [app.yEditField_6.Value app.toEditField_18.Value]);
            xlim(app.UIAxes, [app.xEditField_6.Value app.toEditField_17.Value]);
            xlabel(app.UIAxes, 'Frequency, kHz');
            ylabel(app.UIAxes, 'Depth');
            title(app.UIAxes, 'Logging VDL');
        end

        % Button pushed function: AdjustparametersButton
        function AdjustparametersButtonPushed(app, event)
            A_factor = str2double(app.AfactorDropDown.Value); % Initial value 1, adjustable
            B_factor = str2double(app.BfactorDropDown.Value); % Initial value 1, adjustable
            lambda_factor = str2double(app.factor1DropDown.Value); % Initial value 1, adjustable
            lambda_factor_2 = str2double(app.factor2DropDown.Value); % Initial value 1, adjustable            
            m = str2double(app.FiltersizerowxcolDropDown.Value); % Number of rows in the smoothing window
            n = str2double(app.FilterlengthDropDown.Value); % Number of columns on each side in the smoothing window
            threshold = str2double(app.ThresholdforEditField.Value); % Threshold for lambda calculation
            
            window_size_depth = str2double(app.WindowsizeforEditField.Value); % Window size for processing
            unit_gap = max(app.all_depth, [],'all') / length(app.all_depth);
            window_size = window_size_depth / unit_gap;
            
            numRows = size(app.all_data, 1);            
            % Initialize sorting window for S20 and S42
            halfWindow = floor((m - 1) / 2);
            numCols = size(app.all_data, 2);
            
            % Pre-calculate 30% index position
            index_30percent = ceil(0.3 * ceil(m * (2 * n + 1)));
            
            for i = (halfWindow + 1):(numRows - halfWindow)
                % Generate Poisson curve using lookup function
                poissonCurve(i,:) = lookup_pois_distr(app.lambda(i, 1), lambda_factor);
                
                % Find the maximum position in the original Poisson curve
                [~, max_idx] = max(poissonCurve(i, :));
                
                % Calculate the new width range based on lambda_factor_2
                if lambda_factor_2 <= 1
                    % Extend the range
                    half_width = round(500 / lambda_factor_2); % 500 is half of 1000 points
                    left_bound = max(1, max_idx - half_width);
                    right_bound = min(1000, max_idx + half_width);
                else
                    % Shrink the range
                    half_width = round(500 / lambda_factor_2); % Factor < 1, so reducing width
                    left_bound = max(1, max_idx - half_width);
                    right_bound = min(1000, max_idx + half_width);
                end
                
                % Extract the range and interpolate to 1000 points
                x_original = linspace(left_bound, right_bound, right_bound - left_bound + 1);
                poissonCurve_selected = poissonCurve(i, left_bound:right_bound);
                x_target = linspace(left_bound, right_bound, 1000); % Target 1000 points
                poissonCurve_resampled = interp1(x_original, poissonCurve_selected, x_target, 'linear', 0);
                
                % Apply shift based on B(i, 1) with factor B_factor
                shift_amount = app.B(i, 1) * B_factor * 1000;
                x_shifted = x_target - shift_amount; % Shifted x positions for interpolation
                poissonCurve_shifted = interp1(x_target, poissonCurve_resampled, x_shifted, 'linear', 0);
                
                % Scale by A(i, 1) and store in poissonMatrix
                poissonMatrix(i, :) = app.A(i, 1) * A_factor * poissonCurve_shifted;
            end
            app.poissonMatrix = poissonMatrix;
            
            imagesc(app.UIAxes_1, 1:100, app.all_depth, app.poissonMatrix);
            colorbar(app.UIAxes_1);
            caxis(app.UIAxes_1, [app.ColorbarEditField_5.Value, app.toEditField_16.Value]);
            xlabel(app.UIAxes_1, 'Relative distance');
            ylabel(app.UIAxes_1, 'Depth');
            title(app.UIAxes_1, 'Acoustic Probabiliy Map');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ePDTModelvsToolVerificationLabel = uilabel(app.UIFigure);
            app.ePDTModelvsToolVerificationLabel.Text = 'ePDT - Model vs Tool Verification';
            app.ePDTModelvsToolVerificationLabel_2 = uilabel(app.UIFigure);
            app.ePDTModelvsToolVerificationLabel_2.Text = 'ePDT - Model vs Tool Verification';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SNTProbabilityMapGeneratorTab = uitab(app.TabGroup);
            app.SNTProbabilityMapGeneratorTab.Title = 'SNT Probability Map Generator';
            app.LoadVDLdatacsvButton = uibutton(app.SNTProbabilityMapGeneratorTab, 'push');
            app.LoadVDLdatacsvButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVDLdatacsvButtonPushed, true);
            app.LoadVDLdatacsvButton.Text = 'Load VDL data (.csv)';
            app.EditField = uieditfield(app.SNTProbabilityMapGeneratorTab, 'text');
            app.ExportStep2datacsvButton = uibutton(app.SNTProbabilityMapGeneratorTab, 'push');
            app.ExportStep2datacsvButton.ButtonPushedFcn = createCallbackFcn(app, @ExportStep2datacsvButtonPushed, true);
            app.ExportStep2datacsvButton.Text = 'Export Step2 data (.csv)';
            app.ParametercontrolPanel = uipanel(app.SNTProbabilityMapGeneratorTab);
            app.ParametercontrolPanel.Title = 'Parameter control';
            app.FiltersizerowxcolDropDownLabel = uilabel(app.ParametercontrolPanel);
            app.FiltersizerowxcolDropDownLabel.Text = 'Filter size (row x col)';
            app.FiltersizerowxcolDropDown = uidropdown(app.ParametercontrolPanel);
            app.FiltersizerowxcolDropDown.Items = {'7', '11', '15', '19'};
            app.FiltersizerowxcolDropDown.Value = '15';
            app.FilterlengthDropDown_3Label = uilabel(app.ParametercontrolPanel);
            app.FilterlengthDropDown_3Label.Text = 'x';
            app.FilterlengthDropDown = uidropdown(app.ParametercontrolPanel);
            app.FilterlengthDropDown.Items = {'5', '7', '9', '11'};
            app.FilterlengthDropDown.Value = '7';
            app.AfactorDropDownLabel = uilabel(app.ParametercontrolPanel);
            app.AfactorDropDownLabel.Text = 'A factor';
            app.AfactorDropDown = uidropdown(app.ParametercontrolPanel);
            app.AfactorDropDown.Items = {'1', '5', '7', '10'};
            app.AfactorDropDown.Value = '1';
            app.BfactorDropDownLabel = uilabel(app.ParametercontrolPanel);
            app.BfactorDropDownLabel.Text = 'B factor';
            app.BfactorDropDown = uidropdown(app.ParametercontrolPanel);
            app.BfactorDropDown.Items = {'0.5', '1', '2', '3'};
            app.BfactorDropDown.Value = '1';
            app.factor1DropDownLabel = uilabel(app.ParametercontrolPanel);
            app.factor1DropDownLabel.Text = 'λ factor 1';
            app.factor1DropDown = uidropdown(app.ParametercontrolPanel);
            app.factor1DropDown.Items = {'0.5', '1', '2', '3'};
            app.factor1DropDown.Value = '1';
            app.factor2DropDownLabel = uilabel(app.ParametercontrolPanel);
            app.factor2DropDownLabel.Text = 'λ factor 2';
            app.factor2DropDown = uidropdown(app.ParametercontrolPanel);
            app.factor2DropDown.Items = {'0.5', '1', '2', '3', '5'};
            app.factor2DropDown.Value = '2';
            app.PlotprobabiliymapButton = uibutton(app.ParametercontrolPanel, 'push');
            app.PlotprobabiliymapButton.ButtonPushedFcn = createCallbackFcn(app, @PlotprobabiliymapButtonPushed, true);
            app.PlotprobabiliymapButton.Text = 'Plot probabiliy map';
            app.WindowsizeforEditFieldLabel = uilabel(app.ParametercontrolPanel);
            app.WindowsizeforEditFieldLabel.Text = 'Window size for  λ';
            app.WindowsizeforEditField = uieditfield(app.ParametercontrolPanel, 'numeric');
            app.WindowsizeforEditField.Value = 1000;
            app.ftmLabel = uilabel(app.ParametercontrolPanel);
            app.ftmLabel.Text = 'ft/m';
            app.ThresholdforEditFieldLabel = uilabel(app.ParametercontrolPanel);
            app.ThresholdforEditFieldLabel.Text = 'Threshold for λ';
            app.ThresholdforEditField = uieditfield(app.ParametercontrolPanel, 'numeric');
            app.ThresholdforEditField.Value = 7;
            app.AdjustparametersButton = uibutton(app.ParametercontrolPanel, 'push');
            app.AdjustparametersButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustparametersButtonPushed, true);
            app.AdjustparametersButton.Text = 'Adjust parameters';
            app.UIAxes_1 = uiaxes(app.SNTProbabilityMapGeneratorTab);
            title(app.UIAxes_1, '')
            xlabel(app.UIAxes_1, 'Frequency')
            ylabel(app.UIAxes_1, '')
            app.xEditField_5 = uieditfield(app.SNTProbabilityMapGeneratorTab, 'numeric');
            app.xEditField_5.Value = 1;
            app.xLabel_2 = uilabel(app.SNTProbabilityMapGeneratorTab);
            app.xLabel_2.Text = 'x:';
            app.toEditField_14Label = uilabel(app.SNTProbabilityMapGeneratorTab);
            app.toEditField_14Label.Text = 'to';
            app.toEditField_14 = uieditfield(app.SNTProbabilityMapGeneratorTab, 'numeric');
            app.toEditField_14.Value = 60;
            app.yEditField_5Label = uilabel(app.SNTProbabilityMapGeneratorTab);
            app.yEditField_5Label.Text = 'y:';
            app.yEditField_5 = uieditfield(app.SNTProbabilityMapGeneratorTab, 'numeric');
            app.toEditField_15Label = uilabel(app.SNTProbabilityMapGeneratorTab);
            app.toEditField_15Label.Text = 'to';
            app.toEditField_15 = uieditfield(app.SNTProbabilityMapGeneratorTab, 'numeric');
            app.toEditField_15.Value = 1000;
            app.ColorbarEditField_5Label = uilabel(app.SNTProbabilityMapGeneratorTab);
            app.ColorbarEditField_5Label.Text = 'Colorbar:';
            app.ColorbarEditField_5 = uieditfield(app.SNTProbabilityMapGeneratorTab, 'numeric');
            app.ColorbarEditField_5.Value = 800;
            app.toEditField_16Label = uilabel(app.SNTProbabilityMapGeneratorTab);
            app.toEditField_16Label.Text = 'to';
            app.toEditField_16 = uieditfield(app.SNTProbabilityMapGeneratorTab, 'numeric');
            app.toEditField_16.Value = 1500;
            app.UIAxes = uiaxes(app.SNTProbabilityMapGeneratorTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Frequency')
            ylabel(app.UIAxes, '')
            app.RescaleButton = uibutton(app.SNTProbabilityMapGeneratorTab, 'push');
            app.RescaleButton.ButtonPushedFcn = createCallbackFcn(app, @RescaleButtonPushed2, true);
            app.RescaleButton.Text = 'Rescale';
            app.PlotVDLButton = uibutton(app.SNTProbabilityMapGeneratorTab, 'push');
            app.PlotVDLButton.ButtonPushedFcn = createCallbackFcn(app, @PlotVDLButtonPushed, true);
            app.PlotVDLButton.Text = 'Plot VDL';
            app.xEditField_6 = uieditfield(app.SNTProbabilityMapGeneratorTab, 'numeric');
            app.xEditField_6.Value = 1;
            app.xLabel_3 = uilabel(app.SNTProbabilityMapGeneratorTab);
            app.xLabel_3.Text = 'x:';
            app.RescaleButton_2 = uibutton(app.SNTProbabilityMapGeneratorTab, 'push');
            app.RescaleButton_2.ButtonPushedFcn = createCallbackFcn(app, @RescaleButton_2Pushed2, true);
            app.RescaleButton_2.Text = 'Rescale';
            app.toEditField_17Label = uilabel(app.SNTProbabilityMapGeneratorTab);
            app.toEditField_17Label.Text = 'to';
            app.toEditField_17 = uieditfield(app.SNTProbabilityMapGeneratorTab, 'numeric');
            app.toEditField_17.Value = 60;
            app.yEditField_6Label = uilabel(app.SNTProbabilityMapGeneratorTab);
            app.yEditField_6Label.Text = 'y:';
            app.yEditField_6 = uieditfield(app.SNTProbabilityMapGeneratorTab, 'numeric');
            app.toEditField_18Label = uilabel(app.SNTProbabilityMapGeneratorTab);
            app.toEditField_18Label.Text = 'to';
            app.toEditField_18 = uieditfield(app.SNTProbabilityMapGeneratorTab, 'numeric');
            app.toEditField_18.Value = 1000;
            app.ColorbarEditField_6Label = uilabel(app.SNTProbabilityMapGeneratorTab);
            app.ColorbarEditField_6Label.Text = 'Colorbar:';
            app.ColorbarEditField_6 = uieditfield(app.SNTProbabilityMapGeneratorTab, 'numeric');
            app.ColorbarEditField_6.Value = -140;
            app.toEditField_19Label = uilabel(app.SNTProbabilityMapGeneratorTab);
            app.toEditField_19Label.Text = 'to';
            app.toEditField_19 = uieditfield(app.SNTProbabilityMapGeneratorTab, 'numeric');
            app.toEditField_19.Value = -60;
            app.LocationmarkercomingsoonPanel = uipanel(app.SNTProbabilityMapGeneratorTab);
            app.LocationmarkercomingsoonPanel.Title = 'Location marker (coming soon)';
            app.TubingEditFieldLabel = uilabel(app.LocationmarkercomingsoonPanel);
            app.TubingEditFieldLabel.Text = 'Tubing';
            app.TubingEditField = uieditfield(app.LocationmarkercomingsoonPanel, 'numeric');
            app.TubingEditField.Value = 20;
            app.toEditField_20Label = uilabel(app.LocationmarkercomingsoonPanel);
            app.toEditField_20Label.Text = 'to';
            app.toEditField_20 = uieditfield(app.LocationmarkercomingsoonPanel, 'numeric');
            app.toEditField_20.Value = 30;
            app.Collar1EditFieldLabel = uilabel(app.LocationmarkercomingsoonPanel);
            app.Collar1EditFieldLabel.Text = 'Collar 1';
            app.Collar1EditField = uieditfield(app.LocationmarkercomingsoonPanel, 'numeric');
            app.Collar1EditField.Value = 40;
            app.toEditField_21Label = uilabel(app.LocationmarkercomingsoonPanel);
            app.toEditField_21Label.Text = 'to';
            app.toEditField_21 = uieditfield(app.LocationmarkercomingsoonPanel, 'numeric');
            app.toEditField_21.Value = 50;
            app.Collar2EditFieldLabel = uilabel(app.LocationmarkercomingsoonPanel);
            app.Collar2EditFieldLabel.Text = 'Collar 2';
            app.Collar2EditField = uieditfield(app.LocationmarkercomingsoonPanel, 'numeric');
            app.Collar2EditField.Value = 60;
            app.toEditField_22Label = uilabel(app.LocationmarkercomingsoonPanel);
            app.toEditField_22Label.Text = 'to';
            app.toEditField_22 = uieditfield(app.LocationmarkercomingsoonPanel, 'numeric');
            app.toEditField_22.Value = 70;
            app.Collar3EditFieldLabel = uilabel(app.LocationmarkercomingsoonPanel);
            app.Collar3EditFieldLabel.Text = 'Collar 3';
            app.Collar3EditField = uieditfield(app.LocationmarkercomingsoonPanel, 'numeric');
            app.Collar3EditField.Value = 70;
            app.toEditField_23Label = uilabel(app.LocationmarkercomingsoonPanel);
            app.toEditField_23Label.Text = 'to';
            app.toEditField_23 = uieditfield(app.LocationmarkercomingsoonPanel, 'numeric');
            app.toEditField_23.Value = 75;
            app.Collar4EditFieldLabel = uilabel(app.LocationmarkercomingsoonPanel);
            app.Collar4EditFieldLabel.Text = 'Collar 4';
            app.Collar4EditField = uieditfield(app.LocationmarkercomingsoonPanel, 'numeric');
            app.Collar4EditField.Value = 75;
            app.toEditField_24Label = uilabel(app.LocationmarkercomingsoonPanel);
            app.toEditField_24Label.Text = 'to';
            app.toEditField_24 = uieditfield(app.LocationmarkercomingsoonPanel, 'numeric');
            app.toEditField_24.Value = 80;
            app.FormationEditFieldLabel = uilabel(app.LocationmarkercomingsoonPanel);
            app.FormationEditFieldLabel.Text = 'Formation';
            app.FormationEditField = uieditfield(app.LocationmarkercomingsoonPanel, 'numeric');
            app.FormationEditField.Value = 80;
            app.toEditField_25Label = uilabel(app.LocationmarkercomingsoonPanel);
            app.toEditField_25Label.Text = 'to';
            app.toEditField_25 = uieditfield(app.LocationmarkercomingsoonPanel, 'numeric');
            app.toEditField_25.Value = 100;
            app.AddlocationmarkerButton = uibutton(app.LocationmarkercomingsoonPanel, 'push');
            app.AddlocationmarkerButton.Enable = 'off';
            app.AddlocationmarkerButton.Text = 'Add location marker';
            app.ParameterAssistanceTab = uitab(app.TabGroup);
            app.ParameterAssistanceTab.Title = 'Parameter Assistance';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNT_ProbabiliyMap

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end